classdef gisaxsleenew_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        gisaxsleenew                matlab.ui.Figure
        pb_2dindexing               matlab.ui.control.Button
        pb_agbhfindcent             matlab.ui.control.Button
        pb_qCalibration2            matlab.ui.control.Button
        pb_GI2nonGI                 matlab.ui.control.Button
        pb_OpenDrawingTool          matlab.ui.control.Button
        uipanel_AxisType            matlab.ui.container.ButtonGroup
        rd_AxisStyle_dX             matlab.ui.control.RadioButton
        rd_AxisStyle_af             matlab.ui.control.RadioButton
        rd_AxisStyle_qr             matlab.ui.control.RadioButton
        rd_AxisStyle_q              matlab.ui.control.RadioButton
        uipanel9                    matlab.ui.container.Panel
        cb_polarizationCorrection   matlab.ui.control.CheckBox
        cb_detSensorPathCorrection  matlab.ui.control.CheckBox
        cb_airpathcorrection        matlab.ui.control.CheckBox
        text39                      matlab.ui.control.Label
        ed_samplethickness          matlab.ui.control.EditField
        cb_samplethickness          matlab.ui.control.CheckBox
        text38                      matlab.ui.control.Label
        cb_transmittance            matlab.ui.control.CheckBox
        CB_exptimenormalize         matlab.ui.control.CheckBox
        text36                      matlab.ui.control.Label
        ed_ScaleField               matlab.ui.control.DropDown
        CB_ScaleI0                  matlab.ui.control.CheckBox
        CB_ApplyScale               matlab.ui.control.CheckBox
        text34                      matlab.ui.control.Label
        text33                      matlab.ui.control.Label
        ed_I0Ref                    matlab.ui.control.EditField
        ed_ScaleFactor              matlab.ui.control.EditField
        uipanel8                    matlab.ui.container.ButtonGroup
        CB_SubtractBackground       matlab.ui.control.CheckBox
        text35                      matlab.ui.control.Label
        ed_BackScaleFactor          matlab.ui.control.EditField
        uipanel5                    matlab.ui.container.Panel
        rd_Transpose                matlab.ui.control.CheckBox
        rd_LeftRight                matlab.ui.control.CheckBox
        rd_UpDown                   matlab.ui.control.CheckBox
        uipanel3                    matlab.ui.container.Panel
        pb_loadmask                 matlab.ui.control.Button
        pbLoadSpecFile              matlab.ui.control.Button
        ed_tthi                     matlab.ui.control.EditField
        text37                      matlab.ui.control.Label
        CB_SetupAutoUpdate          matlab.ui.control.CheckBox
        ed_tiltangle                matlab.ui.control.EditField
        ed_STDp                     matlab.ui.control.EditField
        pbloadEPICS                 matlab.ui.control.Button
        ed_Beta                     matlab.ui.control.EditField
        textBeta                    matlab.ui.control.Label
        ed_Edensity                 matlab.ui.control.EditField
        textEdensity                matlab.ui.control.Label
        ed_Offset                   matlab.ui.control.EditField
        textOffset                  matlab.ui.control.Label
        pbt_loadsetup               matlab.ui.control.Button
        pb_SDD                      matlab.ui.control.Button
        ed_Cy                       matlab.ui.control.EditField
        ed_Cx                       matlab.ui.control.EditField
        text21                      matlab.ui.control.Label
        ed_STDq                     matlab.ui.control.EditField
        text20                      matlab.ui.control.Label
        text19                      matlab.ui.control.Label
        ed_Xenergy                  matlab.ui.control.EditField
        textpixelsize               matlab.ui.control.Label
        ed_pSize                    matlab.ui.control.EditField
        ed_SDD                      matlab.ui.control.EditField
        text9                       matlab.ui.control.Label
        ed_ai                       matlab.ui.control.EditField
        laelai                      matlab.ui.control.Label
        pbt_PutCenter               matlab.ui.control.Button
        text2                       matlab.ui.control.Label
        text32                      matlab.ui.control.Label
        pbSAXS                      matlab.ui.control.Button
        pbSaveBinary                matlab.ui.control.Button
        pb_imganalysis              matlab.ui.control.Button
    end

    
    methods (Access = private)

        function ReadandSetValues(app, varargin)
            center(1) = str2double(char(cellstr(app.ed_Cx.Value)));
            center(2) = str2double(char(cellstr(app.ed_Cy.Value)));
            px = str2double(char(cellstr(app.ed_STDp.Value)));
            Q = str2double(char(cellstr(app.ed_STDq.Value)));
            xeng = str2double(char(cellstr(app.ed_Xenergy.Value)));
            psize = str2double(char(cellstr(app.ed_pSize.Value)));
            %offset = str2double(char(cellstr(app.edit23.Value)));
            waveln = eng2wl(xeng);
            SDD = str2double(char(cellstr(app.ed_SDD.Value)));
            ai = str2double(char(cellstr(app.ed_ai.Value)));
            tthi = str2double(char(cellstr(app.ed_tthi.Value)));
            offset = str2double(char(cellstr(app.ed_Offset.Value)));
            edensity = str2double(char(cellstr(app.ed_Edensity.Value)));
            beta = str2double(char(cellstr(app.ed_Beta.Value)));
            try
                %tiltangle = str2double(char(cellstr(app.ed_tiltangle.Value)));
                tiltangle = eval(char(cellstr(app.ed_tiltangle.Value)));
            catch
                tiltangle = 0;
            end
            %saxs = app.gisaxsleenew,'userdata');
            saxs = getgihandle;
            saxs.waveln = waveln;
            %saxs.offset', offset);
            saxs.center= center;
            saxs.SDD= SDD;
            saxs.xeng= xeng;
            saxs.psize= psize;
            saxs.pxQ= Q/px;
            saxs.px= px;
            saxs.Q= Q;
            saxs.ai= ai;
            saxs.tthi = tthi;
            saxs.offset= offset;
            saxs.edensity= edensity;
            saxs.beta= beta;
            saxs.tiltangle = tiltangle;
            %set(app.gisaxsleenew,'userdata', saxs);
            setgihandle(saxs);
        end

        
        function SetSAXSValues(app)
            saxs = getgihandle;
            %center(1) = str2double(char(cellstr(app.ed_Cx.Value)));
            if isfield(saxs, 'center')
                app.ed_Cx.Value = saxs.center(1);
                app.ed_Cy.Value = saxs.center(2);
            end
            if isfield(saxs, 'xeng')
                app.ed_Xenergy.Value = saxs.xeng;
            end
            %psize = str2double(char(cellstr(get(app.ed_pSize.Value)));
            if isfield(saxs, 'psize')
                app.ed_pSize.Value = saxs.psize;
            end
            %offset = str2double(char(cellstr(get(app.edit23.Value)));
            %waveln = eng2wl(xeng);
            %SDD = str2double(char(cellstr(get(app.ed_SDD.Value)));
            if isfield(saxs, 'SDD')
                app.ed_SDD.Value = saxs.SDD;
            end
            if isfield(saxs, 'tiltangle')
                if numel(saxs.tiltangle)>1
                    str = strcat('[',num2str(saxs.tiltangle),']');
                else
                    str = num2str(saxs.tiltangle);
                end
                app.ed_tiltangle.Value = str;
            end
            if isfield(saxs, 'ai')
                app.ed_ai.Value = saxs.ai;
            end
            if isfield(saxs, 'Q')
                app.ed_STDq.Value = saxs.Q;
            end
            if isfield(saxs, 'px')
                if saxs.px ~= app.ed_STDp.Value
                    app.ed_STDp.Value = num2str(saxs.px);
                    Q = str2double(app.ed_STDq.Value);
                    psize = str2double(app.ed_pSize.Value);
                    waveln = eng2wl(str2double(app.ed_Xenergy.Value));
                    SDD = SDDcal(saxs.px, Q, psize, waveln);
                    saxs.pxQ = Q/saxs.px;
                    app.ed_SDD.Value = num2str(SDD);
                end
            end
            %ai = str2double(char(cellstr(get(app.ed_ai.Value)));
            % set(app.ed_ai.Value = saxs.ai);
            %offset = str2double(char(cellstr(get(app.ed_Offset.Value)));
            if isfield(saxs, 'offset')
                app.ed_Offset.Value = saxs.offset;
            end
            %edensity = str2double(char(cellstr(get(app.ed_Edensity.Value)));
            %beta = str2double(char(cellstr(get(app.ed_Beta.Value)));
            %set(app.ed_Edensity.Value = saxs.edensity);
            %set(app.ed_Beta.Value = saxs.beta);
            imgh = evalin('base', 'SAXSimageviewerhandle');
            setgihandle(saxs)
            figure(imgh);
        end
        
        function imgoperation(app)
            saxs=getgihandle;
            %imgopr = getfield(saxs, 'imgoperation');
            imgopr.Transpose = app.rd_Transpose.Value;
            imgopr.UpDown = app.rd_UpDown.Value;
            imgopr.LeftRight = app.rd_LeftRight.Value;
            saxs.imgoperation = imgopr;
            setgihandle(saxs);
        end
        
        % function lblistcallback(app, hObject, eventdata, varargin)
        %     if ~isempty(varargin)
        %         handles = getappdata(varargin{1}, 'handle');
        %     else
        %         return
        %     end
        %     if strcmp(app.SAXSfileList.Value,'open') % If double click
        %         index_selected = app.lbfile.Value;
        %         file_list = app.lbfile.Value;
        %         filename = file_list{index_selected}; % Item selected in list box
        %         wildcard = char(cellstr(app.ed_wild.Value));
        %         if handles.is_dir(handles.sorted_index(index_selected))
        %             cd (filename);
        %             load_listbox(pwd, wildcard, handles);
        %             app.ed_dir.Value = pwd;
        %         else
        %             [path,name,ext,ver] = fileparts(filename);
        %     %        hSAXSlee=findobj('Tag','gisaxsleenew');
        %     %        if isempty(hSAXSlee)==1
        %     %            disp('An empty structure in Userdata')
        %     %        else
        %                 saxs=getgihandle;
        %                 saxs=openccdfile({path, [name,ext]},saxs);
        %                 setgihandle(saxs);
        %     %        end
        %         end
        %     end
        % end
        % 
        % function pb_Expsave_Callback(app, hObject, eventdata, handles)
        %     % --- Executes on button press in pb_Expsave.
        % 
        %     % hObject    handle to pb_Expsave (see GCBO)
        %     % eventdata  reserved - to be defined in a future version of MATLAB
        %     % handles    structure with handles and user data (see GUIDATA)
        %     dirF = pwd;
        %     filen = app.ed_ExpFilename.Value;
        % 
        %     fid = fopen(filen,'w');
        %     S(1) = {'X-ray Energy(keV) : '};
        %     S(2) = {'Wavelength(A) : '};
        %     S(3) = {'Psize(Pixel size, mm) : '};
        %     S(4) = {'XCenter : '};
        %     S(5) = {'YCenter : '};
        %     S(6) = {'Pixel Distance(Standard) : '};
        %     S(7) = {'q value(A-1, Standard) : '};
        %     S(8) = {'SDD(mm) : '};
        %     S(9) = {'*End'};
        %     data(1) = str2double(char(cellstr(app.ed_Xenergy.Value)));
        %     data(2) = 12.398/data(1);
        %     data(3) = str2double(char(cellstr(app.ed_pSize.Value)));
        %     data(4) = str2double(char(cellstr(app.ed_Cx.Value)));
        %     data(5) = str2double(char(cellstr(app.ed_Cy.Value)));
        %     data(6) = str2double(char(cellstr(app.ed_STDp.Value)));
        %     data(7) = str2double(char(cellstr(app.ed_STDq.Value)));
        %     data(8) = str2double(char(cellstr(app.ed_SDD.Value)));
        %     for i=1:8
        %         strsave = [S{i}, num2str(data(i)), '\n'];
        %         fprintf(fid, strsave);
        %     end
        %     fprintf(fid, S{9});
        %     fclose(fid);
        % end
        
        % function pbfilelist_Callback(app, hObject, eventdata, handles)
        %     % hObject    handle to pbfilelist (see GCBO)
        %     % eventdata  reserved - to be defined in a future version of MATLAB
        %     % handles    structure with handles and user data (see GUIDATA)
        %     h = SAXSfilelist;
        %     hlbl = findobj(h, 'tag', 'lbfile');
        %     set(hlbl, 'callback', {@app.lblistcallback, h});
        % end
        % 
        % function varargout = pbload_Callback(app, h, eventdata, handles, varargin)
        %     % --------------------------------------------------------------------
        % 
        %     %saxs=getgihandle;
        %     saxs = getgihandle;
        %     eventdata = varargin{1};
        %     if isempty(eventdata)
        %         saxs = openccdfile({},saxs);
        %     else
        %         saxs = openccdfile(eventdata, saxs);
        %     end
        %     setgihandle(saxs);
        % end
        % 
        % function pbloglin_Callback(app, hObject, eventdata, handles)
        %     % --- Executes on button press in pbloglin.
        % 
        %     % hObject    handle to pbloglin (see GCBO)
        %     % eventdata  reserved - to be defined in a future version of MATLAB
        %     % handles    structure with handles and user data (see GUIDATA)
        %     loglin
        % end
        % 
        % function pbvertline_Callback(app, hObject, eventdata, handles)
        %     % --- Executes on button press in pbvertline.
        % 
        %     % hObject    handle to pbvertline (see GCBO)
        %     % eventdata  reserved - to be defined in a future version of MATLAB
        %     % handles    structure with handles and user data (see GUIDATA)
        %     b = findobj('Tag', 'axis_img');
        %     axes(b);
        %     %pX = str2double(char(cellstr(app.pX.Value)));
        %     pY = str2double(char(cellstr(app.txtHorzline.Value)));
        %     center = handles.center;
        %     ai = handles.hai;
        %     SDD = handles.hSDD;
        %     pixelsize = handles.hpixel_size;
        %     dataimg = double(get_imgFigure);
        %     imgsize = size(dataimg);
        %     pX = 1:imgsize(1);
        %     %thf = rad2deg(atan((pX - center(1))/SDD*pixelsize));
        %     af = rad2deg(atan((-pX + center(2))./sqrt((SDD/pixelsize)^2 + (pY-center(1))^2))) - ai;
        % 
        %     hd20 = figure(20);
        %     set(hd20, 'Tag', '1D_Data_Display');
        %     set(hd20, 'Name', '1D plotter');
        %     plot(af, dataimg(:, pY));D = [reshape(af, length(af), 1), reshape(dataimg(:, pY), length(af), 1)];
        %     size(D);
        %     handles.imgh1d = hd20;
        %     handles.data1d = D;
        %     guidata(hObject, handles);
        % end
        % 
        % function phHorzline_Callback(app, hObject, eventdata, handles)
        %     % --- Executes on button press in phHorzline.
        % 
        %     % hObject    handle to phHorzline (see GCBO)
        %     % eventdata  reserved - to be defined in a future version of MATLAB
        %     % handles    structure with handles and user data (see GUIDATA)
        %     b = findobj('Tag', 'axis_img');
        %     axes(b);
        %     %pX = str2double(char(cellstr(app.pX.Value)));
        %     pX = str2double(char(cellstr(app.txtVertline.Value)));
        %     center = handles.center;
        %     ai = handles.hai;
        %     SDD = handles.hSDD;
        %     pixelsize = handles.hpixel_size;
        %     dataimg = double(get_imgFigure);
        %     imgsize = size(dataimg);pY = 1:imgsize(2);
        %     thf = rad2deg(atan((pY - center(1))/SDD*pixelsize));
        % 
        %     hd20 = figure(20);
        %     set(hd20, 'Tag', '1D_Data_Display');
        %     set(hd20, 'Name', '1D plotter');
        %     plot(thf, dataimg(pX, :));D = [reshape(thf, length(thf), 1), reshape(dataimg(pX, :), length(thf), 1)];
        %     size(D)
        %     handles.imgh1d = hd20;
        %     handles.data1d = D;
        %     guidata(hObject, handles);
        % end
        % % 
        % function varargout = pushbutton2_Callback(app, h, eventdata, handles, varargin)
        %     % --------------------------------------------------------------------
        % 
        %     a = findobj('Tag', 'imgFigure');
        %     figure(a);
        %     point = ginput(2);
        % 
        %     center(1) = handles.center(1);
        %     center(2) = handles.center(2);
        % 
        %     for i=1:1:2
        %        X = point(i, 1);
        %        Y = point(i, 2);
        %        mu(i) = calmu(center, X, Y);
        %     end
        % 
        %     guidata(h, handles);
        %     app.edit1.Value =  mu(1);
        %     app.edit2.Value =  mu(2);
        % end
        % 
        % function varargout = pushbutton3_Callback(app, h, eventdata, handles, varargin)
        %     % --------------------------------------------------------------------
        % 
        %     center = handles.center;
        %     px = str2double(char(cellstr(app.edit5.Value)));
        %     Q = str2double(char(cellstr(app.edit6.Value)));
        % 
        %     D = circular(double(get_imgFigure), center, [px Q]);
        %     hd20 = figure(20);
        %     set(hd20, 'Tag', '1D_Data_Display');
        %     set(hd20, 'Name', '1D plotter');
        %     plot(D(:,1), D(:,2));
        %     size(D)
        %     handles.imgh1d = hd20;
        %     handles.data1d = D;
        %     guidata(h, handles);
        % end
        % 
        % function varargout = pushbutton4_Callback(app, h, eventdata, handles, varargin)
        %     % --------------------------------------------------------------------
        % 
        %     center = handles.center;
        %     px = str2double(char(cellstr(app.edit5.Value)));
        %     Q = str2double(char(cellstr(app.edit6.Value)));
        %     mu(1) = str2double(char(cellstr(app.edit1.Value)));
        %     mu(2) = str2double(char(cellstr(app.edit2.Value)));
        %     if mu(1) > mu(2)
        %         mu = double(mu);
        %         mu(1) = mu(1) - 360;
        %     end
        % 
        %     D = circular(double(get_imgFigure), center, [px Q], mu(1), mu(2), 0);
        %     hd20 = figure(20);
        %     set(hd20, 'Tag', '1D_Data_Display');
        %     set(hd20, 'Name', '1D plotter');
        % 
        %     plot(D(:,1), D(:,2));
        %     handles.imgh1d = hd20;
        %     handles.data1d = D;
        %     guidata(h, handles);
        % end
        % 
        % function radiobutton12_Callback(app, hObject, eventdata, handles)
        %     % --- Executes on button press in radiobutton12.
        % 
        %     % hObject    handle to radiobutton12 (see GCBO)
        %     % eventdata  reserved - to be defined in a future version of MATLAB
        %     % handles    structure with handles and user data (see GUIDATA)
        % 
        %     % Hint: get(hObject.Value returns toggle state of radiobutton12
        %     saxs = getgihandle;
        %     if isfield(saxs, 'norminfo')
        %         saxs.norminfo.xeng = get(hObject, 'value');
        %         saxs.norminfo.xengcol = str2double(app.ed_EngCol.Value);
        %         setgihandle(saxs);
        %     else
        %         disp('norminfo is not created yet, see radiobutton12_callback function')
        %     end
        %     setgihandle(saxs)
        % end
        
        % function radiobutton13_Callback(app, hObject, eventdata, handles)
        %     % --- Executes on button press in radiobutton13.
        % 
        %     % hObject    handle to radiobutton13 (see GCBO)
        %     % eventdata  reserved - to be defined in a future version of MATLAB
        %     % handles    structure with handles and user data (see GUIDATA)
        % 
        %     % Hint: get(hObject.Value returns toggle state of radiobutton13
        %     saxs = getgihandle;
        %     if isfield(saxs, 'norminfo')
        %         saxs.norminfo.norm = get(hObject.Value;
        %         saxs.norminfo.normcol = str2double(app.ed_NormCol.Value);
        %         setgihandle(saxs);
        %     else
        %         disp('norminfo is not created yet, see radiobutton12_callback function')
        %     end
        %     setgihandle(saxs)
        % end
        
        % function rb_markCenter_Callback(app, hObject, eventdata, handles)
        %     % --- Executes on button press in rb_markCenter.
        % 
        % 
        %     if get(hObject, 'Value') == 1
        %         %b = findobj(handles.ImgNew, 'Type', 'axes');
        %         b = findobj(gcf, 'Type', 'axes');
        %         axes(b);
        %         Cx = str2double(char(cellstr(app.ed_Cx.Value)));
        %         Cy = str2double(char(cellstr(app.ed_Cy.Value)));
        % 
        %         temp = -100:100;X = zeros(1,201);Y=zeros(1,201);
        %         Xt = X + Cx*ones(1,201);Yt = Y + Cy*ones(1,201) + temp;
        %         ah = line(Xt, Yt);set(ah, 'Color', [1 0 0]);
        %         Xt = X + Cx*ones(1,201) + temp;Yt = Y + Cy*ones(1,201);
        %         bh = line(Xt, Yt);set(bh, 'Color', [1 0 0]);
        %         handles.cross = [ah, bh];
        %         guidata(hObject, handles);
        %     else
        %         if isfield(handles, 'cross')
        %             if ~isempty(handles.cross)
        %                 delete(handles.cross);
        %             end
        %         end
        %     end
        % end
        % 
        % 
        % function rbai_column_Callback(app, hObject, eventdata, handles)
        %     % --- Executes on button press in rbai_column.
        % 
        %     % hObject    handle to rbai_column (see GCBO)
        %     % eventdata  reserved - to be defined in a future version of MATLAB
        %     % handles    structure with handles and user data (see GUIDATA)
        % 
        %     % Hint: get(hObject.Value returns toggle state of rbai_column
        %     saxs = getgihandle;
        %     if isfield(saxs, 'norminfo')
        %         saxs.norminfo.ai= hObject.Value;
        %         saxs.norminfo.aicol = str2double(app.ed_aiCol.Value);
        %         setgihandle(saxs);
        %     else
        %         disp('norminfo is not created yet, see rbai_column_Callback function')
        %     end
        %     setgihandle(saxs)
        % end
        
        % function rd_Gbinary_Callback(app, hObject, eventdata, handles)
        %     % --- Executes on button press in rd_Gbinary.
        % 
        %     % hObject    handle to rd_Gbinary (see GCBO)
        %     % eventdata  reserved - to be defined in a future version of MATLAB
        %     % handles    structure with handles and user data (see GUIDATA)
        % 
        %     % Hint: get(hObject.Value returns toggle state of rd_Gbinary
        % 
        %     startbyte = str2double(char(cellstr(app.ed_GB_sb.Value)));
        %     dimX = str2double(char(cellstr(app.ed_GB_dimX.Value)));
        %     dimY = str2double(char(cellstr(app.ed_GB_dimY.Value)));
        %     byte = char(cellstr(app.ed_GB_byte.Value));
        %     GB.startbyte = startbyte;
        %     GB.dimX = dimX;
        %     GB.dimY = dimY;
        %     GB.byte = byte;
        %     if (get(hObject, 'Value') == 1)
        %     %    hSAXSlee=findobj('Tag','gisaxsleenew');
        %         saxs=getgihandle;
        %         saxs.CCD', 'GBinary');
        %         saxs.GB', GB);
        %         setgihandle(saxs);
        %     end
        % end
        % 
        % function rd_GoldD_Callback(app, hObject, eventdata, handles)
        %     % --- Executes on button press in rd_GoldD.
        % 
        %     % hObject    handle to rd_GoldD (see GCBO)
        %     % eventdata  reserved - to be defined in a future version of MATLAB
        %     % handles    structure with handles and user data (see GUIDATA)
        % 
        %     % Hint: get(hObject.Value returns toggle state of rd_GoldD
        %     if (get(hObject, 'Value') == 1)
        %     %    hSAXSlee=findobj('Tag','gisaxsleenew');
        %         saxs=getgihandle;
        %         saxs.CCD = 'goldccd';
        %         setgihandle(saxs);
        %     end
        % end
        % 
        % function rd_MarCCD_Callback(app, hObject, eventdata, handles)
        %     % --- Executes on button press in rd_MarCCD.
        % 
        %     % hObject    handle to rd_MarCCD (see GCBO)
        %     % eventdata  reserved - to be defined in a future version of MATLAB
        %     % handles    structure with handles and user data (see GUIDATA)
        % 
        %     % Hint: get(hObject.Value returns toggle state of rd_MarCCD
        %     if (get(hObject, 'Value') == 1)
        %     %    hSAXSlee=findobj('Tag','gisaxsleenew');
        %         saxs=getgihandle;
        %         saxs.CCD = 'mar165';
        %         setgihandle(saxs);
        %     end
        % end
        % 
        % function rd_PrinceCCD_Callback(app, hObject, eventdata, handles)
        %     % --------------------------------------------------------------------
        %     % --- Executes on button press in rd_MarCCD.
        % 
        %     % hObject    handle to rd_MarCCD (see GCBO)
        %     % eventdata  reserved - to be defined in a future version of MATLAB
        %     % handles    structure with handles and user data (see GUIDATA)
        % 
        %     % Hint: get(hObject.Value returns toggle state of rd_MarCCD
        %     if (get(hObject, 'Value') == 1)
        %     %    hSAXSlee=findobj('Tag','gisaxsleenew');
        %         saxs=getgihandle;
        %         saxs.CCD', 'princeton');
        %         saxs.frame', 1);
        %         setgihandle(saxs);
        %     end
        % end
        % 
        % function varargout = resbtn_Callback(app, h, eventdata, handles, varargin)
        %     % --------------------------------------------------------------------
        % 
        %     rescale(1) = str2double(char(cellstr(app.rstxt1.Value)));
        %     rescale(2) = str2double(char(cellstr(app.rstxt2.Value)));
        % 
        %     imghandle = figure(handles.imgh);
        %     imagesc(double(get_imgFigure), rescale);axis image;
        % end
        % 
        % function varargout = savebtn_Callback(app, h, eventdata, handles, varargin)
        %     % --------------------------------------------------------------------
        % 
        %     if ~exist('file')
        %     	[file, datadir]=uiputfile('*.dat','Select data file');
        %     	if file==0 return; end
        %         file = [datadir, file];
        %     end
        %     [x, y, z] = getlinefig(gcf);
        %     if prod(size(x)) == prod(size(z.qp))
        %         k = [z.qp', y];
        %     elseif prod(size(x)) ~= prod(size(z.qp)) & (isfield(z, 'af') == 1)
        %         k = [z.af', y];
        %     elseif prod(size(x)) ~= prod(size(z.qp)) & (isfield(z, 'af') ~= 1) & (isfield(z, 'QperP') == 1)
        %         k = [x*z.QperP, y];
        %     elseif prod(size(x)) ~= prod(size(z.qp)) & (isfield(z, 'af') ~= 1) & (isfield(z, 'QperP') ~= 1)
        %         k = [x, y];
        %     end
        %     strsave = ['save ', file, ' k -ascii'];
        %     eval(strsave);
        % end
        % 
        % function th2f_ref_CreateFcn(app, hObject, eventdata, handles)
        %     % --- Executes during object creation, after setting all properties.
        % 
        %     % hObject    handle to th2f_ref (see GCBO)
        %     % eventdata  reserved - to be defined in a future version of MATLAB
        %     % handles    empty - handles not created until after all CreateFcns called
        % 
        %     % Hint: edit controls usually have a white background on Windows.
        %     %       See ISPC and COMPUTER.
        %     if ispc
        %         set(hObject,'BackgroundColor','white');
        %     else
        %         set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
        %     end
        % end
        % 
        % function th2frefpixel_CreateFcn(app, hObject, eventdata, handles)
        %     % --- Executes during object creation, after setting all properties.
        % 
        %     % hObject    handle to th2frefpixel (see GCBO)
        %     % eventdata  reserved - to be defined in a future version of MATLAB
        %     % handles    empty - handles not created until after all CreateFcns called
        % 
        %     % Hint: edit controls usually have a white background on Windows.
        %     %       See ISPC and COMPUTER.
        %     if ispc
        %         set(hObject,'BackgroundColor','white');
        %     else
        %         set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
        %     end
        % end
        % 
        % function txtHorzline_CreateFcn(app, hObject, eventdata, handles)
        %     % --- Executes during object creation, after setting all properties.
        % 
        %     % hObject    handle to txtHorzline (see GCBO)
        %     % eventdata  reserved - to be defined in a future version of MATLAB
        %     % handles    empty - handles not created until after all CreateFcns called
        % 
        %     % Hint: edit controls usually have a white background on Windows.
        %     %       See ISPC and COMPUTER.
        %     if ispc
        %         set(hObject,'BackgroundColor','white');
        %     else
        %         set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
        %     end
        % end
        % 
        % function txtVertline_CreateFcn(app, hObject, eventdata, handles)
        %     % --- Executes during object creation, after setting all properties.
        % 
        %     % hObject    handle to txtVertline (see GCBO)
        %     % eventdata  reserved - to be defined in a future version of MATLAB
        %     % handles    empty - handles not created until after all CreateFcns called
        % 
        %     % Hint: edit controls usually have a white background on Windows.
        %     %       See ISPC and COMPUTER.
        %     if ispc
        %         set(hObject,'BackgroundColor','white');
        %     else
        %         set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
        %     end
        % end
        % 
        % function txt_2thi_CreateFcn(app, hObject, eventdata, handles)
        %     % --- Executes during object creation, after setting all properties.
        % 
        %     % hObject    handle to txt_2thi (see GCBO)
        %     % eventdata  reserved - to be defined in a future version of MATLAB
        %     % handles    empty - handles not created until after all CreateFcns called
        % 
        %     % Hint: edit controls usually have a white background on Windows.
        %     %       See ISPC and COMPUTER.
        %     if ispc
        %         set(hObject,'BackgroundColor','white');
        %     else
        %         set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
        %     end
        % end
        % 
        % function txt_ai_CreateFcn(app, hObject, eventdata, handles)
        %     % --- Executes during object creation, after setting all properties.
        % 
        %     % hObject    handle to txt_ai (see GCBO)
        %     % eventdata  reserved - to be defined in a future version of MATLAB
        %     % handles    empty - handles not created until after all CreateFcns called
        % 
        %     % Hint: edit controls usually have a white background on Windows.
        %     %       See ISPC and COMPUTER.
        %     if ispc
        %         set(hObject,'BackgroundColor','white');
        %     else
        %         set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
        %     end
        % end
        % 
        % function txtaf_CreateFcn(app, hObject, eventdata, handles)
        %     % --- Executes during object creation, after setting all properties.
        % 
        %     % hObject    handle to txtaf (see GCBO)
        %     % eventdata  reserved - to be defined in a future version of MATLAB
        %     % handles    empty - handles not created until after all CreateFcns called
        % 
        %     % Hint: edit controls usually have a white background on Windows.
        %     %       See ISPC and COMPUTER.
        %     if ispc
        %         set(hObject,'BackgroundColor','white');
        %     else
        %         set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
        %     end
        % end
        % 
        % function txtqx_CreateFcn(app, hObject, eventdata, handles)
        %     % --- Executes during object creation, after setting all properties.
        % 
        %     % hObject    handle to txtqx (see GCBO)
        %     % eventdata  reserved - to be defined in a future version of MATLAB
        %     % handles    empty - handles not created until after all CreateFcns called
        % 
        %     % Hint: edit controls usually have a white background on Windows.
        %     %       See ISPC and COMPUTER.
        %     if ispc
        %         set(hObject,'BackgroundColor','white');
        %     else
        %         set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
        %     end
        % end
        % 
        % function txtqy_CreateFcn(app, hObject, eventdata, handles)
        %     % --- Executes during object creation, after setting all properties.
        % 
        %     % hObject    handle to txtqy (see GCBO)
        %     % eventdata  reserved - to be defined in a future version of MATLAB
        %     % handles    empty - handles not created until after all CreateFcns called
        % 
        %     % Hint: edit controls usually have a white background on Windows.
        %     %       See ISPC and COMPUTER.
        %     if ispc
        %         set(hObject,'BackgroundColor','white');
        %     else
        %         set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
        %     end
        % end
        % 
        % function txtqz_CreateFcn(app, hObject, eventdata, handles)
        %     % --- Executes during object creation, after setting all properties.
        % 
        %     % hObject    handle to txtqz (see GCBO)
        %     % eventdata  reserved - to be defined in a future version of MATLAB
        %     % handles    empty - handles not created until after all CreateFcns called
        % 
        %     % Hint: edit controls usually have a white background on Windows.
        %     %       See ISPC and COMPUTER.
        %     if ispc
        %         set(hObject,'BackgroundColor','white');
        %     else
        %         set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
        %     end
        % end
        % 
        % function txtthf_CreateFcn(app, hObject, eventdata, handles)
        %     % --- Executes during object creation, after setting all properties.
        % 
        %     % hObject    handle to txtthf (see GCBO)
        %     % eventdata  reserved - to be defined in a future version of MATLAB
        %     % handles    empty - handles not created until after all CreateFcns called
        % 
        %     % Hint: edit controls usually have a white background on Windows.
        %     %       See ISPC and COMPUTER.
        %     if ispc
        %         set(hObject,'BackgroundColor','white');
        %     else
        %         set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
        %     end
        % end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Load data for component configuration
            componentData = load('gisaxsleenew_App.mat');
            
            % Set component properties that require runtime configuration
            app.gisaxsleenew.UserData = componentData.gisaxsleenew.UserData;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ReadandSetValues(app)

        end

        % Value changed function: CB_ApplyScale
        function CB_ApplyScale_Callback(app, event)
            % --- Executes on button press in CB_ApplyScale.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to CB_ApplyScale (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject.Value returns toggle state of CB_ApplyScale
            saxs = getgihandle;
            if event.Value
                saxs.qScaleFactor = 1;
            else
                saxs.qScaleFactor = 0;
            end
            setgihandle(saxs);
            ed_ScaleFactor_Callback(app);
        end

        % Value changed function: CB_ScaleI0
        function CB_ScaleI0_Callback(app, event)
            % --- Executes on button press in CB_ScaleI0.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to CB_ScaleI0 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject.Value returns toggle state of CB_ScaleI0
            saxs = getgihandle;
            if isfield(saxs, 'specfile')
                file = saxs.specfile;
            else
                file = '';
            end
            if event.Value
                saxs.qScaleI0 = 1;
                if ~isempty(file)
                    c = specSAXSn2(file);
                else
                    errordlg('Load 12ID-B specfile so that you can select a field.', 'Specfile Error');
                end
            else
                saxs.qScaleI0 = 0;
                c = {};
            end

            app.ed_ScaleField.Items =  c;
            
            setgihandle(saxs);
            ed_I0Ref_Callback(app, event);
        end

        % Value changed function: CB_SetupAutoUpdate
        function CB_SetupAutoUpdate_Callback(app, event)
            % --- Executes on button press in CB_SetupAutoUpdate.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to CB_SetupAutoUpdate (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject.Value returns toggle state of CB_SetupAutoUpdate
            saxs = getgihandle;
            saxs.isAutoSetupUpdate = event.Value;
            setgihandle(saxs);
        end

        % Value changed function: CB_SubtractBackground
        function CB_SubtractBackground_Callback(app, event)
            % --- Executes on button press in CB_SubtractBackground.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to CB_SubtractBackground (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject.Value returns toggle state of CB_SubtractBackground
            saxs = getgihandle;
            if event.Value
                saxs.qBackSubtract = 1;
            else
                saxs.qBackSubtract = 0;
            end
            %setgihandle(saxs);
            %saxs = getgihandle;
            saxs.BackScaleFactor = str2double(app.ed_BackScaleFactor.Value);
            setgihandle(saxs);
        end

        % Value changed function: CB_exptimenormalize
        function CB_exptimenormalize_Callback(app, event)
            % --- Executes on button press in CB_exptimenormalize.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to CB_exptimenormalize (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject.Value returns toggle state of CB_exptimenormalize
            saxs = getgihandle;
            if event.Value
                if ~isfield(saxs, 'specfile')
                    errordlg('Spec file is not specified, thus cannot determine time.', 'Specfile Error');
                end
                saxs.qtime_normalize = 1;
            else
                saxs.qtime_normalize = 0;
            end
            setgihandle(saxs);
        end

        % Value changed function: cb_airpathcorrection
        function cb_airpathcorrection_Callback(app, event)
            % --- Executes on button press in cb_airpathcorrection.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cb_airpathcorrection (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject.Value returns toggle state of cb_airpathcorrection
            saxs = getgihandle;
            if event.Value
                ang = det_geometry(saxs);
                tthspace = reshape(ang(:,3), saxs.imgsize);
                saxs.airpath = correctfactor_pathabsorption(tthspace, saxs.SDD);
            else
                if isfield(saxs, 'airpath')
                    saxs = rmfield(saxs, 'airpath');
                end
            end
            setgihandle(saxs);
        end

        % Value changed function: cb_detSensorPathCorrection
        function cb_detSensorPathCorrection_Callback(app, event)
            % --- Executes on button press in cb_detSensorPathCorrection.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cb_detSensorPathCorrection (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject.Value returns toggle state of cb_detSensorPathCorrection
            saxs = getgihandle;
            if event.Value
                ang = det_geometry(saxs);
                tthspace = reshape(ang(:,3), saxs.imgsize);
                saxs.detpath = correctfactor_detSensor(tthspace, saxs.xeng);
            else
                if isfield(saxs, 'detpath')
                    saxs = rmfield(saxs, 'detpath');
                end
            end
            setgihandle(saxs);
        end

        % Value changed function: cb_polarizationCorrection
        function cb_polarizationCorrection_Callback(app, event)
            % --- Executes on button press in cb_polarizationCorrection.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cb_polarizationCorrection (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject.Value returns toggle state of cb_polarizationCorrection
            saxs = getgihandle;
            if event.Value
                ang = det_geometry(saxs);
                saxs.polarization = reshape(correctfactor_polarization(ang), saxs.imgsize);
            else
                if isfield(saxs, 'polarization')
                    saxs = rmfield(saxs, 'polarization');
                end
            end
            setgihandle(saxs);
        end

        % Value changed function: cb_samplethickness
        function cb_samplethickness_Callback(app, event)
            % --- Executes on button press in cb_samplethickness.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cb_samplethickness (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject.Value returns toggle state of cb_samplethickness
            saxs = getgihandle;
            if event.Value
                saxs.qSampleThickness = 1;
            %    saxs.SampleThickness = str2double(app.ed_samplethickness.Value);
            else
                saxs.qSampleThickness = 0;
            end
            setgihandle(saxs);
            ed_samplethickness_Callback(app)
        end

        % Value changed function: cb_transmittance
        function cb_transmittance_Callback(app, event)
            % --- Executes on button press in cb_transmittance.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cb_transmittance (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject.Value returns toggle state of cb_transmittance
            saxs = getgihandle;
            if event.Value
                saxs.qTrans4BSF = 1;
            else
                saxs.qTrans4BSF = 0;
            end
            setgihandle(saxs);
        end

        % Value changed function: ed_BackScaleFactor
        function ed_BackScaleFactor_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ed_BackScaleFactor (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject.Value returns contents of ed_BackScaleFactor as text
            %        str2double(get(hObject.Value) returns contents of ed_BackScaleFactor as a double
            saxs = getgihandle;
            saxs.BackScaleFactor = str2double(event.Value);
            setgihandle(saxs);
        end

        % Value changed function: ed_Beta
        function ed_Beta_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool

            ReadandSetValues(app)
        end

        % Value changed function: ed_Cx
        function ed_Cx_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool

            ReadandSetValues(app)
        end

        % Value changed function: ed_Cy
        function ed_Cy_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            ReadandSetValues(app)
        end

        % Value changed function: ed_Edensity
        function ed_Edensity_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool

            ReadandSetValues(app)
        end

        % Value changed function: ed_I0Ref
        function ed_I0Ref_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            
            saxs = getgihandle;
            saxs.ScaleI0 = str2double(app.ed_I0Ref.Value);
            setgihandle(saxs);
        end

        % Value changed function: ed_Offset
        function ed_Offset_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool

            ReadandSetValues(app)
        end

        % Value changed function: ed_SDD
        function ed_SDD_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool

            %center(1) = str2double(char(cellstr(app.ed_Cx.Value)));
            %center(2) = str2double(char(cellstr(app.ed_Cy.Value)));
            Q = str2double(char(cellstr(app.ed_STDq.Value)));
            xeng = str2double(char(cellstr(app.ed_Xenergy.Value)));
            psize = str2double(char(cellstr(app.ed_pSize.Value)));
            SDD = str2double(char(cellstr(app.ed_SDD.Value)));
            %offset = str2double(char(cellstr(app.edit23.Value)));
            waveln = eng2wl(xeng);
            px = SDDcal(SDD, Q, psize, waveln, 'sdd');
            app.ed_STDp.Value =  sprintf('%0.3f', px);
            %saxs = app.gisaxsleenew,'userdata');
            saxs = getgihandle;
            %saxs.waveln', waveln);
            %saxs.offset', offset);
            %saxs.center', center);
            %saxs.xeng', xeng);
            %saxs.psize', psize);
            %saxs.pxQ', Q/px);
            %app.gisaxsleenew,'userdata', saxs);
            setgihandle(saxs);
            ReadandSetValues(app)
        end

        % Value changed function: ed_STDp
        function ed_STDp_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool

            ReadandSetValues(app)
        end

        % Value changed function: ed_STDq
        function ed_STDq_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool

            ReadandSetValues(app)
        end

        % Value changed function: ed_ScaleFactor
        function ed_ScaleFactor_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool

            saxs = getgihandle;
            saxs.ScaleFactor = str2double(app.ed_ScaleFactor.Value);
            setgihandle(saxs);
        end

        % Value changed function: ed_ScaleField
        function ed_ScaleField_Callback(app, event)
            % --- Executes on selection change in ed_ScaleField.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ed_ScaleField (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject.Value) returns ed_ScaleField contents as cell array
            %        contents{get(hObject.Value} returns selected item from ed_ScaleField
            saxs = getgihandle;
            %contents = cellstr(get(hObject.Value);
            %FN = contents{get(hObject.Value};
            FN = event.Source.Value;
            if ~isempty(FN)
                saxs.ScaleI0Fieldname = FN;
            else
                saxs = rmfield(saxs, 'ScaleI0Fieldname');
            end
            setgihandle(saxs);
        end

        % Value changed function: ed_Xenergy
        function ed_Xenergy_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool

            ReadandSetValues(app)
        end

        % Value changed function: ed_ai
        function ed_ai_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool

            ReadandSetValues(app)
        end

        % Value changed function: ed_pSize
        function ed_pSize_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool

            ReadandSetValues(app)
        end

        % Value changed function: ed_samplethickness
        function ed_samplethickness_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            saxs = getgihandle;
            saxs.SampleThickness = str2double(ap.ed_samplethickness.Value)*0.1;% convert mm to cm
            setgihandle(saxs);
        end

        % Value changed function: ed_tiltangle
        function ed_tiltangle_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            ReadandSetValues(app)
        end

        % Value changed function: ed_tthi
        function ed_tthi_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            ReadandSetValues(app)
        end

        % Window button down function: gisaxsleenew
        function gisaxsleenew_WindowButtonDownFcn(app, event)
            % --- Executes on mouse press over figure background, over a disabled or
            % --- inactive control, or over an axes background.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to gisaxsleenew (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            saxs = getgihandle;
            if isfield(saxs, 'ai')
                app.ed_ai.Value = num2str(saxs.ai);
                app.ed_Xenergy.Value = num2str(saxs.xeng);
            end
        end

        % Button pushed function: pbLoadSpecFile
        function pbLoadSpecFile_Callback(app, event)
            % --- Executes on button press in pbLoadSpecFile.
            
            [file, datadir]=uigetfile({'*.*','All format(*.*)'});
            if file==0 
                return; 
            end
            file = [datadir, file];
            %app.ed_histfile.Value =  file);
            %hSAXSlee = findobj('Tag', 'gisaxsleenew');
            %saxs = get(hSAXSlee, 'Userdata');
            saxs = getgihandle;
            saxs.specfile = file;
            %set(hSAXSlee, 'Userdata', saxs);
            setgihandle(saxs);
        end

        % Button pushed function: pbSAXS
        function pbSAXS_Callback(app, event)
            % --- Executes on button press in pbSAXS.
            
            saxsaverage2
        end

        % Button pushed function: pbSaveBinary
        function pbSaveBinary_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %if ~exist('file')
        	[filen, datadir]=uigetfile({'*.*','All format(*.*)';'*.spe','CCD data(*.spe)';'*.dat','GAS data(*.dat)'});
        	if len(filen)>0 
            
                file = [datadir, filen];
            %end
                if app.rd_GoldD.Value == 1
                    disp('Program was not available now, request to the author')
                elseif app.rd_MarCCD.Value == 1
                    disp('Program was not available now, request to the author')
                elseif app.rd_PrinceCCD.Value == 1
                    disp('Program was not available now, request to the author')
                elseif app.rd_Gbinary.Value == 1
                    GB_dataFormat = app.ed_GB_byte.Value;
                    %GB_dimX = str2double(app.ed_GB_dimX.Value);
                    %GB_dimY = str2double(app.ed_GB_dimY.Value);
                    GB_Startbyte = str2double(app.ed_GB_sb.Value);
                    
                    fid = fopen(file, 'r+', 'n');
                    fseek(fid, GB_Startbyte, -1);
                    fwrite(fid, get(findobj(gcf, 'type', 'image'), 'Cdata'), GB_dataFormat);
                    fclose(fid);
                else
                    disp('Please select one of file format')
                    return
                end
            end
        end

        % Button pushed function: pb_2dindexing
        function pb_2dindexing_Callback(app, event)
            % --- Executes on button press in pb_2dindexing.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pb_2dindexing (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            indexing2dpowder
        end

        % Button pushed function: pb_GI2nonGI
        function pb_GI2nonGI_Callback(app, event)
            % --- Executes on button press in pb_GI2nonGI.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pb_GI2nonGI (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            GISAXSLee_convertGI2nonGI
        end

        % Button pushed function: pb_OpenDrawingTool
        function pb_OpenDrawingTool_Callback(app, event)
            % --- Executes on button press in pb_OpenDrawingTool.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pb_OpenDrawingTool (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            GISAXSLee_drawingtool
        end

        % Button pushed function: pb_SDD
        function pb_SDD_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            center(1) = str2double(char(cellstr(app.ed_Cx.Value)));
            center(2) = str2double(char(cellstr(app.ed_Cy.Value)));
            px = str2double(char(cellstr(app.ed_STDp.Value)));
            Q = str2double(char(cellstr(app.ed_STDq.Value)));
            xeng = str2double(char(cellstr(app.ed_Xenergy.Value)));
            psize = str2double(char(cellstr(app.ed_pSize.Value)));
            %offset = str2double(char(cellstr(app.edit23.Value)));
            waveln = eng2wl(xeng);
            SDD = SDDcal(px, Q, psize, waveln);
            app.ed_SDD.Value =  num2str(SDD);
            %saxs = app.gisaxsleenew,'userdata');
            saxs = getgihandle;
            saxs.waveln = waveln;
            %saxs.offset', offset);
            saxs.center = center;
            saxs.SDD = SDD;
            saxs.xeng = xeng;
            saxs.psize = psize;
            saxs.pxQ = Q/px;
            %app.gisaxsleenew,'userdata', saxs);
            setgihandle(saxs);
        end

        % Button pushed function: pb_agbhfindcent
        function pb_agbhfindcent_Callback(app, event)
            % --- Executes on button press in pb_agbhfindcent.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pb_agbhfindcent (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            agbh_findcenter
        end

        % Button pushed function: pb_imganalysis
        function pb_imganalysis_Callback(app, event)
            % --- Executes on button press in pb_imganalysis.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pb_imganalysis (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            imganalysis_App
        end

        % Button pushed function: pb_qCalibration2
        function pb_qCalibration2_Callback(app, event)
            % --- Executes on button press in pb_qCalibration2.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pb_qCalibration2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            qCalibration2
        end

        % Button pushed function: pbloadEPICS
        function pbloadEPICS_Callback(app, event)
            % --- Executes on button press in pbloadEPICS.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbloadEPICS (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [~, val] = system('hostname');
            if strcmp(strtrim(val), 'purple.xor.aps.anl.gov')
               psize = str2double(strtrim(epics_get('12idb:saxs:det:pixelSize')));
               SDD = str2double(strtrim(epics_get('12idb:saxs:det:SDD')));
               offset = str2double(strtrim(epics_get('12idb:saxs:det:offset')));
               beamX = str2double(strtrim(epics_get('12idb:saxs:beamX')));
               beamY = str2double(strtrim(epics_get('12idb:saxs:beamY')));
               xeng = str2double(strtrim(epics_get('12ida1:DCM:EnRBV')));
               edensity = 0;
               saxs.center(1) = beamX;
               saxs.center(2) = beamY;
               saxs.psize = psize;
               saxs.SDD = SDD;
               saxs.offset = offset;
               saxs.edensity = edensity;
               saxs.xeng = xeng;
            %   waveln = eng2wl(xeng);
            %   app.ed_Cx, num2str(beamX));
            %   app.ed_Cy, num2str(beamY));
            %   app.ed_Xenergy, num2str(xeng));
            %   app.ed_pSize, num2str(psize));
            %   app.ed_SDD, num2str(SDD));
            %   app.ed_Offset, num2str(offset));
            %   app.ed_Edensity, num2str(edensity));
            else
               saxs = getgihandle;
            end
            
            setgihandle(saxs)
            SetSAXSValues(app)
        end

        % Button pushed function: pbt_PutCenter
        function pbt_PutCenter_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            ReadandSetValues(app, handles)
            
            [filename, pathname] = uiputfile( ...
                   {'*.mat;*.fig;*.mdl', 'All Setup Files (*.mat)'}, ...
                    'Save as');
            saxs = getgihandle;
            if isfield(saxs, 'imgfigurehandle')
                saxs = rmfield(saxs, 'imgfigurehandle');
            end
            if isfield(saxs, 'mask')
                saxs = rmfield(saxs, 'mask');
            end
            if isfield(saxs, 'image')
                saxs = rmfield(saxs, 'image');
            end
            save([pathname, filename], 'saxs');
            
            if isunix
                fid = fopen('~/.SAXSsetup', 'w');
                fprintf(fid, '# Pixel Size: %0.3f', saxs.psize);
                fprintf(fid, '# Center X: %0.3f', saxs.center(1));
                fprintf(fid, '# Center Y: %0.3f', saxs.center(2));
                fprintf(fid, '# SDD: %0.3f', saxs.SDD);
                fprintf(fid, '# pitch: %0.3f', saxs.pitch);
                fprintf(fid, '# roll: %0.3f', saxs.roll);
                fprintf(fid, '# yaw: %0.3f', saxs.yaw);
                fclose(fid);
            end
        end

        % Button pushed function: pbt_loadsetup
        function pbt_loadsetup_Callback(app, event)
            % --- Executes on button press in pbt_loadsetup.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbt_loadsetup (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            si = getgihandle;
            [filename, pathname] = uigetfile( ...
                   {'*.m;*.fig;*.mat;*.mdl', 'All Setup Files (*.mat)'}, ...
                    'Open as');
            load([pathname, filename], 'saxs');
            
            if isfield(saxs, 'center')
                app.ed_Cx.Value =  num2str(saxs.center(1));
                app.ed_Cy.Value =  num2str(saxs.center(2));
            elseif isfield(saxs, 'BeamXY')
                app.ed_Cx.Value =  num2str(saxs.BeamXY(1));
                app.ed_Cy.Value =  num2str(saxs.BeamXY(2));
                saxs.center = saxs.BeamXY;
                saxs = rmfield(saxs, 'BeamXY');
            end
            
            if isfield(saxs, 'xeng')
                app.ed_Xenergy.Value =  num2str(saxs.xeng);
            elseif isfield(saxs, 'eng')
                app.ed_Xenergy.Value =  num2str(saxs.eng);
                saxs.xeng = saxs.eng;
                saxs = rmfield(saxs, 'eng');
            end
            
            if isfield(saxs, 'psize')
                app.ed_pSize.Value =  num2str(saxs.psize);
            elseif isfield(saxs, 'pSize')
                app.ed_pSize.Value =  num2str(saxs.pSize);
                saxs.psize = saxs.pSize;
                saxs = rmfield(saxs, 'pSize');
            end
            if isfield(saxs, 'SDD')
                app.ed_SDD.Value =  num2str(saxs.SDD);
            else
                app.ed_SDD.Value =  '2000';
                saxs.SDD = 2000;
            end
            
            if isfield(saxs, 'ai')
                app.ed_ai.Value =  num2str(saxs.ai);
            else
                app.ed_ai.Value =  '0';
                saxs.ai = 0;
            end
            
            if isfield(saxs, 'px')
                app.ed_STDp.Value =  num2str(saxs.px);
                app.ed_STDq.Value =  num2str(saxs.Q);
            else
                app.ed_STDq.Value =  '0.10765';
            %    app.ed_STDp.Value =  num2str(saxs.px));
                saxs.px = 0.10765;
            end
            if isfield(saxs, 'offset')
                app.ed_Offset.Value =  num2str(saxs.offset);
            else
                app.ed_Offset.Value =  '0';
                saxs.offset = 0;
            end
            if isfield(saxs, 'edensity')
                app.ed_Edensity.Value =  num2str(saxs.edensity);
            else
                app.ed_Edensity.Value =  '0';
                saxs.edensity = 0;
            end
            if isfield(saxs, 'beta')
                app.ed_Beta.Value =  num2str(saxs.beta);
            else
                app.ed_Beta.Value =  '0';
                saxs.beta = 0;
            end
            saxs.dir = pathname;
            saxs.specfile = '';
            if isfield(saxs, 'qMap')
                saxs = rmfield(saxs, 'qMap');
            end
            if isfield(saxs, 'qCMap')
                saxs = rmfield(saxs, 'qCMap');
            end
            if isfield(saxs, 'qRMap')
                saxs = rmfield(saxs, 'qRMap');
            end
            if isfield(saxs, 'mask')
                saxs = rmfield(saxs, 'mask');
            end
            if isfield(saxs, 'qArray')
                saxs = rmfield(saxs, 'qArray');
            end
            
            try
                tilt = sprintf('%0.2f', saxs.tiltangle(1));
                if numel(saxs.tiltangle) > 1
                    for i=2:numel(saxs.tiltangle)
                        tilt = sprintf('%s %0.2f', tilt, saxs.tiltangle(i));
                    end
                    tilt = ['[', tilt, ']'];
                end
                app.ed_tiltangle.Value =  tilt;
            catch
                saxs.tiltangle = 0;
            end
            if isfield(saxs, 'imgoperation')
                try
                    app.rd_UpDown.Value = saxs.imgoperation.UpDown;
                catch
                    app.rd_UpDown.Value = 0;
                end
                try
                    app.rd_LeftRight.Value = saxs.imgoperation.LeftRight;
                catch
                    app.rd_LeftRight.Value = 0;
                end
                try
                    app.rd_Transpose.Value = saxs.imgoperation.transpose;
                catch
                    app.rd_Transpose.Value = 0;
                end
            end
            
            if isfield(si, 'image')
                saxs.image = si.image;
                saxs.imgname = si.imgname;
                saxs.imagename = si.imagename;
                saxs.imgsize = si.imgsize;
            end
            setgihandle(saxs);
            ed_SDD_Callback(app, event)
        end

        % Selection changed function: uipanel_AxisType
        function uipanel_AxisType_SelectionChangeFcn(app, event)
            % --- Executes when selected object is changed in uipanel_AxisType.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to the selected object in uipanel_AxisType
            % eventdata  structure with the following fields (see UIBUTTONGROUP)
            %	EventName: string 'SelectionChanged' (read only)
            %	OldValue: handle of the previously selected object or empty if none was selected
            %	NewValue: handle of the currently selected object
            % handles    structure with handles and user data (see GUIDATA)
            saxs = getgihandle;
            saxs.axistype = event.NewValue.Text;
            setgihandle(saxs);
        end

        % Value changed function: rd_UpDown
        function rd_UpDownValueChanged(app, event)
            %value = app.rd_UpDown.Value;
            imgoperation(app)
        end

        % Value changed function: rd_LeftRight
        function rd_LeftRightValueChanged(app, event)
            %value = app.rd_LeftRight.Value;
            imgoperation(app)
        end

        % Value changed function: rd_Transpose
        function rd_TransposeValueChanged(app, event)
            %value = app.rd_Transpose.Value;
            imgoperation(app)
        end

        % Button pushed function: pb_loadmask
        function pb_loadmaskButtonPushed(app, event)
            % --------------------------------------------------------------------
            
            prePath = pwd;
            % --- open file
            [filename, filepath] = uigetfile( ...
                {'*.bmp;*.BMP','Mask Image (*.bmp, *.BMP)';'*.tiff;*.tif;*.TIF;*.TIFF','Mask Image (*.tif, *.tiff, *.TIF, *.TIFF)';'*.*','All Files (*.*)'}, ...
                'Select Mask Image');
            % If "Cancel" is selected then return
            if isequal([filename,filepath],[0,0])
                restorePath(prePath);
                return
            end
            saxs = getgihandle;
            fullfilename = fullfile(filepath, filename);
            saxs.mask = double(imread(fullfilename));
            saxs.mask(saxs.mask < 1) = NaN;
            setgihandle(saxs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.gisaxsleenew = uifigure('Visible', 'off');
            colormap(app.gisaxsleenew, 'parula');
            app.gisaxsleenew.Name = 'Experiment Setup';
            app.gisaxsleenew.WindowButtonDownFcn = createCallbackFcn(app, @gisaxsleenew_WindowButtonDownFcn, true);
            app.gisaxsleenew.Tag = 'gisaxsleenew';
            app.pb_imganalysis = uibutton(app.gisaxsleenew, 'push');
            app.pb_imganalysis.ButtonPushedFcn = createCallbackFcn(app, @pb_imganalysis_Callback, true);
            app.pb_imganalysis.Tag = 'pb_imganalysis';
            app.pb_imganalysis.Text = 'Line cuts';
            app.pbSaveBinary = uibutton(app.gisaxsleenew, 'push');
            app.pbSaveBinary.ButtonPushedFcn = createCallbackFcn(app, @pbSaveBinary_Callback, true);
            app.pbSaveBinary.Tag = 'pbSaveBinary';
            app.pbSaveBinary.Enable = 'off';
            app.pbSaveBinary.Visible = 'off';
            app.pbSaveBinary.Text = 'Save 2d data';
            app.pbSAXS = uibutton(app.gisaxsleenew, 'push');
            app.pbSAXS.ButtonPushedFcn = createCallbackFcn(app, @pbSAXS_Callback, true);
            app.pbSAXS.Tag = 'pbSAXS';
            app.pbSAXS.Text = 'SAXS tool';
            app.uipanel3 = uipanel(app.gisaxsleenew);
            app.uipanel3.Title = 'Experimental setup';
            app.uipanel3.Tag = 'uipanel3';
            app.text32 = uilabel(app.uipanel3);
            app.text32.Tag = 'text32';
            app.text32.Text = 'Det. angle[p,y,r] | r';
            app.text2 = uilabel(app.uipanel3);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Center';
            app.pbt_PutCenter = uibutton(app.uipanel3, 'push');
            app.pbt_PutCenter.ButtonPushedFcn = createCallbackFcn(app, @pbt_PutCenter_Callback, true);
            app.pbt_PutCenter.Tag = 'pbt_PutCenter';
            app.pbt_PutCenter.Text = 'Accept & Save setup info.';
            app.laelai = uilabel(app.uipanel3);
            app.laelai.Tag = 'laelai';
            app.laelai.Text = 'ai';
            app.ed_ai = uieditfield(app.uipanel3, 'text');
            app.ed_ai.ValueChangedFcn = createCallbackFcn(app, @ed_ai_Callback, true);
            app.ed_ai.Tag = 'ed_ai';
            app.ed_ai.Value = '0';
            app.text9 = uilabel(app.uipanel3);
            app.text9.Tag = 'text9';
            app.text9.Text = 'SDD(mm)';
            app.ed_SDD = uieditfield(app.uipanel3, 'text');
            app.ed_SDD.ValueChangedFcn = createCallbackFcn(app, @ed_SDD_Callback, true);
            app.ed_SDD.Tag = 'ed_SDD';
            app.ed_SDD.Value = '2550';
            app.ed_pSize = uieditfield(app.uipanel3, 'text');
            app.ed_pSize.ValueChangedFcn = createCallbackFcn(app, @ed_pSize_Callback, true);
            app.ed_pSize.Tag = 'ed_pSize';
            app.ed_pSize.Value = '0.075';
            app.textpixelsize = uilabel(app.uipanel3);
            app.textpixelsize.Tag = 'textpixelsize';
            app.textpixelsize.Text = 'pixel size(mm)';
            app.ed_Xenergy = uieditfield(app.uipanel3, 'text');
            app.ed_Xenergy.ValueChangedFcn = createCallbackFcn(app, @ed_Xenergy_Callback, true);
            app.ed_Xenergy.Tag = 'ed_Xenergy';
            app.ed_Xenergy.Value = '13.3';
            app.text19 = uilabel(app.uipanel3);
            app.text19.Tag = 'text19';
            app.text19.Text = 'X-ray Energy(keV)';
            app.text20 = uilabel(app.uipanel3);
            app.text20.Tag = 'text20';
            app.text20.Text = 'Peak(Standard)';
            app.ed_STDq = uieditfield(app.uipanel3, 'text');
            app.ed_STDq.ValueChangedFcn = createCallbackFcn(app, @ed_STDq_Callback, true);
            app.ed_STDq.Tag = 'ed_STDq';
            app.ed_STDq.Value = '0.10763';
            app.text21 = uilabel(app.uipanel3);
            app.text21.Tag = 'text21';
            app.text21.Text = 'q value(Standard)';
            app.ed_Cx = uieditfield(app.uipanel3, 'text');
            app.ed_Cx.ValueChangedFcn = createCallbackFcn(app, @ed_Cx_Callback, true);
            app.ed_Cx.Tag = 'ed_Cx';
            app.ed_Cx.Value = '1440';
            app.ed_Cy = uieditfield(app.uipanel3, 'text');
            app.ed_Cy.ValueChangedFcn = createCallbackFcn(app, @ed_Cy_Callback, true);
            app.ed_Cy.Tag = 'ed_Cy';
            app.ed_Cy.Value = '206';
            app.pb_SDD = uibutton(app.uipanel3, 'push');
            app.pb_SDD.ButtonPushedFcn = createCallbackFcn(app, @pb_SDD_Callback, true);
            app.pb_SDD.Tag = 'pb_SDD';
            app.pb_SDD.Text = 'SDD';
            app.pbt_loadsetup = uibutton(app.uipanel3, 'push');
            app.pbt_loadsetup.ButtonPushedFcn = createCallbackFcn(app, @pbt_loadsetup_Callback, true);
            app.pbt_loadsetup.Tag = 'pbt_loadsetup';
            app.pbt_loadsetup.Text = 'Load setup info.';
            app.textOffset = uilabel(app.uipanel3);
            app.textOffset.Tag = 'textOffset';
            app.textOffset.Text = 'offset value';
            app.ed_Offset = uieditfield(app.uipanel3, 'text');
            app.ed_Offset.ValueChangedFcn = createCallbackFcn(app, @ed_Offset_Callback, true);
            app.ed_Offset.Tag = 'ed_Offset';
            app.ed_Offset.Value = '0';
            app.textEdensity = uilabel(app.uipanel3);
            app.textEdensity.Tag = 'textEdensity';
            app.textEdensity.Text = 'e-density';
            app.ed_Edensity = uieditfield(app.uipanel3, 'text');
            app.ed_Edensity.ValueChangedFcn = createCallbackFcn(app, @ed_Edensity_Callback, true);
            app.ed_Edensity.Tag = 'ed_Edensity';
            app.ed_Edensity.Value = '0';
            app.textBeta = uilabel(app.uipanel3);
            app.textBeta.Tag = 'textBeta';
            app.textBeta.Text = 'beta';
            app.ed_Beta = uieditfield(app.uipanel3, 'text');
            app.ed_Beta.ValueChangedFcn = createCallbackFcn(app, @ed_Beta_Callback, true);
            app.ed_Beta.Tag = 'ed_Beta';
            app.ed_Beta.Value = '1E-9';
            app.pbloadEPICS = uibutton(app.uipanel3, 'push');
            app.pbloadEPICS.ButtonPushedFcn = createCallbackFcn(app, @pbloadEPICS_Callback, true);
            app.pbloadEPICS.Tag = 'pbloadEPICS';
            app.pbloadEPICS.Text = 'Load setup EPICS';
            app.ed_STDp = uieditfield(app.uipanel3, 'text');
            app.ed_STDp.ValueChangedFcn = createCallbackFcn(app, @ed_STDp_Callback, true);
            app.ed_STDp.Tag = 'ed_STDp';
            app.ed_STDp.Value = '320';
            app.ed_tiltangle = uieditfield(app.uipanel3, 'text');
            app.ed_tiltangle.ValueChangedFcn = createCallbackFcn(app, @ed_tiltangle_Callback, true);
            app.ed_tiltangle.Tag = 'ed_tiltangle';
            app.ed_tiltangle.Value = '0';
            app.CB_SetupAutoUpdate = uicheckbox(app.uipanel3);
            app.CB_SetupAutoUpdate.ValueChangedFcn = createCallbackFcn(app, @CB_SetupAutoUpdate_Callback, true);
            app.CB_SetupAutoUpdate.Tag = 'CB_SetupAutoUpdate';
            app.CB_SetupAutoUpdate.Text = 'Auto Update if available';
            app.text37 = uilabel(app.uipanel3);
            app.text37.Tag = 'text37';
            app.text37.Text = 'tthi';
            app.ed_tthi = uieditfield(app.uipanel3, 'text');
            app.ed_tthi.ValueChangedFcn = createCallbackFcn(app, @ed_tthi_Callback, true);
            app.ed_tthi.Tag = 'ed_tthi';
            app.ed_tthi.Value = '0';
            app.pbLoadSpecFile = uibutton(app.uipanel3, 'push');
            app.pbLoadSpecFile.ButtonPushedFcn = createCallbackFcn(app, @pbLoadSpecFile_Callback, true);
            app.pbLoadSpecFile.Tag = 'pbLoadSpecFile';
            app.pbLoadSpecFile.Text = 'Load 12ID-B specfile';
            app.pb_loadmask = uibutton(app.uipanel3, 'push');
            app.pb_loadmask.ButtonPushedFcn = createCallbackFcn(app, @pb_loadmaskButtonPushed, true);
            app.pb_loadmask.Tag = 'pbt_loadsetup';
            app.pb_loadmask.Text = 'Load Mask';
            app.uipanel5 = uipanel(app.gisaxsleenew);
            app.uipanel5.Title = 'Image Orientation';
            app.uipanel5.Tag = 'uipanel5';
            app.rd_UpDown = uicheckbox(app.uipanel5);
            app.rd_UpDown.ValueChangedFcn = createCallbackFcn(app, @rd_UpDownValueChanged, true);
            app.rd_UpDown.Text = 'UpDown';
            app.rd_LeftRight = uicheckbox(app.uipanel5);
            app.rd_LeftRight.ValueChangedFcn = createCallbackFcn(app, @rd_LeftRightValueChanged, true);
            app.rd_LeftRight.Text = 'LeftRight';
            app.rd_Transpose = uicheckbox(app.uipanel5);
            app.rd_Transpose.ValueChangedFcn = createCallbackFcn(app, @rd_TransposeValueChanged, true);
            app.rd_Transpose.Text = 'Transpose';
            app.uipanel8 = uibuttongroup(app.gisaxsleenew);
            app.uipanel8.Title = 'Background subtraction';
            app.uipanel8.Tag = 'uipanel8';
            app.ed_BackScaleFactor = uieditfield(app.uipanel8, 'text');
            app.ed_BackScaleFactor.ValueChangedFcn = createCallbackFcn(app, @ed_BackScaleFactor_Callback, true);
            app.ed_BackScaleFactor.Tag = 'ed_BackScaleFactor';
            app.ed_BackScaleFactor.Value = '1';
            app.text35 = uilabel(app.uipanel8);
            app.text35.Tag = 'text35';
            app.text35.Text = 'BSF, if not using Transmittance';
            app.CB_SubtractBackground = uicheckbox(app.uipanel8);
            app.CB_SubtractBackground.ValueChangedFcn = createCallbackFcn(app, @CB_SubtractBackground_Callback, true);
            app.CB_SubtractBackground.Tag = 'CB_SubtractBackground';
            app.CB_SubtractBackground.Tooltip = 'Check if you would like to subtract background';
            app.CB_SubtractBackground.Text = 'Subtract Background';
            app.uipanel9 = uipanel(app.gisaxsleenew);
            app.uipanel9.Title = 'Normalization : To apply changes, Reload Data';
            app.uipanel9.Tag = 'uipanel9';
            app.ed_ScaleFactor = uieditfield(app.uipanel9, 'text');
            app.ed_ScaleFactor.ValueChangedFcn = createCallbackFcn(app, @ed_ScaleFactor_Callback, true);
            app.ed_ScaleFactor.Tag = 'ed_ScaleFactor';
            app.ed_ScaleFactor.Tooltip = 'Absolute intensity scaling factor.';
            app.ed_ScaleFactor.Value = '1';
            app.ed_I0Ref = uieditfield(app.uipanel9, 'text');
            app.ed_I0Ref.ValueChangedFcn = createCallbackFcn(app, @ed_I0Ref_Callback, true);
            app.ed_I0Ref.Tag = 'ed_I0Ref';
            app.ed_I0Ref.Tooltip = 'I(0) value when the standard was measured.';
            app.ed_I0Ref.Value = '1';
            app.text33 = uilabel(app.uipanel9);
            app.text33.Tag = 'text33';
            app.text33.Text = 'Scaling Factor';
            app.text34 = uilabel(app.uipanel9);
            app.text34.Tag = 'text34';
            app.text34.Text = 'I(0) Ref';
            app.CB_ApplyScale = uicheckbox(app.uipanel9);
            app.CB_ApplyScale.ValueChangedFcn = createCallbackFcn(app, @CB_ApplyScale_Callback, true);
            app.CB_ApplyScale.Tag = 'CB_ApplyScale';
            app.CB_ApplyScale.Tooltip = 'Check if you would like to apply scale factor';
            app.CB_ApplyScale.Text = 'Apply the scale factor.';
            app.CB_ScaleI0 = uicheckbox(app.uipanel9);
            app.CB_ScaleI0.ValueChangedFcn = createCallbackFcn(app, @CB_ScaleI0_Callback, true);
            app.CB_ScaleI0.Tag = 'CB_ScaleI0';
            app.CB_ScaleI0.Tooltip = 'Otherwise, I0b, I0 and I0Ref are all set to 1';
            app.CB_ScaleI0.Text = 'Scale I(0)';
            app.ed_ScaleField = uidropdown(app.uipanel9);
            app.ed_ScaleField.Items = {};
            app.ed_ScaleField.ValueChangedFcn = createCallbackFcn(app, @ed_ScaleField_Callback, true);
            app.ed_ScaleField.Tag = 'ed_ScaleField';
            app.ed_ScaleField.Tooltip = 'Fieldnames for I(0) Scaling';
            app.ed_ScaleField.Value = {};
            app.text36 = uilabel(app.uipanel9);
            app.text36.Tag = 'text36';
            app.text36.Text = 'I=(Img/t/T-Img_b/tb/Tb/I0b*I0)*I0Ref/I0*SF/d';
            app.CB_exptimenormalize = uicheckbox(app.uipanel9);
            app.CB_exptimenormalize.ValueChangedFcn = createCallbackFcn(app, @CB_exptimenormalize_Callback, true);
            app.CB_exptimenormalize.Tag = 'CB_exptimenormalize';
            app.CB_exptimenormalize.Tooltip = 'Exposure time normalize';
            app.CB_exptimenormalize.Text = 'Normalize exposure time(t), or t=1';
            app.cb_transmittance = uicheckbox(app.uipanel9);
            app.cb_transmittance.ValueChangedFcn = createCallbackFcn(app, @cb_transmittance_Callback, true);
            app.cb_transmittance.Tag = 'cb_transmittance';
            app.cb_transmittance.Tooltip = 'You need to define an empty air data before using this option.';
            app.cb_transmittance.Text = 'Calculate Transmittance, or T=1';
            app.text38 = uilabel(app.uipanel9);
            app.text38.Tag = 'text38';
            app.text38.Text = 'I=(Img/t-BSF*img_b/tb/I0b*I0)*I0Ref/I0*SF/d';
            app.cb_samplethickness = uicheckbox(app.uipanel9);
            app.cb_samplethickness.ValueChangedFcn = createCallbackFcn(app, @cb_samplethickness_Callback, true);
            app.cb_samplethickness.Tag = 'cb_samplethickness';
            app.cb_samplethickness.Text = 'Apply the sample thickness';
            app.ed_samplethickness = uieditfield(app.uipanel9, 'text');
            app.ed_samplethickness.ValueChangedFcn = createCallbackFcn(app, @ed_samplethickness_Callback, true);
            app.ed_samplethickness.Tag = 'ed_samplethickness';
            app.ed_samplethickness.Value = '1';
            app.text39 = uilabel(app.uipanel9);
            app.text39.Tag = 'text39';
            app.text39.Text = 'mm';
            app.cb_airpathcorrection = uicheckbox(app.uipanel9);
            app.cb_airpathcorrection.ValueChangedFcn = createCallbackFcn(app, @cb_airpathcorrection_Callback, true);
            app.cb_airpathcorrection.Tag = 'cb_airpathcorrection';
            app.cb_airpathcorrection.Tooltip = 'Check if you would like to correct angle-dependent absorption by air path from sample to detector. ';
            app.cb_airpathcorrection.Text = 'Air path correction';
            app.cb_detSensorPathCorrection = uicheckbox(app.uipanel9);
            app.cb_detSensorPathCorrection.ValueChangedFcn = createCallbackFcn(app, @cb_detSensorPathCorrection_Callback, true);
            app.cb_detSensorPathCorrection.Tag = 'cb_detSensorPathCorrection';
            app.cb_detSensorPathCorrection.Tooltip = 'Check if you would like to correct for angle dependent path of X-ray in the detector sensor.';
            app.cb_detSensorPathCorrection.Text = 'Detector sensor path correction (PE only)';
            app.cb_polarizationCorrection = uicheckbox(app.uipanel9);
            app.cb_polarizationCorrection.ValueChangedFcn = createCallbackFcn(app, @cb_polarizationCorrection_Callback, true);
            app.cb_polarizationCorrection.Tag = 'cb_polarizationCorrection';
            app.cb_polarizationCorrection.Tooltip = 'Check if you would like to apply polarization correction for synchrotron.';
            app.cb_polarizationCorrection.Text = 'Polarization Correction';
            app.uipanel_AxisType = uibuttongroup(app.gisaxsleenew);
            app.uipanel_AxisType.SelectionChangedFcn = createCallbackFcn(app, @uipanel_AxisType_SelectionChangeFcn, true);
            app.uipanel_AxisType.Title = 'Axis Type';
            app.uipanel_AxisType.Tag = 'uipanel_AxisType';
            app.rd_AxisStyle_q = uiradiobutton(app.uipanel_AxisType);
            app.rd_AxisStyle_q.Tag = 'rd_AxisStyle_q';
            app.rd_AxisStyle_q.Text = 'q';
            app.rd_AxisStyle_q.Value = true;
            app.rd_AxisStyle_qr = uiradiobutton(app.uipanel_AxisType);
            app.rd_AxisStyle_qr.Tag = 'rd_AxisStyle_qr';
            app.rd_AxisStyle_qr.Text = 'qrz';
            app.rd_AxisStyle_af = uiradiobutton(app.uipanel_AxisType);
            app.rd_AxisStyle_af.Tag = 'rd_AxisStyle_af';
            app.rd_AxisStyle_af.Text = 'af';
            app.rd_AxisStyle_dX = uiradiobutton(app.uipanel_AxisType);
            app.rd_AxisStyle_dX.Tag = 'rd_AxisStyle_dX';
            app.rd_AxisStyle_dX.Text = 'dX';
            app.pb_OpenDrawingTool = uibutton(app.gisaxsleenew, 'push');
            app.pb_OpenDrawingTool.ButtonPushedFcn = createCallbackFcn(app, @pb_OpenDrawingTool_Callback, true);
            app.pb_OpenDrawingTool.Tag = 'pb_OpenDrawingTool';
            app.pb_OpenDrawingTool.Text = 'Drawing Tool';
            app.pb_GI2nonGI = uibutton(app.gisaxsleenew, 'push');
            app.pb_GI2nonGI.ButtonPushedFcn = createCallbackFcn(app, @pb_GI2nonGI_Callback, true);
            app.pb_GI2nonGI.Tag = 'pb_GI2nonGI';
            app.pb_GI2nonGI.Tooltip = 'Convert GI image into q space.';
            app.pb_GI2nonGI.Text = 'Img Convsn';
            app.pb_qCalibration2 = uibutton(app.gisaxsleenew, 'push');
            app.pb_qCalibration2.ButtonPushedFcn = createCallbackFcn(app, @pb_qCalibration2_Callback, true);
            app.pb_qCalibration2.Tag = 'pb_qCalibration2';
            app.pb_qCalibration2.Text = 'qCalibration';
            app.pb_agbhfindcent = uibutton(app.gisaxsleenew, 'push');
            app.pb_agbhfindcent.ButtonPushedFcn = createCallbackFcn(app, @pb_agbhfindcent_Callback, true);
            app.pb_agbhfindcent.Tag = 'pb_agbhfindcent';
            app.pb_agbhfindcent.Text = 'Find Center';
            app.pb_2dindexing = uibutton(app.gisaxsleenew, 'push');
            app.pb_2dindexing.ButtonPushedFcn = createCallbackFcn(app, @pb_2dindexing_Callback, true);
            app.pb_2dindexing.Tag = 'pb_2dindexing';
            app.pb_2dindexing.Tooltip = 'Convert GI image into q space.';
            app.pb_2dindexing.Text = '2D peak fitting';
            % Show the figure after all components are created
            app.gisaxsleenew.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gisaxsleenew_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.gisaxsleenew)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.gisaxsleenew)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.gisaxsleenew)
        end
    end
end