classdef particlemaker_PDB_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        particlemaker_pdb          matlab.ui.Figure
        Untitled_1                 matlab.ui.container.Menu
        mnLoadmat                  matlab.ui.container.Menu
        Untitled_3                 matlab.ui.container.Menu
        mnLoadASet                 matlab.ui.container.Menu
        mnSaveAs                   matlab.ui.container.Menu
        mnSaveAllParticles         matlab.ui.container.Menu
        Untitled_5                 matlab.ui.container.Menu
        mnAddParticle              matlab.ui.container.Menu
        mnRemoveParticle           matlab.ui.container.Menu
        Untitled_6                 matlab.ui.container.Menu
        mnDrawParticle             matlab.ui.container.Menu
        mnDrawParticleinUnitCell   matlab.ui.container.Menu
        pm_orientationfactor       matlab.ui.control.DropDown
        pm_color                   matlab.ui.control.DropDown
        pm_rho                     matlab.ui.control.DropDown
        pm_wyckoff                 matlab.ui.control.DropDown
        ed_position_LabCoordinate  matlab.ui.control.EditField
        text21                     matlab.ui.control.Label
        ed_position2               matlab.ui.control.EditField
        text20                     matlab.ui.control.Label
        text19                     matlab.ui.control.Label
        pb_loadPDB                 matlab.ui.control.Button
        ed_PDBfilename             matlab.ui.control.EditField
        ed_degofrandomorientation  matlab.ui.control.EditField
        text18                     matlab.ui.control.Label
        uibg_ParticleOrientation   matlab.ui.container.ButtonGroup
        rb_orientationtype5        matlab.ui.control.RadioButton
        rb_orientationtype4        matlab.ui.control.RadioButton
        pb_Fqcal                   matlab.ui.control.Button
        rb_orientationtype3        matlab.ui.control.RadioButton
        rb_orientationtype2        matlab.ui.control.RadioButton
        rb_orientationtype1        matlab.ui.control.RadioButton
        edpsi                      matlab.ui.control.EditField
        txtpsi                     matlab.ui.control.Label
        edtheta                    matlab.ui.control.EditField
        txttheta                   matlab.ui.control.Label
        edphi                      matlab.ui.control.EditField
        txtphi                     matlab.ui.control.Label
        uibg_isPq                  matlab.ui.container.ButtonGroup
        pushbutton25               matlab.ui.control.Button
        pushbutton24               matlab.ui.control.Button
        pushbutton23               matlab.ui.control.Button
        pushbutton22               matlab.ui.control.Button
        pushbutton21               matlab.ui.control.Button
        pushbutton20               matlab.ui.control.Button
        pushbutton19               matlab.ui.control.Button
        pushbutton18               matlab.ui.control.Button
        rb_Pqoption4               matlab.ui.control.RadioButton
        rb_Pqoption1               matlab.ui.control.RadioButton
        pb_clearPqfields           matlab.ui.control.Button
        rb_Pqoption3               matlab.ui.control.RadioButton
        rb_Pqoption2               matlab.ui.control.RadioButton
        pb_PickPqname              matlab.ui.control.Button
        pbloadaset                 matlab.ui.control.Button
        edcolor                    matlab.ui.control.EditField
        txtcolor                   matlab.ui.control.Label
        text13                     matlab.ui.control.Label
        pmnumber                   matlab.ui.control.DropDown
        pbdrawinlattice            matlab.ui.control.Button
        pbdraw                     matlab.ui.control.Button
        edsizewidth                matlab.ui.control.EditField
        text9                      matlab.ui.control.Label
        edSOF                      matlab.ui.control.EditField
        text8                      matlab.ui.control.Label
        edLshell                   matlab.ui.control.EditField
        txtshell                   matlab.ui.control.Label
        edrho                      matlab.ui.control.EditField
        text6                      matlab.ui.control.Label
        edsize                     matlab.ui.control.EditField
        txtsize                    matlab.ui.control.Label
        edposition                 matlab.ui.control.EditField
        text2                      matlab.ui.control.Label
        text1                      matlab.ui.control.Label
        pmshape                    matlab.ui.control.DropDown
    end

    properties (Access = public)
        model
    end
    methods (Access = public)
        function load_data(app, varargin)
            model = varargin{1};
            app.model = model;
            pmnumber_Callback(app, [])
        end
        function update_wyckoff(app, varargin)
            if numel(varargin)==0
                try
                    sginfo = evalin('base', 'sginfo');
                catch
                    sginfo = [];
                end
                if isempty(sginfo)
                    return
                end
                wy = wyckoff;
                wy = wy{sginfo.Number};
            else
                wy = varargin{1};
            end
            cnt = cell(size(wy, 1), 1);
            for i=1:size(wy, 1)
                cnt{i} = strcat(num2str(wy{i, 2}), wy{i, 1}, ':[', wy{i, 4},']');
            end
            app.pm_wyckoff.Items = cnt;
            app.pm_wyckoff.ValueIndex = 1;
            app.pm_wyckoff.UserData = wy;
        end
    end
    methods (Access = private)
        function check_Pqoption(app, varargin)
            try
                model = app.model;
            catch
                return;
            end
            N = str2double(get(app.pmnumber, 'value'));
            option = 1;
            if isempty(model)
                return
            end
            if isfield(model{N}, 'Pqmono')
                option = 2;
                if isfield(model{N}, 'Pq')
                    model{N} = rmfield(model{N}, 'Pq');
                end
                if isfield(model{N}, 'Fq_o')
                    model{N} = rmfield(model{N}, 'Fq_o');
                end
                if isfield(model{N}, 'Fq_os')
                    model{N} = rmfield(model{N}, 'Fq_os');
                end
            elseif isfield(model{N}, 'pdb')
                option = 4;
            else
                if isfield(model{N}, 'Pq')
                    option = 3;
                end
                if isfield(model{N}, 'Fq_o')
                    option = 3;
                end
                if isfield(model{N}, 'Fq_o')
                    option = 3;
                end
            end
            switch option
                case 1
                    set(app.rb_Pqoption1, 'value', 1);
                case 2
                    set(app.rb_Pqoption2, 'value', 1);
                case 3
                    set(app.rb_Pqoption3, 'value', 1);
                case 4
                    set(app.rb_Pqoption4, 'value', 1);
            end
            app.model = model;
        end
        
        function check_duplicatedparticles(app, figTag, newpo, newparticlecolor)
            figh = findobj('tag', figTag);
            allparticle = findobj(figh, 'type', 'surface');
            newparticle = findobj(figh, 'type', 'surface', 'Facecolor', newparticlecolor);
            otherparticle = setdiff(allparticle, newparticle);
            if isempty(otherparticle)
                return
            end
            m = get(otherparticle, 'userdata');
            
            for count1 = 1:numel(newpo)
                for count2 = 1:numel(newpo{count1}.position)/3
                    po = newpo{count1}.position(count2, :);
                    for i=1:numel(m)
                        if iscell(m)
                            mn = m{i};
                        else
                            mn = m;
                        end
            
                        if isempty(mn)
                            break;
                        end
                        %if (sum((mn.positionincell - po).^2) == 0)
                        if (sum((mn(:, 1:3) - po).^2) == 0)
                            cprintf('Errors', sprintf('Position of particles (%s and %s) overlap at [%i, %i, %i]\n',...
                            mn.particle.color, newparticlecolor, po(1), po(2), po(3)));
                        end
                    end
                end
            end
        end
        
        function defaultparticle(app, handles)
            maxN = numel(app.pmnumber.Value);
            set(app.pmnumber, 'value', maxN);
            set(app.pmshape, 'value', 1);
            set(app.edposition, 'Value', '[0, 0, 0]');
            set(app.edsize, 'Value', '10');
            set(app.edsizewidth, 'Value', '0');
            set(app.edrho, 'Value', '1');
            set(app.edLshell, 'Value', '[]');
            set(app.edSOF, 'Value', '1');
            set(app.edcolor, 'Value', 'r');
            set(app.ed_PDBfilename, 'Value', '');
        end
        
        function ed_Pqmononame_Callback(app, hObject, eventdata, handles)
            % hObject    handle to ed_Pqmononame (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Text') returns contents of ed_Pqmononame as text
            %        str2double(get(hObject,'Text')) returns contents of ed_Pqmononame as a double
            setvalues(app)
        end
        
        function ed_Pqmononame_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to ed_Pqmononame (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function housekeepfigure(app, currentModelN)
            %set(app.ed
            
                figTag = 'particlemaker_figure';
                figh = findobj('Tag', figTag);
                delete(findobj(figh, 'type', 'line'));
            
                ax = findobj(figh, 'type', 'axes');
                %
                %newparticle = findobj(ax, 'tag', strcat('Model', num2str(currentModelN)));
                delete(findobj(ax, 'type', 'light'));
                allparticle = get(ax, 'children');
                newp_indices = [];
                for i = 1:numel(allparticle)
                    if size(allparticle(i).UserData,2)==4
                        if allparticle(i).UserData(4) == currentModelN
                            newp_indices = [newp_indices, i];
                        end
                    end
                end
                newparticle = allparticle(newp_indices);

                if ~isempty(allparticle)
                    allparticle(~isprop(allparticle, 'facealpha')) = [];
                end
            
                    if ~isempty(allparticle)
                        otherparticle = setdiff(allparticle, newparticle);
                        set(otherparticle, 'Facealpha', 0.2);
                        delete(newparticle);
                    end
                % if isscalar(app.pmnumber.Value)
                %     if ~isempty(figh)
                %         figure(figh)
                %         clf
                %     else
                %         figh = figure;
                %         set(figh, 'tag', figTag);
                %     end
                % else
                %     %allparticle = findobj(figh, 'type', 'surface');
                %     %colr = get(allparticle{1}, 'Facecolor');
                %     %newparticle = findobj(figh, 'type', 'surface', 'Facecolor', colr);
                %     if ~isempty(allparticle)
                %         otherparticle = setdiff(allparticle, newparticle);
                %         set(otherparticle, 'Facealpha', 0.2);
                %         delete(newparticle);
                %     end
                % end
        end
        
        function pb_calPq_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in pb_calPq.
            
            % hObject    handle to pb_calPq (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            model = app.model;
            N = str2double(get(app.pmnumber, 'value'));
            %[Sq, r,pr] = debyepr(q, x, y, z, 500, atm, Fq)
            q = evalin('base', 'q');
            cprintf('*blue', 'Computation started...Wait.\n');
            %Pq = pq_pdb(q, pdb.atom);
            p{1} = model{N};
            
            [Pqall, ~, Fqo, ~, Fq_os] = Pq_general(p, q);
            model{N}.Pq = [q(:), Pqall];
            model{N}.Fq_o = [q(:), Fqo];
            model{N}.Fq_os = [q(:), Fq_os];
            
            %[~, Pq] = AnisoFormFactor(model{N}, q, [], 3);
            cprintf('*blue', 'Computation done.\n');
            %model{N}.Pq = [q(:), Pq(:)];
            figure;
            loglog(q, Pqall);
            k = [q(:), Pqall];
            app.model = model;;
            % save
            % This code checks if the user pressed cancel on the dialog.
            [filename, pathname] = uiputfile( ...
               {'*.dat','Dat file (*.dat)'; ...
                '*.*',  'All Files (*.*)'}, ...
                'Save data');
            
            % This code checks if the user pressed cancel on the dialog.
            
            if isequal(filename,0) || isequal(pathname,0)
               disp('User pressed cancel')
               return
            end
            FN = fullfile(pathname, filename);
            save(FN, 'k', '-ascii');
        end
        
        function pb_loadPq_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in pb_loadPq.
            
            % hObject    handle to pb_loadPq (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            model = app.model;
            N = str2double(get(app.pmnumber, 'value'));
            
                [filename, pathname] = uigetfile( ...
                   {'*.mat','mat file (*.dat)'; ...
                    '*.*',  'All Files (*.*)'}, ...
                    'Pick a file', ...
                    'MultiSelect', 'off');
            
                % This code checks if the user pressed cancel on the dialog.
            
                if isequal(filename,0) || isequal(pathname,0)
                   disp('User pressed cancel')
                   return
                else
                   disp(['User selected ', fullfile(pathname, filename)])
                end
            
            p = load(fullfile(pathname, filename));
            
            model{N}.Pq = p;
            
            app.model = model;
        end
        
        function pbadd_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in pbadd.
            
            % hObject    handle to pbadd (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %model = app.model;
            model = app.model;
            if isempty(model)
                return
            end
            %t = numel(model.particle);
            %t = numel(app.pmnumber.Items);
            n = numel(model);
            model{n+1} = model{n};
            %num = numel(md.edensity);
            num = numlist2cellstr((1:n+1));
            %set(gcbf, 'userdata', model);
            app.model = model;
            app.pmnumber.Items = num;
        end
        
        function pbload_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in pbload.
            
            % hObject    handle to pbload (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                [filename, pathname] = uigetfile( ...
                   {'*.mat','mat file (*.dat)'; ...
                    '*.*',  'All Files (*.*)'}, ...
                    'Pick a file', ...
                    'MultiSelect', 'off');
            
                % This code checks if the user pressed cancel on the dialog.
            
                if isequal(filename,0) || isequal(pathname,0)
                   disp('User pressed cancel')
                   return
                else
                   disp(['User selected ', fullfile(pathname, filename)])
                end
            
                p = load(fullfile(pathname, filename));
            %model = app.model;
            try
                model = app.model;
            catch
                model = [];
            end
            
            N = str2double(get(app.pmnumber, 'value'));
            model{N} = p.model;
            %set(gcbf, 'userdata', model);
            app.model = model;
            
            pmnumber_Callback(app)
        end
        
        function pbremove_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in pbremove.
            
            % hObject    handle to pbremove (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            t = numel(app.pmnumber.Value);
            if t<2
                return;
            end
            sellayer = str2double(get(app.pmnumber, 'value'));
            %model = app.model;
            model = app.model;
            %model = gf_model(model, 'remove', 'particle', sellayer);
            model(sellayer) = [];
            %particle_update(model, sellayer, handles);
            %t = numel(model.particle);
            %num = numel(md.edensity);
            num = numlist2cellstr((1:t-1));
            set(app.pmnumber, 'value', numel(num))
            set(app.pmnumber, 'Value', num);
            
            if sellayer == numel(model)
                if sellayer ~= 1
                    set(app.pmnumber, 'value', sellayer-1);
                else
                    set(app.pmnumber, 'value', 1);
                end
            end
            %set(gcbf, 'userdata', model);
            app.model = model;
            
            % display the parameters corresponding to the selected particle number.
            pmnumber_Callback([], [], handles)
        end
        
        function pbsave_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in pbsave.
            
            % hObject    handle to pbsave (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % [filename, pathname] = uiputfile( ...
            %    '*.mat', ...
            %     'Save as', ...
            %     'MultiSelect', 'off');
            %
            % % This code checks if the user pressed cancel on the dialog.
            %
            % if isequal(filename,0) || isequal(pathname,0)
            %    disp('User pressed cancel')
            %    return
            % else
            %    disp(['User selected ', fullfile(pathname, filename)])
            % end
            
            %fn = fullfile(pathname, filename);
            %model = app.model;
            model = app.model;
            N = str2double(get(app.pmnumber, 'value'));
            %save(fn, model{N});
            model = model{N};
            uisave('model', [model.shape, '.mat']);
        end
        
        function setvalues(app)
            %model = app.model;
            try
                model = app.model;
            catch
                model = [];
            end
            N = str2double(get(app.pmnumber, 'value'));
            shape = app.pmshape.Value;
            %Nshape = app.pmshape.ValueIndex;
            model{N}.shape = shape;
            %model{N}.positioninput = eval(app.edposition.Value);
            model{N}.positioninput = app.edposition.Value;
            model{N}.position2 = app.ed_position2.Value;
            
            if isempty(app.ed_position_LabCoordinate.Value)
                tmp = [];
            else
                tmp = eval(app.ed_position_LabCoordinate.Value);
            end
            model{N}.position_lab = tmp;
            
            % model{N}.centering = tmp;
            % if isempty(model{N}.centering)
            %     model{N}.centering = '';
            % end
            % posinput = eval(model{N}.positioninput);
            % switch lower(model{N}.centering)
            %     case {'', 'p'}
            %         model{N}.position = posinput;
            %     case 'i'
            %         model{N}.position = [posinput; ...
            %             posinput + repmat([1/2, 1/2, 1/2], size(posinput, 1), 1)];
            %     case 'f'
            %         model{N}.position = [posinput; ...
            %             posinput + repmat([1/2, 1/2, 0], size(posinput, 1), 1);...
            %             posinput + repmat([1/2, 0, 1/2], size(posinput, 1), 1);...
            %             posinput + repmat([0, 1/2, 1/2], size(posinput, 1), 1);...
            %             ];
            % end
            
            model{N}.orientationfactor = str2double(app.ed_degofrandomorientation.Value);
            
            switch lower(model{N}.shape)
                case 'paespherical'
                    shapefield = 'radius';
                    sigfield = 'radius_sig';
                    shellfield = 'DNAs';
                    set(app.edsize, 'enable', 'on');
                    set(app.edrho, 'enable', 'on')
                    set(app.edsizewidth, 'enable', 'on');
                    set(app.edLshell, 'enable', 'on');
                    set(app.ed_PDBfilename, 'enable', 'off');
                    set(app.pb_loadPDB, 'enable', 'off');
                case 'sphere'
                    shapefield = 'radius';
                    sigfield = 'radius_sig';
                    shellfield = 'Lshell';
                    set(app.edsize, 'enable', 'on');
                    set(app.edrho, 'enable', 'on')
                    set(app.edsizewidth, 'enable', 'on');
                    set(app.edLshell, 'enable', 'on');
                    set(app.ed_PDBfilename, 'enable', 'off');
                    set(app.pb_loadPDB, 'enable', 'off');
                case {'pdb', 'boxmodel'}
                    shapefield = 'edgelength';
                    sigfield = 'edgelength_sig';
                    shellfield = 'Lshell';
                    set(app.edsize, 'Value', num2str(1));
                    set(app.edrho, 'enable', 'on')
                    set(app.edsizewidth, 'enable', 'off');
                    set(app.edLshell, 'enable', 'off');
                    set(app.ed_PDBfilename, 'enable', 'on');
                    set(app.pb_loadPDB, 'enable', 'on');
                otherwise
                    shapefield = 'edgelength';
                    sigfield = 'edgelength_sig';
                    shellfield = 'Lshell';
            end
            
            model{N}.(shapefield) = eval(app.edsize.Value);
            sig = eval(app.edsizewidth.Value);
            if sig > 1
                error('sig should be less than 1')
            end
            
            if numel(model{N}.(shapefield)) == numel(sig)
                model{N}.(sigfield) = model{N}.(shapefield).*sig;
            elseif numel(sig) == 1
                model{N}.(sigfield) = model{N}.(shapefield)*sig;
            elseif isscalar(model{N}.(shapefield))
                error('The number of sig is larger than that of edgelength');
            end
            model{N}.rho = eval(app.edrho.Value);
            model{N}.(shellfield) = eval(app.edLshell.Value);
            if (model{N}.(shapefield) == 0)
                cprintf('red', '%s of the model %d is 0\n', shapefield, N);
                model{N} = [];
                %set(gcbf, 'userdata', model);
                app.model = model;
                return
            end
            if ~isempty(model{N}.(shellfield))
                if model{N}.(shellfield) == 0
                    model{N}.(shellfield) = [];
                end
            end
            
            
            model{N}.SOF = eval(app.edSOF.Value);
            %model{N}.color = app.edcolor.Value;
            model{N}.color = app.pm_color.UserData{app.pm_color.ValueIndex,2}
            model{N}.TfactorB = 0;
            % fnstr = get(app.ed_Pqmononame.Value;
            %
            % if isempty(fnstr)
            %     try
            %     model{N} = rmfield(model{N}, 'Pqname');
            %     end
            %     model{N}.Pqname = fnstr;
            % end
            
            fnstr = app.ed_PDBfilename.Value;
            if isempty(fnstr)
            % else
                try
                    model{N} = rmfield(model{N}, 'pdbfilename');
                end
            else
                %model{N}.pdbfilename = fnstr;
                switch model{N}.shape
                    case 'sqrtPq'
                        model{N}.rhoname = fnstr;
                    case 'rho-spherical'
                        model{N}.rhoname = fnstr;
                    case 'PDB'
                        model{N}.pdbfilename = fnstr;
                    case 'BOXmodel'
                        model{N}.BOXname = fnstr;
                end
            end
            
            check_Pqoption(app);
            
            h = get(app.uibg_ParticleOrientation, 'SelectedObject');
            model{N}.OrientationType = get(h, 'Text');
            model{N}.Rotmat = eye(3);
            model{N}.Rotstring1 = app.edphi.Value;
            model{N}.Rotstring2 = app.edtheta.Value;
            model{N}.Rotstring3 = app.edpsi.Value;
            switch lower(model{N}.OrientationType)
                case 'euler'
                    phi = str2double(app.edphi.Value);
                    theta = str2double(app.edtheta.Value);
                    psi = str2double(app.edpsi.Value);
            
                    model{N}.rotangle = [phi, theta, psi];
                    if (phi~=0)|(theta~=0)|(psi~=0)
                        Rmat = eulerAnglesToRotation3d(phi, theta, psi);
                        model{N}.Rotmat = Rmat(1:3, 1:3);
                    end
                case 'around a vector'
                    vector = eval(app.edphi.Value);
                    angle = str2double(app.edtheta.Value);
                    model{N}.Rotmat = rotate_around_vector(vector, angle);
                    model{N}.rotvector = vector;
                    model{N}.rotangle = angle;
                case 'xyz->hkl'
                    try
                        hklx = eval(app.edphi.Value);
                    catch
                        hklx = [];
                    end
                    try
                        hkly = eval(app.edtheta.Value);
                    catch
                        hkly = [];
                    end
                    try
                        hklz = eval(app.edpsi.Value);
                    catch
                        hklz = [];
                    end
            
                    XYZ = [1, 0, 0; 0, 1, 0; 0, 0, 1];
                    if isempty(hklx) || numel(hklx) ~= 3
                        hklx = [0, 0, 0];
                    end
                    if isempty(hkly) || numel(hkly) ~= 3
                        hkly = [0, 0, 0];
                    end
                    if isempty(hklz) || numel(hklz) ~= 3
                        hklz = [0, 0, 0];
                    end
                    isempty_rvector = [(isempty(hklx)|| sum(hklx.^2) == 0),...
                        (isempty(hkly)|| sum(hkly.^2) == 0),...
                        isempty(hklz)|| sum(hklz.^2) == 0];
            
                    hkl = [hklx; hkly; hklz]; % a*; b*; c*
                    hkl = hkl(~isempty_rvector, :);
                    XYZ = XYZ(~isempty_rvector, :);
                    %cellinfo = evalin('base', 'cellinfo');
                    %HKL = cellinfo.recimat*hkl';
                    HKL = hkl';
                    HKL = HKL';
                    R = eye(3);
                    for i=1:size(HKL, 1)
                        Ri = rotate_between_vectors(HKL(i, :), XYZ(i, :));
                        R = R*Ri;
                    end
                    model{N}.Rotmat = R;
            end
            %model{N}.Rotmat = R2*model{N}.Rotmat;
            
            %set(gcbf, 'userdata', model);
            app.model = model;;
            % Since the following function also load and save SgAtoms, it should be
            % after assignin... line.
            uibg_ParticleOrientation_SelectionChangedFcn(app, []);
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Load data for component configuration
            componentData = load('particlemaker_PDB_App.mat');
            
            % Set component properties that require runtime configuration
            app.pm_wyckoff.UserData = componentData.pm_wyckoff.UserData;
            app.pm_rho.UserData = componentData.pm_rho.UserData;
            app.pm_color.UserData = componentData.pm_color.UserData;
            app.pm_orientationfactor.UserData = componentData.pm_orientationfactor.UserData;
        end
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function particlemaker_PDB_OpeningFcn(app, varargin)
            % --- Executes just before particlemaker_PDB is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.particlemaker_pdb, 'onscreen');
            update_wyckoff(app)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to particlemaker_PDB (see VARARGIN)
            
            % Choose default command line output for particlemaker_PDB
%            app.output = hObject;
            
            % Update handles structure
%            guidata(hObject, handles);
        end

        % Value changed function: edLshell
        function edLshell_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edLshell (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Text') returns contents of edLshell as text
            %        str2double(get(hObject,'Text')) returns contents of edLshell as a double
            setvalues(app)
        end

        % Value changed function: edSOF
        function edSOF_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edSOF (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Text') returns contents of edSOF as text
            %        str2double(get(hObject,'Text')) returns contents of edSOF as a double
            setvalues(app)
        end

        % Value changed function: ed_position2
        function ed_position2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ed_centering (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Text') returns contents of ed_centering as text
            %        str2double(get(hObject,'Text')) returns contents of ed_centering as a double
            setvalues(app)
        end

        % Value changed function: ed_degofrandomorientation
        function ed_degofrandomorientation_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ed_degofrandomorientation (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Text') returns contents of ed_degofrandomorientation as text
            %        str2double(get(hObject,'Text')) returns contents of ed_degofrandomorientation as a double
            setvalues(app)
        end

        % Value changed function: ed_position_LabCoordinate
        function ed_position_LabCoordinate_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ed_position_LabCoordinate (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Text') returns contents of ed_position_LabCoordinate as text
            %        str2double(get(hObject,'Text')) returns contents of ed_position_LabCoordinate as a double
            setvalues(app)
        end

        % Value changed function: edcolor
        function edcolor_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edcolor (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Text') returns contents of edcolor as text
            %        str2double(get(hObject,'Text')) returns contents of edcolor as a double
            setvalues(app)
        end

        % Value changed function: edphi
        function edphi_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edphi (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Text') returns contents of edphi as text
            %        str2double(get(hObject,'Text')) returns contents of edphi as a double
            setvalues(app)
        end

        % Value changed function: edposition
        function edposition_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edposition (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Text') returns contents of edposition as text
            %        str2double(get(hObject,'Text')) returns contents of edposition as a double
            setvalues(app)
        end

        % Value changed function: edpsi
        function edpsi_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edpsi (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Text') returns contents of edpsi as text
            %        str2double(get(hObject,'Text')) returns contents of edpsi as a double
            setvalues(app)
        end

        % Value changed function: edrho
        function edrho_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edrho (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Text') returns contents of edrho as text
            %        str2double(get(hObject,'Text')) returns contents of edrho as a double
            setvalues(app)
        end

        % Value changed function: edsize
        function edsize_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edsize (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Text') returns contents of edsize as text
            %        str2double(get(hObject,'Text')) returns contents of edsize as a double
            setvalues(app)
        end

        % Value changed function: edsizewidth
        function edsizewidth_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edsizewidth (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Text') returns contents of edsizewidth as text
            %        str2double(get(hObject,'Text')) returns contents of edsizewidth as a double
            setvalues(app)
        end

        % Value changed function: edtheta
        function edtheta_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edtheta (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Text') returns contents of edtheta as text
            %        str2double(get(hObject,'Text')) returns contents of edtheta as a double
            setvalues(app)
        end

        % Menu selected function: mnAddParticle
        function mnAddParticle_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to mnAddParticle (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            pbadd_Callback(app, hObject, eventdata, handles)
        end

        % Menu selected function: mnDrawParticle
        function mnDrawParticle_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to mnDrawParticle (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            pbdraw_Callback(app, event)
        end

        % Menu selected function: mnDrawParticleinUnitCell
        function mnDrawParticleinUnitCell_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to mnDrawParticleinUnitCell (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            pbdrawinlattice_Callback(app)
        end

        % Menu selected function: mnLoadASet
        function mnLoadASet_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to mnLoadASet (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            PM_load_particles(handles)
        end

        % Menu selected function: mnLoadmat
        function mnLoadmat_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to mnLoadmat (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            pbload_Callback(app, hObject, eventdata, handles)
        end

        % Menu selected function: mnRemoveParticle
        function mnRemoveParticle_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to mnRemoveParticle (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            t = numel(app.pmnumber.Items);
            if t<2
                return;
            end
            sellayer = str2double(get(app.pmnumber, 'value'));
            pmitems = app.pmnumber.Items;
            %model = app.model;
            model = app.model;
            %model = gf_model(model, 'remove', 'particle', sellayer);
            model(sellayer) = [];
            pmitems(sellayer) = [];
            %particle_update(model, sellayer, handles);
            %t = numel(model.particle);
            %num = numel(md.edensity);
            %num = numlist2cellstr((1:t-1));
            set(app.pmnumber, 'items', pmitems)
            %set(app.pmnumber, 'Value', sellayer-1);
            
            if sellayer == numel(model)
                if sellayer ~= 1
                    set(app.pmnumber, 'value', sellayer-1);
                end
            end
            %set(gcbf, 'userdata', model);
            app.model = model;
            
            % display the parameters corresponding to the selected particle number.
            pmnumber_Callback(app)
        end

        % Menu selected function: mnSaveAllParticles
        function mnSaveAllParticles_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to mnSaveAllParticles (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            particles = get(gcf, 'userdata');
            PM_save_particles(particles)
        end

        % Menu selected function: mnSaveAs
        function mnSaveAs_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to mnSaveAs (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            pbsave_Callback(app, hObject, eventdata, handles)
        end

        % Button pushed function: pb_Fqcal
        function pb_Fqcal_Callback(app, event)
            % --- Executes on button press in pb_Fqcal.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pb_Fqcal (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            model = app.model;
            N = str2double(get(app.pmnumber, 'value'));
            q = evalin('base', 'q');
            cprintf('*blue', 'Wait for computing <F(q)>.\n');
            [~, ~, Fqmean] = AnisoFormFactor(model{N}, q, [], 3);
            cprintf('*blue', 'Done.\n');
            Fqmean = [q(:), Fqmean(:)];
            model{N}.Fqmean = Fqmean;
            model{N}.Fq = Fqmean;
            app.model = model;
        end

        % Button pushed function: pb_PickPqname
        function pb_PickPqname_Callback(app, event)
            % --- Executes on button press in pb_PickPqname.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pb_PickPqname (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                [filename, pathname] = uigetfile( ...
                   {'*.dat','dat file (*.dat)'; ...
                    '*.*',  'All Files (*.*)'}, ...
                    'Pick a file', ...
                    'MultiSelect', 'off');
            
                % This code checks if the user pressed cancel on the dialog.
            
                if isequal(filename,0) || isequal(pathname,0)
                   disp('User pressed cancel')
                   return
                else
                   disp(['User selected ', fullfile(pathname, filename)])
                end
            
            pb_clearPqfields_Callback(hObject, eventdata, handles)
            model = app.model;
            N = str2double(get(app.pmnumber, 'value'));
            [a, b] = hdrload(fullfile(pathname, filename));
            [keyw, remw] = strtok(a);
            Pqmono.size = str2double(remw(2:end));
            Pqmono.Pq = b(:, 1:2);
            Pqmono.Fq_o = [b(:,1), b(:,3)+sqrt(-1)*b(:,4)];
            model{N}.Pqmono = Pqmono;
            app.model = model;
        end

        % Button pushed function: pb_clearPqfields
        function pb_clearPqfields_Callback(app, event)
            % --- Executes on button press in pb_clearPqfields.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pb_clearPqfields (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            model = app.model;
            N = str2double(get(app.pmnumber, 'value'));
            %[Sq, r,pr] = debyepr(q, x, y, z, 500, atm, Fq)
            %Pq = pq_pdb(q, pdb.atom);
            if isfield(model{N}, 'Pq')
                model{N} = rmfield(model{N}, 'Pq');
                model{N} = rmfield(model{N}, 'Fq_o');
                model{N} = rmfield(model{N}, 'Fq_os');
            end
            if isfield(model{N}, 'Pqpdb')
                model{N} = rmfield(model{N}, 'Pqpdb');
            end
            if isfield(model{N}, 'Pqmono')
                model{N} = rmfield(model{N}, 'Pqmono');
            end
            app.model = model;
        end

        % Button pushed function: pb_loadPDB
        function pb_loadPDB_Callback(app, event)
            % --- Executes on button press in pb_loadPDB.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pb_loadPDB (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            N = str2double(get(app.pmnumber, 'value'));
            Nsh = app.pmshape.ValueIndex;
            vshape = app.pmshape.Value;
            %vshape = C{Nsh};
            switch vshape
                case 'PDB'
                    fileselectstr = {'*.pdb','PDB file (*.pdb)'; ...
               '*.mat','MAT file (*.mat)'; ...
                '*.*',  'All Files (*.*)'};
                case 'BOXmodel'
                    fileselectstr = {'*.mat','MAT file (*.mat)'; ...
                '*.*',  'All Files (*.*)'};
                case 'rho-spherical'
                    fileselectstr = {'*.dat','DAT file (*.dat)'; ...
                '*.*',  'All Files (*.*)'};
                case 'sqrtPq'
                    fileselectstr = {'*.dat','DAT file (*.dat)'; ...
                '*.*',  'All Files (*.*)'};
                otherwise
                    cprintf('*red', 'Use this only for PDB, BOXmodel and rho-spherical.\n');
                    return
            end
            %set(app.pmshape, 'value', vshape);
            
            [filename, pathname] = uigetfile( ...
                fileselectstr, ...
                'Pick a file', ...
                'MultiSelect', 'off');
            
            % This code checks if the user pressed cancel on the dialog.
            
            if isequal(filename,0) || isequal(pathname,0)
               disp('User pressed cancel')
               return
            else
               disp(['User selected ', fullfile(pathname, filename)])
            end
            FN = fullfile(pathname, filename);
            [~, PDBname, ext] = fileparts(FN);
            
            model = app.model;
            
            switch vshape
                case 'sqrtPq'
                    dt = load(FN);
                    model{N}.Pq = dt;
                    model{N}.rhoname = PDBname;
                case 'rho-spherical'
                    dt = load(FN);
                    model{N}.rhomodel = dt;
                    model{N}.rhoname = PDBname;
                case 'PDB'
                    switch ext
                        case '.pdb'
                            cprintf('*blue', 'Wait for loading.....\n');
                            pdb = PDB(FN);
                            cprintf('*blue', 'File "%s" are loaded.\n', FN);
                        case '.mat'
                            dt = load(FN);
                            pdb = dt.pdb;
                    end
                    model{N}.pdb = pdb;
                    model{N}.pdbfilename = PDBname;
                case 'BOXmodel'
                    dt = load(FN);
                    dtfieldName = fieldnames(dt);
                    model{N}.BOXmodel = dt.(dtfieldName{1});
                    model{N}.BOXname = PDBname;
            %        model{N} = rmfield(model{N}, 'pdbfilename');
            end
            
            set(app.ed_PDBfilename, 'Value', PDBname);
            app.model = model;
        end

        % Button pushed function: pbdraw
        function pbdraw_Callback(app, event)
            % --- Executes on button press in pbdraw.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbdraw (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            setvalues(app)
            %model = app.model;
            model = app.model;
            %t = numel(model.particle);
            N = str2double(get(app.pmnumber, 'value'));
            if isempty(model{N})
                return;
            end
            pt{1} = model{N};
            %figTag = getappdata(gcbf, 'figTag');
            figTag = 'SingleParticle_Fig';
            drawparticle(pt, figTag);
        end

        % Button pushed function: pbdrawinlattice
        function pbdrawinlattice_Callback(app, event)
            % --- Executes on button press in pbdrawinlattice.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbdrawinlattice (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            setvalues(app)
            model = app.model;
            %model = app.model;
            %model = evalin('base', 'particles');
            %t = numel(model.particle);
            currentModelN = str2double(get(app.pmnumber, 'value'));
            if isempty(model{currentModelN})
                return;
            end
            
            pt{1} = model{currentModelN};
            figTag = getappdata(gcbf, 'figTag');
            sginfo = evalin('base', 'sginfo');
            cellinfo = evalin('base', 'cellinfo');
            if contains_replace(sginfo.LatticeSystem, 'trigonal')
                if (cellinfo.A==cellinfo.B) && (cellinfo.A==cellinfo.C) && (cellinfo.alpha==cellinfo.beta) && (cellinfo.alpha==cellinfo.gamma)
                    sginfo.SymMatrices = sginfo.SymMatricesR;
                    sginfo.LatticeSystem = 'trigonal(R)';
                    sginfo.NoLatticeCenteringVector = 1;
                end
            end
            figh = findobj('tag', 'particlemaker_figure');
            if isempty(figh)
                figh = findobj('tag', figTag);
            else
                figTag = 'particlemaker_figure';
            end
            if isempty(figh)
                figh = figure;
                if isempty(figTag)
                    figTag = 'mymodel';
                end
                setappdata(gcbf, 'figTag', figTag)
                set(figh, 'tag', figTag);
            end
            azel = get(findobj(figh, 'type', 'axes'), 'view');
            housekeepfigure(app, currentModelN)
            setappdata(figh, 'myviewangle', azel);
            
            [p, b, ph] = drawparticle(pt, figTag, sginfo, cellinfo);
            set(ph, 'tag', strcat('Model', num2str(currentModelN)));
            
            check_duplicatedparticles(app, figTag, b, p{1}.color)
        end

        % Button pushed function: pbloadaset
        function pbloadaset_Callback(app, event)
            % --- Executes on button press in pbloadaset.
            
                [filename, pathname] = uigetfile( ...
                   {'*.mat','mat file (*.dat)'; ...
                    '*.*',  'All Files (*.*)'}, ...
                    'Pick a file', ...
                    'MultiSelect', 'off');
            
                % This code checks if the user pressed cancel on the dialog.
            
                if isequal(filename,0) || isequal(pathname,0)
                   disp('User pressed cancel')
                   return
                else
                   disp(['User selected ', fullfile(pathname, filename)])
                end
            model = load(fullfile(pathname, filename));
            %set(gcbf, 'userdata', model);
            app.model = model;;
            %t = numel(model.particle);
            n = numel(model);
            %num = numel(md.edensity);
            num = numlist2cellstr((1:n));
            app.model = model;;
            set(app.pmnumber, 'Value', num);
        end

        % Value changed function: pm_color
        function pm_color_Callback(app, event)
            % --- Executes on selection change in pm_color.
            col = app.pm_color.UserData{app.pm_color.ValueIndex,1};
            if ~ischar(col)
                col = sprintf('[%0.3f,%0.3f,%0.3f]',col);
            end
            set(app.edcolor, 'Value', col);
            setvalues(app)
        end

        % Value changed function: pm_orientationfactor
        function pm_orientationfactor_Callback(app, event)
            % --- Executes on selection change in pm_orientationfactor.
            
            %set(app.ed_degofrandomorientation, 'Value', ud{get(hObject,'Value'),2})
            set(app.ed_degofrandomorientation, 'Value', num2str(app.pm_orientationfactor.UserData{app.pm_orientationfactor.ValueIndex,2}));
            setvalues(app)
        end

        % Value changed function: pm_rho
        function pm_rho_Callback(app, event)
            % --- Executes on selection change in pm_rho.
            
            set(app.edrho, 'Value', num2str(app.pm_rho.UserData{app.pm_rho.ValueIndex,2}));
            setvalues(app)
        end

        % Value changed function: pm_wyckoff
        function pm_wyckoff_Callback(app, event)
            % --- Executes on selection change in pm_wyckoff.
            
            wy = event.Source.Value;
            wy = strsplit(wy, ':');
            set(app.edposition, 'Value', wy{2});
            try
                setvalues(app)
            catch
            
            end
        end

        % Value changed function: pmnumber
        function pmnumber_Callback(app, event)
            % --- Executes on selection change in pmnumber.
            

            model = app.model;
            if isempty(model)
                model = app.model;
            end
            N = str2double(get(app.pmnumber, 'value'));
            Nparticleall = numel(app.pmnumber.Value);
            % if Nparticleall < numel(model)
            %     model(Nparticleall+1:end) = [];
            %     app.model = model;
            % end
            
           
            if ~isempty(model)
                vshape = model{N}.shape;
                set(app.pmshape, 'value', vshape);
            else
                vshape = app.pmshape.Value;
            end
            
            % str = [num2str(model{N}.positioninput(1)),',',num2str(model{N}.positioninput(2)),',',...
            %     num2str(model{N}.positioninput(3))];
            % set(app.edposition, 'Value', ['[', str, ']']);
            if N>numel(model)
                error('numel(model) is less than the number chosen.')
            end
            if isfield(model{N}, 'positioninput')
                if ~ischar(model{N}.positioninput)
                    posinput = ['[',num2str(model{N}.positioninput),']'];
                else
                    posinput = model{N}.positioninput;
                end
                set(app.edposition, 'Value', posinput);
            else
                set(app.edposition, 'Value', 0);
            end
            if isfield(model{N}, 'position2')
                if ~ischar(model{N}.position2)
                    pos2 = ['[',num2str(model{N}.position2),']'];
                else
                    pos2 = model{N}.position2;
                end
                set(app.ed_position2, 'Value', pos2);
            else
                set(app.ed_position2, 'Value', 0);
            end
            
            % if isfield(model{N}, 'centering')
            %     %set(app.ed_position2, 'Value', num2str(model{N}.centering));
            %     set(app.ed_position2, 'Value', ['[',num2str(model{N}.position2), ']']);
            % else
            %     set(app.ed_position2, 'Value', '');
            % end
            
            if isfield(model{N}, 'position_lab')
                set(app.ed_position_LabCoordinate, 'Value', ['[',num2str(model{N}.position_lab), ']']);
            else
                set(app.ed_position_LabCoordinate, 'Value', '[]');
            end
            
            if isfield(model{N}, 'orientationfactor')
                set(app.ed_degofrandomorientation, 'Value', num2str(model{N}.orientationfactor));
            else
                set(app.ed_degofrandomorientation, 'Value', 0);
            end
            
            set(app.edsize, 'enable', 'on');
            set(app.edsizewidth, 'enable', 'on');
            set(app.edLshell, 'enable', 'on');
            set(app.ed_PDBfilename, 'enable', 'on');
            set(app.pb_loadPDB, 'enable', 'on');
            % text of txtsize
            switch lower(model{N}.shape)
                case {'paespherical'}
                    shapefield = 'radius';
                    sigfield = 'radius_sig';
                    shellfield ='DNAs';
                    app.txtsize.Text = 'radius';
                    app.txtshell.Text = 'DNA([lsingle,Nseq,Nsticky,DNA#,hyb_pair#,invkappa])';
                case {'sphere'}
                    shapefield = 'radius';
                    sigfield = 'radius_sig';
                    shellfield ='Lshell';
                    app.txtsize.Text = 'radius';
                    app.txtshell.Text = "Shell Thickness";
                otherwise
                    shapefield = 'edgelength';
                    sigfield = 'edgelength_sig';
                    shellfield ='Lshell';
                    app.txtsize.Text = 'edge length';
                    app.txtshell.Text = "Shell Thickness";
            end
            switch model{N}.shape
                case {'PDB', 'BOXmodel', 'rho-spherical', 'sqrtPq'}
                    set(app.edsize, 'Value', 1)
                    set(app.edsize, 'enable', 'off');
                    set(app.edsizewidth, 'enable', 'off');
                    set(app.edLshell, 'enable', 'off');
                    cprintf('*red', 'Should load a model using the button "Load".\n')
                otherwise
                    set(app.ed_PDBfilename, 'enable', 'off');
                    set(app.pb_loadPDB, 'enable', 'off');
                    if isfield(model{N}, 'pdb')
                        model{N} = rmfield(model{N}, 'pdb');
                    end
                    if isfield(model{N}, 'BOXmodel')
                        model{N} = rmfield(model{N}, 'BOXmodel');
                    end
            end
            
            if numel(model{N}.(shapefield)) == 2
                str = [num2str(model{N}.(shapefield)(1)),',',num2str(model{N}.(shapefield)(2))];
                set(app.edsize, 'Value', ['[', str, ']']);
            else
                set(app.edsize, 'Value', num2str(model{N}.(shapefield)));
            end
            
            set(app.edsizewidth, 'Value', num2str(model{N}.(sigfield)/model{N}.(shapefield)));
            if isfield(model{N}, 'rho')
                if isscalar(model{N}.rho)
                    set(app.edrho, 'Value', num2str(model{N}.rho));
                else
                    set(app.edrho, 'Value', '[', num2str(model{N}.rho), ']');
                end
            else
                set(app.edrho, 'Value', '1');
            end
            
            if isfield(model{N}, shellfield)
                if isempty(model{N}.(shellfield))
                    set(app.edLshell, 'Value', '[]');
                else
                    val = '[';
                    for m = 1:numel(model{N}.(shellfield))
                        val = sprintf('%s%s,', val, num2str(model{N}.(shellfield)(m)));
                    end
                    val(end) = ']';
                    set(app.edLshell, 'Value', val);
                end
            else
                set(app.edLshell, 'Value', '[]');
            end
            
            check_Pqoption(app)
            switch model{N}.shape
                case 'PDB'
                    if isfield(model{N}, 'pdbfilename')
                        set(app.ed_PDBfilename, 'Value', model{N}.pdbfilename);
                    else
                        set(app.ed_PDBfilename, 'Value', '');
                    end
                case 'BOXmodel'
                    if isfield(model{N}, 'BOXname')
                        set(app.ed_PDBfilename, 'Value', model{N}.BOXname);
                    else
                        set(app.ed_PDBfilename, 'Value', '');
                    end
                case 'rho-spherical'
                    if isfield(model{N}, 'rhoname')
                        set(app.ed_PDBfilename, 'Value', model{N}.rhoname);
                    else
                        set(app.ed_PDBfilename, 'Value', '');
                    end
                case 'sqrtPq'
                    if isfield(model{N}, 'rhoname')
                        set(app.ed_PDBfilename, 'Value', model{N}.rhoname);
                    else
                        set(app.ed_PDBfilename, 'Value', '');
                    end
            end
            
            % setvalues(handles);
            orientobj = app.uibg_ParticleOrientation.Children;
            h_radiobutton =  findobj(orientobj, 'type', 'uiradiobutton');
            %orienttype = h_radiobutton.Value;
            if ~isfield(model{N}, 'OrientationType')
                model{N}.OrientationType = 'Euler';
            end
            for kk=1:numel(h_radiobutton)
                if strcmpi(h_radiobutton(kk).Text, model{N}.OrientationType)
                    h_radiobutton(kk).Value = 1;
                end
            end

            
            if isfield(model{N}, 'SOF')
                set(app.edSOF, 'Value', num2str(model{N}.SOF));
            else
                set(app.edSOF, 'Value', '');
            end
            
            if isfield(model{N}, 'color')
                Ncol= findcellstr(app.pm_color.UserData(:,2), model{N}.color);
                set(app.edcolor, 'Value', app.pm_color.UserData{Ncol,1});
            else
                set(app.edcolor, 'Value', 'r');
            end
            
            if isfield(model{N}, 'Rotstring1')
                set(app.edphi, 'Value', num2str(model{N}.Rotstring1));
            else
                set(app.edphi, 'Value', '0');
            end
            
            if isfield(model{N}, 'Rotstring2')
                set(app.edtheta, 'Value', num2str(model{N}.Rotstring2));
            else
                set(app.edtheta, 'Value', '0');
            end
            
            if isfield(model{N}, 'Rotstring3')
                set(app.edpsi, 'Value', num2str(model{N}.Rotstring3));
            else
                set(app.edpsi, 'Value', '0');
            end
        end

        % Value changed function: pmshape
        function pmshape_Callback(app, event)
            % --- Executes on selection change in pmshape.
            
            %N = str2double(get(app.pmnumber, 'value'));
            shape = app.pmshape.Value;
            %Nshape = app.pmshape.ValueIndex;
            switch lower(shape)
                case 'paespherical'
                    app.txtsize.Text = 'radius';
                    app.txtshell.Text = 'DNA([lsingle,Nseq,Nsticky,DNA#,hyb_pair#,invkappa])';
                    disp("")
                    disp("Provide DNA parameters")
                    disp("  lsingle : Length of ssDNA")
                    disp("  Nseq : Number of duplex seq (including A)")
                    disp("  Nsticky: Number of basepair in sticky ends")
                    disp("  DNA/area: Number of duplex per core area")
                    disp("  hyb_par#: Index of hybridization pair (For particle 2, type 2)")
                    disp("  invkappa: Inverse of Debye length (0.5 for 500mM)")
                    disp(" example for nm : [0.4, 50, 5, 0.72, 1, 0.5]")
                    disp(" example for Angstrom : [4, 50, 5, 0.0072, 1, 0.5]")
                    disp("")
                    disp("")
                case 'sphere'
                    app.txtsize.Text = 'radius';
                    app.txtshell.Text = 'Shell Thickness';
                case {'pdb', 'boxmodel'}
                    app.txtsize.Text = '1';
                    app.txtsize.Text = 'edge length';
                    app.txtshell.Text = 'Shell Thickness';
            %        cprintf('*blue', 'Recommended to compute P(q) and Load it\n');
                otherwise
                    app.txtsize.Text = 'edge length';
                    app.txtshell.Text = 'Shell Thickness';
            end
            
            setvalues(app)
        end

        % Button pushed function: pushbutton18
        function pushbutton18_Callback(app, event)
            % --- Executes on button press in pushbutton18.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton18 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            model = app.model;
            N = str2double(get(app.pmnumber, 'value'));
            %[Sq, r,pr] = debyepr(q, x, y, z, 500, atm, Fq)
            q = evalin('base', 'q');
            cprintf('*blue', 'Computation started...Wait.\n');
            %Pq = pq_pdb(q, pdb.atom);
            
            p{1} = model{N};
            switch model{N}.shape
                case 'sphere'
                    shapefield = 'radius';
                    sigfield = 'radius_sig';
                case 'PDB'
                    shapefield = 'edgelength';
                    sigfield = 'edgelength_sig';
                    set(app.edsize, 'Value', num2str(1));
                otherwise
                    shapefield = 'edgelength';
                    sigfield = 'edgelength_sig';
            end
            
            p{1}.(sigfield) = 0;
            
            [Pqmono, ~, Fqo] = Pq_general(p, q);
            Pq = [];
            Pq.Pq = [q(:), Pqmono];
            Pq.Fq_o = [q(:), Fqo];
            Pq.size = p{1}.(shapefield)(1);
            if isfield(model{N}, 'Pq')
                model{N} = rmfield(model{N}, 'Pq');
            end
            model{N}.Pqmono = Pq;
            %model{N}.Fq_os = [q(:), Fq_os];
            
            %[~, Pq] = AnisoFormFactor(model{N}, q, [], 3);
            cprintf('*blue', 'Computation done.\n');
            app.model = model;
        end

        % Button pushed function: pushbutton19
        function pushbutton19_Callback(app, event)
            % --- Executes on button press in pushbutton19.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton19 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [filename, pathname] = uiputfile( ...
               {'*.dat','Dat file (*.dat)'; ...
                '*.*',  'All Files (*.*)'}, ...
                'Save data');
            
            % This code checks if the user pressed cancel on the dialog.
            
            if isequal(filename,0) || isequal(pathname,0)
               disp('User pressed cancel')
               return
            end
            
            model = app.model;
            q = evalin('base', 'q');
            
            N = str2double(get(app.pmnumber, 'value'));
            if isfield(model{N}, 'Pqmono')
                Pqmono = model{N}.Pqmono;
                k = [q(:), Pqmono.Pq(:, 2), ...
                    real(Pqmono.Fq_o(:,2)), imag(Pqmono.Fq_o(:,2))];
            else
                errordlg('Dude! Calculate before trying to save.', 'My Error Dialog');
                return
            end
            
            % switch model{N}.shape
            %     case 'sphere'
            %         shapefield = 'radius';
            %         sigfield = 'radius_sig';
            %     case 'PDB'
            %         shapefield = 'edgelength';
            %         sigfield = 'edgelength_sig';
            %         set(app.edsize, 'Value', num2str(1));
            %     otherwise
            %         shapefield = 'edgelength';
            %         sigfield = 'edgelength_sig';
            % end
            
            FN = fullfile(pathname, filename);
            fid = fopen(FN, 'w');
            %fprintf(fid, 'Size : %0.5e\n', model{N}.(shapefield));
            fprintf(fid, 'Size : %0.5e\n', model{N}.Pqmono.size);
            fprintf(fid, '%0.8e %0.8e %0.8e %0.8e\n', k');
            fclose(fid);
        end

        % Button pushed function: pushbutton20
        function pushbutton20_Callback(app, event)
            % --- Executes on button press in pushbutton20.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton20 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            model = app.model;
            N = str2double(get(app.pmnumber, 'value'));
            %[Sq, r,pr] = debyepr(q, x, y, z, 500, atm, Fq)
                [filename, pathname] = uigetfile( ...
                   {'*.dat','dat file (*.dat)'; ...
                    '*.*',  'All Files (*.*)'}, ...
                    'Pick a file', ...
                    'MultiSelect', 'off');
            
                % This code checks if the user pressed cancel on the dialog.
            
                if isequal(filename,0) || isequal(pathname,0)
                   disp('User pressed cancel')
                   return
                else
                   disp(['User selected ', fullfile(pathname, filename)])
                end
            
            k = load(fullfile(pathname, filename));
            
            model{N}.Pq = [k(:,1), k(:,2)];
            if size(k, 2)>2
                model{N}.Fq_o = [k(:,1), k(:,3)+sqrt(-1)*k(:,4)];
                model{N}.Fq_os = [k(:,1), k(:,5)+sqrt(-1)*k(:,6)];
            end
            %[~, Pq] = AnisoFormFactor(model{N}, q, [], 3);
            app.model = model;
        end

        % Button pushed function: pushbutton21
        function pushbutton21_Callback(app, event)
            % --- Executes on button press in pushbutton21.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton21 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            model = app.model;
            N = str2double(get(app.pmnumber, 'value'));
            %[Sq, r,pr] = debyepr(q, x, y, z, 500, atm, Fq)
            q = evalin('base', 'q');
            cprintf('*blue', 'Computation started...Wait.\n');
            %Pq = pq_pdb(q, pdb.atom);
            
            p{1} = model{N};
            switch model{N}.shape
                case 'sphere'
                    shapefield = 'radius';
                    sigfield = 'radius_sig';
                case 'PDB'
                    shapefield = 'edgelength';
                    sigfield = 'edgelength_sig';
                    set(app.edsize, 'Value', num2str(1));
                otherwise
                    shapefield = 'edgelength';
                    sigfield = 'edgelength_sig';
            end
            
            %p{1}.(shapefield) = 0;
            
            [Pqall, ~, Fqo, ~, Fq_os] = Pq_general(p, q);
            model{N}.Pq = [q(:), Pqall];
            model{N}.Fq_o = [q(:), Fqo];
            model{N}.Fq_os = [q(:), Fq_os];
            
            %[~, Pq] = AnisoFormFactor(model{N}, q, [], 3);
            cprintf('*blue', 'Computation done.\n');
            app.model = model;
        end

        % Button pushed function: pushbutton22
        function pushbutton22_Callback(app, event)
            % --- Executes on button press in pushbutton22.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton22 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [filename, pathname] = uiputfile( ...
               {'*.dat','Dat file (*.dat)'; ...
                '*.*',  'All Files (*.*)'}, ...
                'Save data');
            
            % This code checks if the user pressed cancel on the dialog.
            
            if isequal(filename,0) || isequal(pathname,0)
               disp('User pressed cancel')
               return
            end
            
            model = app.model;
            N = str2double(get(app.pmnumber, 'value'));
            if isfield(model{N}, 'Pq')
                k = [model{N}.Pq(:, 1), model{N}.Pq(:, 2), ...
                    real(model{N}.Fq_o(:, 2)), imag(model{N}.Fq_o(:, 2)), ...
                    real(model{N}.Fq_os(:, 2)), imag(model{N}.Fq_os(:, 2))];
            else
                errordlg('Dude! Calculate before trying to save.', 'My Error Dialog');
                return
            end
            
            FN = fullfile(pathname, filename);
            save(FN, 'k', '-ascii');
        end

        % Button pushed function: pushbutton23
        function pushbutton23_Callback(app, event)
            % --- Executes on button press in pushbutton23.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton23 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            model = app.model;
            N = str2double(get(app.pmnumber, 'value'));
            %[Sq, r,pr] = debyepr(q, x, y, z, 500, atm, Fq)
                [filename, pathname] = uigetfile( ...
                   {'*.dat','dat file (*.dat)'; ...
                    '*.*',  'All Files (*.*)'}, ...
                    'Pick a file', ...
                    'MultiSelect', 'off');
            
                % This code checks if the user pressed cancel on the dialog.
            
                if isequal(filename,0) || isequal(pathname,0)
                   disp('User pressed cancel')
                   return
                else
                   disp(['User selected ', fullfile(pathname, filename)])
                end
            pb_clearPqfields_Callback(hObject, eventdata, handles)
            model = app.model;
            N = str2double(get(app.pmnumber, 'value'));
            [a, b] = hdrload(fullfile(pathname, filename));
            [keyw, remw] = strtok(a);
            Pqmono.size = str2double(remw(2:end));
            Pqmono.Pq = b(:, 1:2);
            Pqmono.Fq_o = [b(:,1), b(:,3)+sqrt(-1)*b(:,4)];
            model{N}.Pqpdb = Pqmono;
            app.model = model;
        end

        % Button pushed function: pushbutton24
        function pushbutton24_Callback(app, event)
            % --- Executes on button press in pushbutton24.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton24 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            model = app.model;
            N = str2double(get(app.pmnumber, 'value'));
            %[Sq, r,pr] = debyepr(q, x, y, z, 500, atm, Fq)
            q = evalin('base', 'q');
            cprintf('*blue', 'Computation started...Wait.\n');
            %Pq = pq_pdb(q, pdb.atom);
            % model{N}.pdb
            
            p{1} = model{N};
            if ~isfield(p{1}, 'pdb')
                errordlg('Dude! Load PDB file.', 'My Error Dialog');
                return
            end
            
            %% slow but accurate
            %[Pq, Fqm] = pq_pdb(q, p{1}.pdb, 3);
            %% fast but less accurate
            Pq = pdbPq(q, p{1}.pdb);
            Pqpdb.Pq = [q(:), Pq];
            %Pqpdb.Fq_o = [q(:), Fqm];
            model{N}.Pqpdb = Pqpdb;
            if isfield(model{N}, 'Pq')
                model{N} = rmfield(model{N}, 'Pq');
            end
            
            %model{N}.Fq_os = [q(:), Fq_os];
            
            %[~, Pq] = AnisoFormFactor(model{N}, q, [], 3);
            cprintf('*blue', 'Computation done.\n');
            app.model = model;
        end

        % Button pushed function: pushbutton25
        function pushbutton25_Callback(app, event)
            % --- Executes on button press in pushbutton25.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton25 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [filename, pathname] = uiputfile( ...
               {'*.dat','Dat file (*.dat)'; ...
                '*.*',  'All Files (*.*)'}, ...
                'Save data');
            
            % This code checks if the user pressed cancel on the dialog.
            
            if isequal(filename,0) || isequal(pathname,0)
               disp('User pressed cancel')
               return
            end
            
            model = app.model;
            N = str2double(get(app.pmnumber, 'value'));
            if isfield(model{N}, 'Pqpdb')
                Pqpdb = model{N}.Pqpdb;
                k = [Pqpdb.Pq(:, 1), Pqpdb.Pq(:, 2), ...
                    real(Pqpdb.Fq_o(:, 2)), imag(Pqpdb.Fq_o(:, 2))];
            else
                errordlg('Dude! Calculate before trying to save.', 'My Error Dialog');
                return
            end
            
            FN = fullfile(pathname, filename);
            save(FN, 'k', '-ascii');
        end

        % Selection changed function: uibg_ParticleOrientation
        function uibg_ParticleOrientation_SelectionChangedFcn(app, event)
            % --- Executes when selected object is changed in uibg_ParticleOrientation.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to the selected object in uibg_ParticleOrientation
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            orintation_text = app.uibg_ParticleOrientation.SelectedObject.Text;
            try
                model = app.model;
                N = str2double(get(app.pmnumber, 'value'));
                model{N}.OrientationType = orintation_text;
            catch
                model = [];
            end
            
            switch lower(app.uibg_ParticleOrientation.SelectedObject.Text)
                case 'random'
                    if ~isempty(model)
                        if ~isfield(model{N}, 'Fq')
                            if isfield(model{N}, 'Fqmean')
                                Fqmean = model{N}.Fqmean;
                            else
                                q = evalin('base', 'q');
                                cprintf('*blue', 'Wait for computing <F(q)>.\n');
                                [~, ~, Fqmean] = AnisoFormFactor(model{N}, q, [], 3);
                                cprintf('*blue', 'Done.\n');
                                Fqmean = [sqrt(q(:,1).^2+q(:,2).^2+q(:,3).^2), Fqmean(:)];
                            end
                            model{N}.Fq = Fqmean;
                        end
                    end
                case 'around a vector'
                    set(app.txtphi, 'Text', 'vector')
                    set(app.txttheta, 'Text', 'Angle(deg)')
                    set(app.txtpsi, 'Text', 'off')
                case 'xyz->hkl'
                    set(app.txtphi, 'Text', 'X')
                    set(app.txttheta, 'Text', 'Y')
                    set(app.txtpsi, 'Text', 'Z')
                    set(app.txtpsi, 'enable', 'on')
                case 'euler'
                    set(app.txtphi, 'Text', 'Rotangle: phi')
                    set(app.txttheta, 'Text', 'Rotangle2: theta')
                    set(app.txtpsi, 'enable', 'on')
                    set(app.txtpsi, 'Text', 'Rotangle3: psi')
                otherwise
                    if ~isempty(model)
                        if isfield(model{N}, 'Fq')
                            model{N}.Fqmean = model{N}.Fq;
                            model{N} = rmfield(model{N}, 'Fq');
                        end
                    end
            end
            if ~isempty(model)
                app.model = model;;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.particlemaker_pdb = uifigure('Visible', 'off');
            colormap(app.particlemaker_pdb, 'parula');
            app.particlemaker_pdb.Name = 'particlemaker_PDB';
            app.particlemaker_pdb.Tag = 'particlemaker_pdb_fig';
            app.Untitled_1 = uimenu(app.particlemaker_pdb);
            app.Untitled_1.Text = 'File';
            app.Untitled_1.Tag = 'Untitled_1';
            app.mnLoadmat = uimenu(app.Untitled_1);
            app.mnLoadmat.MenuSelectedFcn = createCallbackFcn(app, @mnLoadmat_Callback, true);
            app.mnLoadmat.Text = 'Load from *.mat';
            app.mnLoadmat.Tag = 'mnLoadmat';
            app.Untitled_3 = uimenu(app.Untitled_1);
            app.Untitled_3.Text = 'Load from *.dat';
            app.Untitled_3.Tag = 'Untitled_3';
            app.mnLoadASet = uimenu(app.Untitled_1);
            app.mnLoadASet.MenuSelectedFcn = createCallbackFcn(app, @mnLoadASet_Callback, true);
            app.mnLoadASet.Text = 'Load a set';
            app.mnLoadASet.Tag = 'mnLoadASet';
            app.mnSaveAs = uimenu(app.Untitled_1);
            app.mnSaveAs.MenuSelectedFcn = createCallbackFcn(app, @mnSaveAs_Callback, true);
            app.mnSaveAs.Text = 'Save Current As *.mat';
            app.mnSaveAs.Tag = 'mnSaveAs';
            app.mnSaveAllParticles = uimenu(app.Untitled_1);
            app.mnSaveAllParticles.MenuSelectedFcn = createCallbackFcn(app, @mnSaveAllParticles_Callback, true);
            app.mnSaveAllParticles.Text = 'Save All Particles';
            app.mnSaveAllParticles.Tag = 'mnSaveAllParticles';
            app.Untitled_5 = uimenu(app.particlemaker_pdb);
            app.Untitled_5.Text = 'Items';
            app.Untitled_5.Tag = 'Untitled_5';
            app.mnAddParticle = uimenu(app.Untitled_5);
            app.mnAddParticle.MenuSelectedFcn = createCallbackFcn(app, @mnAddParticle_Callback, true);
            app.mnAddParticle.Text = 'Add Particle';
            app.mnAddParticle.Tag = 'mnAddParticle';
            app.mnRemoveParticle = uimenu(app.Untitled_5);
            app.mnRemoveParticle.MenuSelectedFcn = createCallbackFcn(app, @mnRemoveParticle_Callback, true);
            app.mnRemoveParticle.Text = 'Remove Particle';
            app.mnRemoveParticle.Tag = 'mnRemoveParticle';
            app.Untitled_6 = uimenu(app.particlemaker_pdb);
            app.Untitled_6.Text = 'Draw';
            app.Untitled_6.Tag = 'Untitled_6';
            app.mnDrawParticle = uimenu(app.Untitled_6);
            app.mnDrawParticle.MenuSelectedFcn = createCallbackFcn(app, @mnDrawParticle_Callback, true);
            app.mnDrawParticle.Text = 'Single Particle';
            app.mnDrawParticle.Tag = 'mnDrawParticle';
            app.mnDrawParticleinUnitCell = uimenu(app.Untitled_6);
            app.mnDrawParticleinUnitCell.MenuSelectedFcn = createCallbackFcn(app, @mnDrawParticleinUnitCell_Callback, true);
            app.mnDrawParticleinUnitCell.Text = 'Particle in the Unit Cell';
            app.mnDrawParticleinUnitCell.Tag = 'mnDrawParticleinUnitCell';
            app.pmshape = uidropdown(app.particlemaker_pdb);
            app.pmshape.Items = {'none', 'sphere', 'cylinder', 'octahedron', 'pyramid', 'truncatedoctahedron', 'facefilledTO', 'cube', 'truncatedcube', 'cubooctahedron', 'concavecube', 'concavecube', 'rhombicdodecahedron', 'rhombicuboctahedron', 'longrd', 'pentagonalbipyramid', 'icosahedron', 'THH', 'PAEspherical', 'rho-spherical', 'sqrtPq', 'PDB', 'BOXmodel'};
            app.pmshape.ValueChangedFcn = createCallbackFcn(app, @pmshape_Callback, true);
            app.pmshape.Tag = 'pmshape';
            app.pmshape.Value = 'none';
            app.text1 = uilabel(app.particlemaker_pdb);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Particle Shape :';
            app.text2 = uilabel(app.particlemaker_pdb);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Fractional Position :';
            app.edposition = uieditfield(app.particlemaker_pdb, 'text');
            app.edposition.ValueChangedFcn = createCallbackFcn(app, @edposition_Callback, true);
            app.edposition.Tag = 'edposition';
            app.edposition.Value = '[0, 0, 0]';
            app.txtsize = uilabel(app.particlemaker_pdb);
            app.txtsize.Tag = 'txtsize';
            app.txtsize.Text = 'Size :';
            app.edsize = uieditfield(app.particlemaker_pdb, 'text');
            app.edsize.ValueChangedFcn = createCallbackFcn(app, @edsize_Callback, true);
            app.edsize.Tag = 'edsize';
            app.edsize.Value = '10';
            app.text6 = uilabel(app.particlemaker_pdb);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Electron Density:';
            app.edrho = uieditfield(app.particlemaker_pdb, 'text');
            app.edrho.ValueChangedFcn = createCallbackFcn(app, @edrho_Callback, true);
            app.edrho.Tag = 'edrho';
            app.edrho.Value = '1';
            app.txtshell = uilabel(app.particlemaker_pdb);
            app.txtshell.Tag = 'text7';
            app.txtshell.Text = 'Shell Thickness :';
            app.edLshell = uieditfield(app.particlemaker_pdb, 'text');
            app.edLshell.ValueChangedFcn = createCallbackFcn(app, @edLshell_Callback, true);
            app.edLshell.Tag = 'edLshell';
            app.edLshell.Value = '0';
            app.text8 = uilabel(app.particlemaker_pdb);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Site Occupancy Factor :';
            app.edSOF = uieditfield(app.particlemaker_pdb, 'text');
            app.edSOF.ValueChangedFcn = createCallbackFcn(app, @edSOF_Callback, true);
            app.edSOF.Tag = 'edSOF';
            app.edSOF.Value = '1';
            app.text9 = uilabel(app.particlemaker_pdb);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Breadth of Size Distr(Fractional):';
            app.edsizewidth = uieditfield(app.particlemaker_pdb, 'text');
            app.edsizewidth.ValueChangedFcn = createCallbackFcn(app, @edsizewidth_Callback, true);
            app.edsizewidth.Tag = 'edsizewidth';
            app.edsizewidth.Value = '0';
            app.pbdraw = uibutton(app.particlemaker_pdb, 'push');
            app.pbdraw.ButtonPushedFcn = createCallbackFcn(app, @pbdraw_Callback, true);
            app.pbdraw.Tag = 'pbdraw';
            app.pbdraw.Text = 'Draw particle';
            app.pbdrawinlattice = uibutton(app.particlemaker_pdb, 'push');
            app.pbdrawinlattice.ButtonPushedFcn = createCallbackFcn(app, @pbdrawinlattice_Callback, true);
            app.pbdrawinlattice.Tag = 'pbdrawinlattice';
            app.pbdrawinlattice.Text = 'Draw in a lattice';
            app.pmnumber = uidropdown(app.particlemaker_pdb);
            app.pmnumber.Items = {'1'};
            app.pmnumber.ValueChangedFcn = createCallbackFcn(app, @pmnumber_Callback, true);
            app.pmnumber.Tag = 'pmnumber';
            app.pmnumber.Value = '1';
            app.text13 = uilabel(app.particlemaker_pdb);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Particle Number :';
            app.txtcolor = uilabel(app.particlemaker_pdb);
            app.txtcolor.Tag = 'txtcolor';
            app.txtcolor.Text = 'Color :';
            app.edcolor = uieditfield(app.particlemaker_pdb, 'text');
            app.edcolor.ValueChangedFcn = createCallbackFcn(app, @edcolor_Callback, true);
            app.edcolor.Tag = 'edcolor';
            app.edcolor.Value = 'r';
            app.pbloadaset = uibutton(app.particlemaker_pdb, 'push');
            app.pbloadaset.ButtonPushedFcn = createCallbackFcn(app, @pbloadaset_Callback, true);
            app.pbloadaset.Tag = 'pbloadaset';
            app.pbloadaset.Text = 'Load a set of particles';
            app.uibg_isPq = uibuttongroup(app.particlemaker_pdb);
            app.uibg_isPq.Title = 'Is P(q) available?';
            app.uibg_isPq.Tag = 'uibg_isPq';
            app.pb_PickPqname = uibutton(app.uibg_isPq, 'push');
            app.pb_PickPqname.ButtonPushedFcn = createCallbackFcn(app, @pb_PickPqname_Callback, true);
            app.pb_PickPqname.Tag = 'pb_PickPqname';
            app.pb_PickPqname.Text = 'Load';
            app.rb_Pqoption2 = uiradiobutton(app.uibg_isPq);
            app.rb_Pqoption2.Tag = 'rb_Pqoption2';
            app.rb_Pqoption2.Text = 'P(q) for the monodisperse particle';
            app.rb_Pqoption3 = uiradiobutton(app.uibg_isPq);
            app.rb_Pqoption3.Tag = 'rb_Pqoption3';
            app.rb_Pqoption3.Text = 'P(q) for the polydisperse particle';
            app.pb_clearPqfields = uibutton(app.uibg_isPq, 'push');
            app.pb_clearPqfields.ButtonPushedFcn = createCallbackFcn(app, @pb_clearPqfields_Callback, true);
            app.pb_clearPqfields.Tag = 'pb_clearPqfields';
            app.pb_clearPqfields.Text = 'Clear Pq fields';
            app.rb_Pqoption1 = uiradiobutton(app.uibg_isPq);
            app.rb_Pqoption1.Tag = 'rb_Pqoption1';
            app.rb_Pqoption1.Text = 'No P(q)';
            app.rb_Pqoption1.Value = true;
            app.rb_Pqoption4 = uiradiobutton(app.uibg_isPq);
            app.rb_Pqoption4.Tag = 'rb_Pqoption4';
            app.rb_Pqoption4.Text = 'P(q) for PDB';
            app.pushbutton18 = uibutton(app.uibg_isPq, 'push');
            app.pushbutton18.ButtonPushedFcn = createCallbackFcn(app, @pushbutton18_Callback, true);
            app.pushbutton18.Tag = 'pushbutton18';
            app.pushbutton18.Text = 'Calculate';
            app.pushbutton19 = uibutton(app.uibg_isPq, 'push');
            app.pushbutton19.ButtonPushedFcn = createCallbackFcn(app, @pushbutton19_Callback, true);
            app.pushbutton19.Tag = 'pushbutton19';
            app.pushbutton19.Text = 'Save as';
            app.pushbutton20 = uibutton(app.uibg_isPq, 'push');
            app.pushbutton20.ButtonPushedFcn = createCallbackFcn(app, @pushbutton20_Callback, true);
            app.pushbutton20.Tag = 'pushbutton20';
            app.pushbutton20.Text = 'Load';
            app.pushbutton21 = uibutton(app.uibg_isPq, 'push');
            app.pushbutton21.ButtonPushedFcn = createCallbackFcn(app, @pushbutton21_Callback, true);
            app.pushbutton21.Tag = 'pushbutton21';
            app.pushbutton21.Text = 'Calculate';
            app.pushbutton22 = uibutton(app.uibg_isPq, 'push');
            app.pushbutton22.ButtonPushedFcn = createCallbackFcn(app, @pushbutton22_Callback, true);
            app.pushbutton22.Tag = 'pushbutton22';
            app.pushbutton22.Text = 'Save as';
            app.pushbutton23 = uibutton(app.uibg_isPq, 'push');
            app.pushbutton23.ButtonPushedFcn = createCallbackFcn(app, @pushbutton23_Callback, true);
            app.pushbutton23.Tag = 'pushbutton23';
            app.pushbutton23.Text = 'Load';
            app.pushbutton24 = uibutton(app.uibg_isPq, 'push');
            app.pushbutton24.ButtonPushedFcn = createCallbackFcn(app, @pushbutton24_Callback, true);
            app.pushbutton24.Tag = 'pushbutton24';
            app.pushbutton24.Text = 'Calculate';
            app.pushbutton25 = uibutton(app.uibg_isPq, 'push');
            app.pushbutton25.ButtonPushedFcn = createCallbackFcn(app, @pushbutton25_Callback, true);
            app.pushbutton25.Tag = 'pushbutton25';
            app.pushbutton25.Text = 'Save as';
            app.uibg_ParticleOrientation = uibuttongroup(app.particlemaker_pdb);
            app.uibg_ParticleOrientation.SelectionChangedFcn = createCallbackFcn(app, @uibg_ParticleOrientation_SelectionChangedFcn, true);
            app.uibg_ParticleOrientation.Title = 'Orientation of Particle';
            app.uibg_ParticleOrientation.Tag = 'uibg_ParticleOrientation';
            app.txtphi = uilabel(app.uibg_ParticleOrientation);
            app.txtphi.Tag = 'txtphi';
            app.txtphi.Text = 'Rotangle: phi(Z) :';
            app.edphi = uieditfield(app.uibg_ParticleOrientation, 'text');
            app.edphi.ValueChangedFcn = createCallbackFcn(app, @edphi_Callback, true);
            app.edphi.Tag = 'edphi';
            app.edphi.Value = '0';
            app.txttheta = uilabel(app.uibg_ParticleOrientation);
            app.txttheta.Tag = 'txttheta';
            app.txttheta.Tooltip = 'Rotation around Y axis';
            app.txttheta.Text = 'Rotangle2: theta(Y) :';
            app.edtheta = uieditfield(app.uibg_ParticleOrientation, 'text');
            app.edtheta.ValueChangedFcn = createCallbackFcn(app, @edtheta_Callback, true);
            app.edtheta.Tag = 'edtheta';
            app.edtheta.Value = '0';
            app.txtpsi = uilabel(app.uibg_ParticleOrientation);
            app.txtpsi.Tag = 'txtpsi';
            app.txtpsi.Tooltip = 'Rotation matrix around Z axis';
            app.txtpsi.Text = 'Rotangle3: psi(X) :';
            app.edpsi = uieditfield(app.uibg_ParticleOrientation, 'text');
            app.edpsi.ValueChangedFcn = createCallbackFcn(app, @edpsi_Callback, true);
            app.edpsi.Tag = 'edpsi';
            app.edpsi.Value = '0';
            app.rb_orientationtype1 = uiradiobutton(app.uibg_ParticleOrientation);
            app.rb_orientationtype1.Tag = 'rb_orientationtype1';
            app.rb_orientationtype1.Text = 'Euler';
            app.rb_orientationtype1.Value = true;
            app.rb_orientationtype2 = uiradiobutton(app.uibg_ParticleOrientation);
            app.rb_orientationtype2.Tag = 'rb_orientationtype2';
            app.rb_orientationtype2.Text = 'Random';
            app.rb_orientationtype3 = uiradiobutton(app.uibg_ParticleOrientation);
            app.rb_orientationtype3.Tag = 'rb_orientationtype3';
            app.rb_orientationtype3.Text = 'Numeric Random';
            app.pb_Fqcal = uibutton(app.uibg_ParticleOrientation, 'push');
            app.pb_Fqcal.ButtonPushedFcn = createCallbackFcn(app, @pb_Fqcal_Callback, true);
            app.pb_Fqcal.Tag = 'pb_Fqcal';
            app.pb_Fqcal.Text = 'Recalc';
            app.rb_orientationtype4 = uiradiobutton(app.uibg_ParticleOrientation);
            app.rb_orientationtype4.Tag = 'rb_orientationtype4';
            app.rb_orientationtype4.Text = 'Around a vector';
            app.rb_orientationtype5 = uiradiobutton(app.uibg_ParticleOrientation);
            app.rb_orientationtype5.Tag = 'rb_orientationtype5';
            app.rb_orientationtype5.Text = 'XYZ->hkl';
            app.text18 = uilabel(app.particlemaker_pdb);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Deg. of Randon Orientation:';
            app.ed_degofrandomorientation = uieditfield(app.particlemaker_pdb, 'text');
            app.ed_degofrandomorientation.ValueChangedFcn = createCallbackFcn(app, @ed_degofrandomorientation_Callback, true);
            app.ed_degofrandomorientation.Tag = 'ed_degofrandomorientation';
            app.ed_degofrandomorientation.Tooltip = 'Degree of random orientation (1: fully random, 0: perfect orientation)';
            app.ed_degofrandomorientation.Value = '0';
            app.ed_PDBfilename = uieditfield(app.particlemaker_pdb, 'text');
            app.ed_PDBfilename.Tag = 'ed_PDBfilename';
            app.pb_loadPDB = uibutton(app.particlemaker_pdb, 'push');
            app.pb_loadPDB.ButtonPushedFcn = createCallbackFcn(app, @pb_loadPDB_Callback, true);
            app.pb_loadPDB.Tag = 'pb_loadPDB';
            app.pb_loadPDB.Text = 'Load';
            app.text19 = uilabel(app.particlemaker_pdb);
            app.text19.Tag = 'text19';
            app.text19.Text = 'Filename :';
            app.text20 = uilabel(app.particlemaker_pdb);
            app.text20.Tag = 'text20';
            app.text20.Text = 'Additional Position :';
            app.ed_position2 = uieditfield(app.particlemaker_pdb, 'text');
            app.ed_position2.ValueChangedFcn = createCallbackFcn(app, @ed_position2_Callback, true);
            app.ed_position2.Tag = 'ed_position2';
            app.ed_position2.Tooltip = {''};
            app.text21 = uilabel(app.particlemaker_pdb);
            app.text21.Tag = 'text21';
            app.text21.Text = 'Lab-coordinate Position :';
            app.ed_position_LabCoordinate = uieditfield(app.particlemaker_pdb, 'text');
            app.ed_position_LabCoordinate.ValueChangedFcn = createCallbackFcn(app, @ed_position_LabCoordinate_Callback, true);
            app.ed_position_LabCoordinate.Tag = 'ed_position_LabCoordinate';
            app.ed_position_LabCoordinate.Tooltip = {'Positions in Angstrom unit. This field is a space holder.'};
            app.pm_wyckoff = uidropdown(app.particlemaker_pdb);
            app.pm_wyckoff.Items = {'24r:[x,y,z]', '12q:[x,y,1/2]', '12p:[x,y,0]', '12o:[x,2*x,z]', '12n:[x,0,z]', '6m:[x,2*x,1/2]', '6l:[x,2*x,0]', '6k:[x,0,1/2]', '6j:[x,0,0]', '6i:[1/2,0,z]', '4h:[1/3,-1/3,z]', '3g:[1/2,0,1/2]', '3f:[1/2,0,0]', '2e:[0,0,z]', '2d:[1/3,-1/3,1/2]', '2c:[1/3,-1/3,0]', '1b:[0,0,1/2]', '1a:[0,0,0]'};
            app.pm_wyckoff.ValueChangedFcn = createCallbackFcn(app, @pm_wyckoff_Callback, true);
            app.pm_wyckoff.Tag = 'pm_wyckoff';
            app.pm_wyckoff.Value = '24r:[x,y,z]';
            app.pm_rho = uidropdown(app.particlemaker_pdb);
            app.pm_rho.Items = {'Arbitrary', 'Au', 'Ag', 'Pt', 'Empty'};
            app.pm_rho.ValueChangedFcn = createCallbackFcn(app, @pm_rho_Callback, true);
            app.pm_rho.Tag = 'pm_rho';
            app.pm_rho.Value = 'Arbitrary';
            app.pm_color = uidropdown(app.particlemaker_pdb);
            app.pm_color.Items = {'red', 'green', 'blue', 'yellow', 'magenta', 'pink', 'black'};
            app.pm_color.ValueChangedFcn = createCallbackFcn(app, @pm_color_Callback, true);
            app.pm_color.Tag = 'pm_color';
            app.pm_color.Value = 'red';
            app.pm_orientationfactor = uidropdown(app.particlemaker_pdb);
            app.pm_orientationfactor.Items = {'Ordered', 'Random', 'otherwise'};
            app.pm_orientationfactor.ValueChangedFcn = createCallbackFcn(app, @pm_orientationfactor_Callback, true);
            app.pm_orientationfactor.Tag = 'pm_orientationfactor';
            app.pm_orientationfactor.Value = 'Ordered';
            % Show the figure after all components are created
            app.particlemaker_pdb.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = particlemaker_PDB_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.particlemaker_pdb)

                % Execute the startup function
                runStartupFcn(app, @(app)particlemaker_PDB_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.particlemaker_pdb)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.particlemaker_pdb)
        end
    end
end