classdef recilatvector_finder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                       matlab.ui.Figure
        m_File                        matlab.ui.container.Menu
        m_loadcellinfo                matlab.ui.container.Menu
        load_cellinfo_frombase        matlab.ui.container.Menu
        m_save_cellinfo               matlab.ui.container.Menu
        mn_saveintegratedintensities  matlab.ui.container.Menu
        m_edit                        matlab.ui.container.Menu
        m_setDataFigure               matlab.ui.container.Menu
        definecenterofcoordinates     matlab.ui.container.Menu
        exportCM2DataFigure           matlab.ui.container.Menu
        defineCM                      matlab.ui.container.Menu
        mn_setcurrent_expsum          matlab.ui.container.Menu
        mn_askexpsetup                matlab.ui.container.Menu
        mn_swapaxis                   matlab.ui.container.Menu
        mn_swapab                     matlab.ui.container.Menu
        mn_swapac                     matlab.ui.container.Menu
        mn_swapbc                     matlab.ui.container.Menu
        m_draw                        matlab.ui.container.Menu
        m_draw_unitcell               matlab.ui.container.Menu
        m_draw_EWsphere               matlab.ui.container.Menu
        m_clearall                    matlab.ui.container.Menu
        mn_indexing                   matlab.ui.container.Menu
        RefinethegridMenu             matlab.ui.container.Menu
        RevertrefinementMenu          matlab.ui.container.Menu
        mn_plotobserved_reflections   matlab.ui.container.Menu
        m_autoindexing                matlab.ui.container.Menu
        mn_overlay2D                  matlab.ui.container.Menu
        mn_find_spacegroup            matlab.ui.container.Menu
        m_analysis                    matlab.ui.container.Menu
        mn_integrateintensities       matlab.ui.container.Menu
        mn_generatebkg                matlab.ui.container.Menu
        edview_rot3                   matlab.ui.control.EditField
        edview_rot2                   matlab.ui.control.EditField
        edview_rot1                   matlab.ui.control.EditField
        text39                        matlab.ui.control.Label
        grid_q_max                    matlab.ui.control.EditField
        bgview_cross                  matlab.ui.container.ButtonGroup
        ed_tmp                        matlab.ui.control.EditField
        rb_v1xv2                      matlab.ui.control.RadioButton
        rb_v3xv1                      matlab.ui.control.RadioButton
        rb_v2xv3                      matlab.ui.control.RadioButton
        cb_show_cross                 matlab.ui.control.CheckBox
        uibuttongroup3                matlab.ui.container.ButtonGroup
        text37                        matlab.ui.control.Label
        set_hkl2                      matlab.ui.control.Button
        ed_hkl2                       matlab.ui.control.EditField
        ed_hkl1                       matlab.ui.control.EditField
        set_hkl1                      matlab.ui.control.Button
        ed_psi_euler                  matlab.ui.control.EditField
        ed_theta_euler                matlab.ui.control.EditField
        ed_phi_euler                  matlab.ui.control.EditField
        text36                        matlab.ui.control.Label
        text35                        matlab.ui.control.Label
        text34                        matlab.ui.control.Label
        ed_gamma                      matlab.ui.control.EditField
        ed_beta                       matlab.ui.control.EditField
        ed_alpha                      matlab.ui.control.EditField
        ed_c                          matlab.ui.control.EditField
        ed_b                          matlab.ui.control.EditField
        ed_a                          matlab.ui.control.EditField
        text33                        matlab.ui.control.Label
        text32                        matlab.ui.control.Label
        text31                        matlab.ui.control.Label
        text30                        matlab.ui.control.Label
        text29                        matlab.ui.control.Label
        txta                          matlab.ui.control.Label
        uipanel4                      matlab.ui.container.Panel
        pushbutton23                  matlab.ui.control.Button
        pushbutton24                  matlab.ui.control.Button
        pushbutton25                  matlab.ui.control.Button
        uipanel7                      matlab.ui.container.Panel
        pushbutton29                  matlab.ui.control.Button
        pushbutton28                  matlab.ui.control.Button
        pushbutton27                  matlab.ui.control.Button
        ch_isReciprocal               matlab.ui.control.CheckBox
        text13                        matlab.ui.control.Label
        text12                        matlab.ui.control.Label
        slalen                        matlab.ui.control.Slider
        slclen                        matlab.ui.control.Slider
        slblen                        matlab.ui.control.Slider
        cb_selhkls                    matlab.ui.control.CheckBox
        uipanel6                      matlab.ui.container.Panel
        cb_draw_P                     matlab.ui.control.CheckBox
        pm_centering_type             matlab.ui.control.DropDown
        uipanel5                      matlab.ui.container.Panel
        ed_theta                      matlab.ui.control.EditField
        ed_phi                        matlab.ui.control.EditField
        text6                         matlab.ui.control.Label
        text5                         matlab.ui.control.Label
        slider_theta                  matlab.ui.control.Slider
        slider_phi                    matlab.ui.control.Slider
        text4                         matlab.ui.control.Label
        ed_markersize                 matlab.ui.control.EditField
        uipanel3                      matlab.ui.container.Panel
        pbsetCa                       matlab.ui.control.Button
        pbsetCb                       matlab.ui.control.Button
        pbsetCc                       matlab.ui.control.Button
        uipanel2                      matlab.ui.container.Panel
        text14                        matlab.ui.control.Label
        text11                        matlab.ui.control.Label
        lb_color                      matlab.ui.control.ListBox
        pbsetc                        matlab.ui.control.Button
        pbsetb                        matlab.ui.control.Button
        pbseta                        matlab.ui.control.Button
        slview1                       matlab.ui.control.Slider
        pbobservedhkl                 matlab.ui.control.Button
        pbdrawlines                   matlab.ui.control.Button
        bgshiftcenter                 matlab.ui.container.ButtonGroup
        pbplus                        matlab.ui.control.Button
        pbminus                       matlab.ui.control.Button
        edshiftcenterval              matlab.ui.control.EditField
        rbshiftcenter1                matlab.ui.control.RadioButton
        rbshiftcenter2                matlab.ui.control.RadioButton
        rbshiftcenter3                matlab.ui.control.RadioButton
        bgview                        matlab.ui.container.ButtonGroup
        cb_show                       matlab.ui.control.CheckBox
        text15                        matlab.ui.control.Label
        edsetview                     matlab.ui.control.EditField
        rbview1                       matlab.ui.control.RadioButton
        rbview2                       matlab.ui.control.RadioButton
        rbview3                       matlab.ui.control.RadioButton
        pblclearlines                 matlab.ui.control.Button
        pbgrid                        matlab.ui.control.Button
        pbprint                       matlab.ui.control.Button
        slview3                       matlab.ui.control.Slider
        slview2                       matlab.ui.control.Slider
        edview3                       matlab.ui.control.EditField
        edview2                       matlab.ui.control.EditField
        edview1                       matlab.ui.control.EditField
        pbcleargrid                   matlab.ui.control.Button
        pbloadcellinfo                matlab.ui.control.Button
    end

    
    methods (Access = private)
        function calculate_q_pixel(app, varargin)
            fh = getappdata(gcbf, 'dataFigure');
            h = findobj(fh, 'tag', 'grid_hkl');
            if isempty(h)
                return
            end
            if numel(h) == 2
                for i=1:numel(h)
                    if ~isnan(h(i).MarkerFaceColor)
                        h = h(i);
                        break
                    end
                end
            end
            xd = h.XData(:);
            yd = h.YData(:);
            zd = h.ZData(:);
            qv = [xd, yd, zd];
            %experimental setup
            saxs = getappdata(gcbf, 'saxsinfo');
            wl = saxs.waveln;
            psize = saxs.psize;
            sdd = saxs.SDD;
            detangle = saxs.tiltangle;
            phirng(1) = saxs.phirange(1);
            phirng(2) = saxs.phirange(2);
            center = saxs.center;
            % calculating...
            [pixN, phi] = qv2pixel(qv, wl, sdd, psize, detangle);
            % plotting....
            figh = getappdata(gcbf, 'expsumimgFigure');
            ax = findobj(figh, 'type', 'axes');
            xl = get(ax, 'XLim');
            yl = get(ax, 'YLim');
            delete(findobj(figh, 'tag', 'simulated_hkls'));
            k = (phi>=phirng(1)) & (phi<=phirng(2)) & pixN(:,3) >= center(2);
            k = k & pixN(:,2)+center(1)>=xl(1) & pixN(:,2)+center(1)<=xl(2);
            k = k & pixN(:,3)+center(2)>=yl(1) & pixN(:,3)+center(2)<=yl(2);
            h = plot(ax, pixN(k,2)+center(1), pixN(k,3)+center(2), 'ro');
            k2 = ((phi<=(-180-phirng(1))) | (phi>=(180-phirng(2)))) & pixN(:,3) >= 0;
            k2 = k2 & pixN(:,2)+center(1)>=xl(1) & pixN(:,2)+center(1)<=xl(2);
            k2 = k2 & pixN(:,3)+center(2)>=yl(1) & pixN(:,3)+center(2)<=yl(2);
            h2 = plot(ax, center(1)-pixN(k2,2), pixN(k2,3)+center(2), 'rs');
            set(h, 'tag', 'simulated_hkls');
            set(h2, 'tag', 'simulated_hkls');
        end
        
        function construct_cellinfo(app)
            
                if app.ch_isReciprocal.Value
                    as = getappdata(gcbf, 'as');
                    bs = getappdata(gcbf, 'bs');
                    cs = getappdata(gcbf, 'cs');
                    ct = celcon4rcplatvec(as, bs, cs);
                else
                    a = getappdata(gcbf, 'a');
                    b = getappdata(gcbf, 'b');
                    c = getappdata(gcbf, 'c');
                    ct = celcon4latvec(a, b, c);
                end
                setappdata(gcbf, 'cellinfo', ct)
                update_from_cellinfo(app)
        end
        
        function draw_latvec(app, n, event)
            switch n
                case {1, 'as', 'a'}
                    name = 'a';
                    c = 'r';
                    linetag = 'linea';
                case {2, 'bs', 'b'}
                    name = 'b';
                    c = 'g';
                    linetag = 'lineb';
                case {3, 'cs', 'c'}
                    name = 'c';
                    c = 'b';
                    linetag = 'linec';
            end
            if app.ch_isReciprocal.Value
                name = [name, 's'];
            end
            v = getappdata(app.figure1, name);
            drawline(app, v, linetag, c);
            update_grid(app)
        end
        
        function h = drawline(app, v, linetag, col)
            fh = getappdata(gcbf, 'dataFigure');
            ax = findobj(fh, 'type', 'axes');
            xl = ax.XLim;
            yl = ax.YLim;
            zl = ax.ZLim;
            ud = get(gcbf, 'userdata');
            if isfield(ud, 'cm')
                cm = ud.cm;
            else
                cm = [0, 0, 0];
            end
            if app.ch_isReciprocal.Value
                cm = [0,0,0];
            else
                % if cm is out of view
                if cm(1)<xl(1) | cm(1)>xl(2) | cm(2)<yl(1) | cm(2)>yl(2) | cm(3)<zl(1) | cm(3)>zl(2)
                    cm(1) = mean(xl);
                    cm(2) = mean(yl);
                    cm(3) = mean(zl);
                    ud.cm = cm;
                    set(gcbf, 'userdata', ud);
                end
            end
            if isempty(fh)
                error('dataFigure has not been selected yet.')
            end
            %ax = findobj(fh, 'type', 'axes');
            delete(findobj(ax, 'tag', linetag));
            h = line(ax, [0, v(1)]+cm(1), [0, v(2)]+cm(2), [0, v(3)]+cm(3));
            set(h, 'color', col, 'linewidth',2);
            set(h, 'tag', linetag);
        end
        
        function edit12_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit12 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit13_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit13 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit14_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit14 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit15_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit15 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit16_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit16 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edsetview_cross_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edsetview_cross (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function indexfound = find_allow_reflections(app, varargin)
            handles = varargin{1};
            ff = evalin('base', 'QmapFamily');
            if isempty(ff)
                warndlg('On the menu of draw_3dmap, run Tools>Split Isosurfaces.', 'Warning', 'modal');
                return
            end
            
            fcm = zeros(numel(ff), 3);
            for i=1:numel(ff)
                fcm(i, :) = ff{i}.cm;
            end
            
            fh = getappdata(gcbf, 'dataFigure');
            if handles.ch_isReciprocal.Value
                v1 = getappdata(gcbf, 'as');
                v2 = getappdata(gcbf, 'bs');
                v3 = getappdata(gcbf, 'cs');
            else
                v1 = getappdata(gcbf, 'a');
                v2 = getappdata(gcbf, 'b');
                v3 = getappdata(gcbf, 'c');
            end
            
            latticevectors = [v1;v2;v3];
            al = norm(v1);
            bl = norm(v2);
            cl = norm(v3);
            rmin = min([al,bl,cl])*0.1;
            hkl = get(findobj(fh, 'tag', 'grid_hkl'), 'userdata');
            qvs = hkl*latticevectors;
            indexfound = [];
            for i = 1:size(fcm,1)
                fv = fcm(i,:);
                ds = sqrt(sum((qvs - fv).^2,2));
                [dsmin, dsindx] = min(ds);
                if dsmin < rmin
                    hkl(dsindx, :)
                    indexfound = [indexfound;hkl(dsindx,:)];
                end
            end
            assignin('base', 'indexfound', indexfound)
        end
        
        function err = fitf(app, x, qv, hkls)
            % x is lattice vectors. [av; bv; cv];
            qvc = hkls*x;
            err = sum(sqrt(sum((qv-qvc).^2, 2)))/length(qvc);
        end
        
        function cv = get_crossvector(app)
            v = get_view(app);
            val = get(app.lb_color,'Value');
            switch val
                case 1
                    name = 'a';
                case 2
                    name = 'b';
                case 3
                    name = 'c';
            end
            if app.ch_isReciprocal.Value
                name = [name, 's'];
            end
            vt = getappdata(gcbf, name);
            cv = cross(v, vt);
        end
        
        function v = get_vector_for_twopnts(app, varargin)
            fh = getappdata(gcbf, 'dataFigure');
            figure(fh);
            ginput(1)
            clickedpt1 = get(gca, 'CurrentPoint');
            ginput(1)
            clickedpt2 = get(gca, 'CurrentPoint');
            %v1 = [clickedpt1(1, :),1]';
            %v2 = VMtx*[clickedpt2(1, :),1]';
            v1 = clickedpt1(1, :);
            v2 = clickedpt2(1, :);
            v = v2-v1;
            vl = sqrt(sum(v.^2));
            % make v normal to the vn
            vn = get_view(app);
            v = cross(cross(vn, v), vn);
            v = v/norm(v)*vl;
        end
        
        function [as, Rmat] = get_view(app, varargin)
            fh = getappdata(gcbf, 'dataFigure');
            if isempty(fh)
                error('Choose a figure with a map of interest by pressing "Set Data Figure".')
            end
            [as, Rmat] = view2vect(fh);
            
            % Decide the length of as based on the box size..
            ax = findobj(fh, 'type', 'axes');
            zl = get(ax, 'zlim');
            yl = get(ax, 'ylim');
            xl = get(ax, 'xlim');
            maxl = max(sqrt(xl.^2+yl.^2+zl.^2));
            as = as*maxl;
            
            % Get the rotation matrix.
            Rmat = Rmat(1:3, 1:3);
        end
        
        function st = getcursor(app, varargin)
                dcm = datacursormode(gcf);
                s = getCursorInfo(dcm);
                if numel(s) > 1
                    error('Choose one point')
                end
                st = s.Position;
        end
        
        function init_spacegroup(app, varargin)
            % UIWAIT makes recilatvector_finder wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
            
            [sg, sgN, syst] = spacegrouplist;
            sg = ['P', sg];
            sgN = [0, sgN];
            syst = ['none', syst];
            nsg = {};
            %sg_all = load('sg_all.mat');
            %set(gcf, 'userdata', sg_all');
            for i=1:numel(sg)
                nsg{i} = sprintf('%i, %s: %s', sgN(i), syst{i}, sg{i});
            end
            hobj = varargin{1};
            handles = varargin{2};
            %set(hobj, 'userdata', sg_all);
            handles.pm_centering_type.String = nsg;
            set(handles.pm_centering_type, 'Value', 1);
        end
        
        function load_cellinfo(app, cellinfo)
            %assignin('base', 'cellinfo', cellinfo)
            
                ev1 = findobj(app.figure1, 'tag', 'edview1');
                ev2 = findobj(app.figure1, 'tag', 'edview2');
                ev3 = findobj(app.figure1, 'tag', 'edview3');
                if app.ch_isReciprocal.Value
                    as = cellinfo.recilatticevectors(1, :);
                    bs = cellinfo.recilatticevectors(2, :);
                    cs = cellinfo.recilatticevectors(3, :);
                    aslen = norm(as);
                    bslen = norm(bs);
                    cslen = norm(cs);
                else
                    a = cellinfo.latticevectors(1, :);
                    b = cellinfo.latticevectors(2, :);
                    c = cellinfo.latticevectors(3, :);
                    aslen = norm(a);
                    bslen = norm(b);
                    cslen = norm(c);
                end
            
                if app.ch_isReciprocal.Value
                    setappdata(app.figure1, 'as', as);
                    setappdata(app.figure1, 'bs', bs);
                    setappdata(app.figure1, 'cs', cs);
                else
                    setappdata(app.figure1, 'a', a);
                    setappdata(app.figure1, 'b', b);
                    setappdata(app.figure1, 'c', c);
                end
                if ~isfield(cellinfo, 'U')
                        % calculate orientation matrix
                    cp = [cellinfo.A,cellinfo.B,cellinfo.C,cellinfo.alpha,cellinfo.beta,cellinfo.gamma];
                    t = celcon(cp);Bmat=t.recimat;
                    UB = cellinfo.recimat;
                    U = UB*inv(Bmat);
                    cellinfo.U = U;
                end
            
            
                set(ev1, 'Value', sprintf('%0.5f', aslen));
                set(ev2, 'Value', sprintf('%0.5f', bslen));
                set(ev3, 'Value', sprintf('%0.5f', cslen));
            
                setappdata(app.figure1, 'cellinfo', cellinfo);
        end
   
        function m_autoindexing_Callback_old(app, hObject, eventdata, handles)
            ff = evalin('base', 'QmapFamily');
            if isempty(ff)
                warndlg('On the menu of draw_3dmap, run Tools>Split Isosurfaces.', 'Warning', 'modal');
                return
            end
            
            fh = getappdata(gcbf, 'dataFigure');
            ax = findobj(fh, 'type', 'axes');
            ff = findobj(ax, 'type', 'patch');
            fcm = zeros(numel(ff), 3);
            for i=1:numel(ff)
                fcm(i, :) = mean(ff(i).Vertices);
            end
            
            pmcobj = findobj(gcbf, 'tag', 'pm_centering_type');
            str = get(pmcobj, 'String');
            strv = get(pmcobj, 'ValueIndex');
            hmax = 2;
            kmax = 2;
            lmax = 2;
            
            % hl = linspace(-hmax, hmax, hmax*2+1);
            % kl = linspace(-kmax, kmax, kmax*2+1);
            % ll = linspace(-lmax, lmax, lmax*2+1);
            % [X, Y, Z] = meshgrid(hl, kl, ll);
            % hkls = [X(:), Y(:), Z(:)];
            N = strtok(str{strv}, ',');
            %sg = strtrim(sg(2:end));
            N = str2double(N);
            switch N
                case 0
                    hkls_cen = [];
            %     case 'I'
            %         hkls_cen = [1, 1, 0; 1 -1, 0; 1, 0, 1];
            %     case 'F'
            %         hkls_cen = [1, 1, 1; 1, -1, -1; 1, -1, 1];
                otherwise
                    ud = get(gcbf, 'userdata');
                    hkls_cen = hkl_listall(sg_gen(N), [hmax, kmax, lmax]);
            end
            if isempty(hkls_cen)
                refa = [1, 0, 0];
                refb = [0, 1, 0];
                refc = [0, 0, 1];
            else
                refa = hkls_cen(1, :);
                k = 2;
                refb = hkls_cen(k, :);
            %     while dot(refb, refa) ~= 0
            %         k = k + 1;
            %         refb = hkls_cen(k, :);
            %     end
                refc = hkls_cen(k+1, :);
            %     while (dot(refc, refa)~=0) | (dot(refc, refa)~=0)
            %         k = k + 1;
            %         refc = hkls_cen(k, :);
            %     end
                m = [refa(:), refb(:), refc(:)];
                if det(m) == 0
                    fprintf('Try different reference hkls so that DET(m) is not 0.\n')
                end
            end
            
            av = [1, 0, 0];
            bv = [0, 1, 0];
            cv = [0, 0, 1];
            ang_range = [inf, inf, inf];
            
            
            dis = sqrt(sum(fcm.^2, 2));
            
            % determine a
                [dis, ind] = sort(dis, 'ascend');
                fcm = fcm(ind, :);
                % finding positive direction
                i = 1;
                a1 = fcm(i, :);
            
                phaseangle = angle2vect(av, fcm(1, :))*180/pi;
                while (phaseangle>180) || (abs(angle2vect2(av, a1)*180/pi) > ang_range(1))
                    i = i+1;
                    a1 = fcm(i, :);
                    phaseangle = angle2vect(av, a1)*180/pi;
                end
                fcm(i, :) = [];
                dis(i) = [];
            % removing all fcm along (0 degree) or against (180 degree) the a1 vector.
                ang = angle2vect(a1, fcm);
                ind = (abs(rem(ang*180/pi, 360)) < 10) | (abs(rem(ang*180/pi, 360)) >170);
                dis(ind) = [];
                fcm(ind, :) = [];
            
            % finding b1
            
                % finding positive direction
                i = 1;
                b1 = fcm(i, :);
                [~, ind] = max(abs(b1));
                while (b1(ind) < 0) || (abs(angle2vect2(bv, b1)*180/pi) > ang_range(2))
                    i = i+1;
                    b1 = fcm(i, :);
                    [~, ind] = max(abs(b1));
                end
                fcm(i, :) = [];
                dis(i) = [];
            
            
            % finding c1.
            % removing all fcm along (0 degree) or against (180 degree) the a1 vector.
                nv = -cross(a1, b1);
                ang = angle2vect(nv, fcm);
            %    dis3 = dis(ind);
                % b1 should not be the second order of a1
                ind = (abs(rem(ang*180/pi, 360)) > 85) & (abs(rem(ang*180/pi, 360)) < 95);
                dis(ind) = [];
                fcm(ind,:) = [];
            %     [~, ind] = min(dis);
            %     c1 = fcm(ind, :);
            
                % finding positive direction
                i = 1;
                c1 = fcm(i, :);
                [~, ind] = max(abs(c1));
                while (c1(ind) < 0) || (abs(angle2vect2(cv, c1)*180/pi) > ang_range(3))
                    i = i+1;
                    c1 = fcm(i, :);
                    [~, ind] = max(abs(c1));
                end
            % Note that:
            % [a1(:), b1(:), c1(:)] = latticevectormatrix' * [refa(:), refb(:), refc(:)]
            % Therefore,
            % latticevectormat' = [a1(:),b1(:),c1(:)]*inv([refa(:), refb(:), refc(:)])
            % latticevectormat = ([a1(:),b1(:),c1(:)]*inv([refa(:), refb(:),
            % refc(:)]))'
            
            Lmat = [a1(:),b1(:),c1(:)]/[refa(:), refb(:), refc(:)];
            a1 = Lmat(:, 1)';
            b1 = Lmat(:, 2)';
            c1 = Lmat(:, 3)';
                set(handles.edview1, 'Value', norm(a1));
                setappdata(gcbf, 'as', a1);
                c = 'r';
                linetag = 'linea';
                drawline(app, a1, linetag, c);
            
                set(handles.edview2, 'Value', norm(b1));
                setappdata(gcbf, 'bs', b1);
                c = 'g';
                linetag = 'lineb';
                drawline(app, b1, linetag, c);
            
                set(handles.edview3, 'Value', norm(c1));
                setappdata(gcbf, 'cs', c1);
                c = 'b';
                linetag = 'linec';
                drawline(app, c1, linetag, c);
            % construct a unit cell.
                pbprint_Callback(app);
        end
        
        function pbsetdatafigure_Callback(app, varargin)
            % --- Executes on button press in pbsetdatafigure.
            
            % hObject    handle to pbsetdatafigure (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if ishandle(varargin{2})
                setappdata(varargin{1}, 'dataFigure', varargin{2});
            else
                setappdata(gcbf, 'dataFigure', gcf);
            end
            handles = varargin{3};
            set(handles.uipanel5, 'visible', 'off');
            if handles.ch_isReciprocal.Value
                xlabel('q_x')
                ylabel('q_y')
                zlabel('q_z')
            else
                xlabel('X')
                ylabel('Y')
                zlabel('Z')
            end
            hold on
        end
        
        function pbsetexpsumfigure_Callback(app, varargin)
            % hObject    handle to pbsetdatafigure (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if ishandle(varargin{2})
                setappdata(varargin{1}, 'expsumimgFigure', varargin{2});
            else
                setappdata(gcbf, 'expsumimgFigure', gcf);
            end
            %handles = varargin{3};
            %set(handles.uipanel5, 'visible', 'off');
            xlabel('X')
            ylabel('Y')
            hold on
            axis image;axis xy;
        end
        
        function pbsetview_Callback(app, hObject)
            % --- Executes on button press in pbsetview.
            
            % hObject    handle to pbsetview (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if isobject(hObject)
                as = get_view(app);
            else
                as = hObject;
            end
            switch lower(app.bgview.SelectedObject.Text)
                case 'view2'
                    set(app.edview2, 'Value', num2str(norm(as)));
                    setappdata(gcbf, 'bs', as);
            %         setappdata(gcbf, 'bRmat', Rmat);
                    c = 'g';
                    linetag = 'lineb';
                case 'view1'
                    set(app.edview1, 'Value', num2str(norm(as)));
                    setappdata(gcbf, 'as', as);
            %         setappdata(gcbf, 'aRmat', Rmat);
                    c = 'r';
                    linetag = 'linea';
                case 'view3'
                    set(app.edview3, 'Value', num2str(norm(as)));
                    setappdata(gcbf, 'cs', as);
            %         setappdata(gcbf, 'cRmat', Rmat);
                    c = 'b';
                    linetag = 'linec';
            end
            drawline(app, as, linetag, c);
        end
        
        function set_length(app, v, vhandle)
                udf = get(gcbf, 'userdata');
                if isfield(udf, 'cm')
                    cm = udf.cm;
                else
                    cm = [0,0,0];
                end
                if contains(vhandle.Tag, 's')
                    cm = [0,0,0];
                end
                % set(vhandle, 'xdata', [0, v(1)]+cm(1));
                % set(vhandle, 'ydata', [0, v(2)]+cm(2));
                % set(vhandle, 'zdata', [0, v(3)]+cm(3));
                set(vhandle, 'xdata', [0, v(1)]);
                set(vhandle, 'ydata', [0, v(2)]);
                set(vhandle, 'zdata', [0, v(3)]);
        end
        
        function set_recivector(app, hObject)
                val = str2double(get(hObject,'Value'));
                switch lower(hObject.Tag)
                    case 'edview1'
                        vname = 'a';
                    case 'edview2'
                        vname = 'b';
                    case 'edview3'
                        vname = 'c';
                end
                if app.ch_isReciprocal.Value
                    vname = [vname, 's'];
                end
                v = getappdata(gcbf, vname);
                v = v/norm(v)*val;
                setappdata(gcbf, vname, v);
                fh = getappdata(gcbf, 'dataFigure');
                vh = findobj(fh, 'tag', ['line', vname(1)]);
                if ~isempty(vh)
                    set_length(app, v, vh);
                end
                construct_cellinfo(app)
                %update_from_cellinfo(handles)
                update_grid(app)
        end
        
        function shiftcenter(app, handles, sign, ds)
            switch lower(app.bgshiftcenter.SelectedObject.Text)
                case 'axis 1'
                    vname = 'a';
                case 'axis 2'
                    vname = 'b';
                case 'axis 3'
                    vname = 'c';
            end
            if app.ch_isReciprocal.Value
                vname = [vname, 's'];
            end
            v = getappdata(gcbf, vname);
            fh = getappdata(gcbf, 'dataFigure');
            %ax = findobj(fh, 'type', 'axes');
            %lh = findobj(ax, 'tag', 'grid_hkl');
            %lh = findobj(ax, 'type', 'line');
            ud = get(gcbf, 'userdata');
            if sign < 0
                ds = ds*(-1);
            end
            if ~isempty(ud)
                if isfield(ud, 'cm')
                    cm = ud.cm;
                else
                    cm = [0,0,0];
                end
                cm = cm+v*ds;
                ud.cm = cm;
                fprintf('cm is at [%0.3f, %0.3f, %0.3f]\n', cm)
                set(gcbf, 'userdata', ud)            
                
                if app.ch_isReciprocal.Value
                    obj = findobj(fh, 'type', 'patch');
                    obj.Vertices = obj.Vertices - v*ds;
                    %obj.XData = obj.XData - v(1)*ds;
                    %obj.YData = obj.YData - v(2)*ds;
                    %obj.ZData = obj.ZData - v(3)*ds;
                else
                    pbdrawlines_Callback(app);
                end
            end
        end
        
        function slider14_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to slider14 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
        function slider15_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to slider15 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
        function slider16_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to slider16 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
        function slider17_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to slider17 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
        function slider_callback(app, event)
            %handles = varargin{1};
            sv = get(gcbo, 'value');
            switch get(gcbo, 'Tag')
                case 'slview1'
                    val_obj = 'edview_rot1';
                    name = 'a';
                case 'slview2'
                    val_obj = 'edview_rot2';
                    name = 'b';
                case 'slview3'
                    val_obj = 'edview_rot3';
                    name = 'c';
            end
            val = str2double(app.(val_obj).Value);
            sv = sv - 0.5;
            if val~=0 % if value in the box 0, sv will be the step
                sv = sign(sv)*val;
            end

            if app.ch_isReciprocal.Value
                name = [name, 's'];
            end
            v = getappdata(gcbf, name);
            vp = get_view(app);
            v = rotate_around_vector(v, vp, sv*90);
            setappdata(gcbf, name, v);
            
            construct_cellinfo(app)
            
            draw_latvec(app, name, event);
            if contains(get(gcbo, 'Tag'), 'slview')
                set(gcbo, 'value', 0.5);
            end
        end
        
        function slider_len_callback(app)
            sv = get(gcbo, 'value');
            sv = sv - 0.5;
            switch get(gcbo, 'Tag')
                case 'slalen'
                    %name = 'as';
                    n = str2double(app.edview1.Value);
                    n = n*(1+sv);
                    set(app.edview1, 'Value', num2str(n));
                    edview1_Callback(app)
                case 'slblen'
                    %name = 'bs';
                    n = str2double(app.edview2.Value);
                    n = n*(1+sv);
                    set(app.edview2, 'Value', num2str(n));
                    edview2_Callback(app)
                case 'slclen'
                    %name = 'cs';
                    n = str2double(app.edview3.Value);
                    n = n*(1+sv);
                    set(app.edview3, 'Value', num2str(n));
                    edview3_Callback(app)
            end
            
            set(gcbo, 'value', 0.5);
        end
        
        function slider_rotate(app, phi, theta)
            figh = getappdata(gcbf, 'dataFigure');
            cc = findobj(figh, 'tag', 'Ewaldsphere_on_RST');
            if isempty(cc)
                fprintf('This function is to rotate Ewaldsphere. Draw the sphere to proceed.\n')
                return
            end
            dt = get(cc, 'userdata');
            xd = dt{1};
            yd = dt{2};
            zd = dt{3};
            R = eulerAnglesToRotation3d(phi, theta, 0);
            R  = R(1:3, 1:3);
            dt = [xd(:), yd(:), zd(:)]*R';
            set(cc, 'xdata', reshape(dt(:,1), size(xd)));
            set(cc, 'ydata', reshape(dt(:,2), size(xd)));
            set(cc, 'zdata', reshape(dt(:,3), size(xd)));
        end
        
        function update_from_cellinfo(app)
                cellinfo = getappdata(app.figure1, 'cellinfo');
                set(app.ed_a, 'value', num2str(cellinfo.A))
                set(app.ed_b, 'value', num2str(cellinfo.B))
                set(app.ed_c, 'value', num2str(cellinfo.C))
                set(app.ed_alpha, 'value', num2str(cellinfo.alpha))
                set(app.ed_beta, 'value', num2str(cellinfo.beta))
                set(app.ed_gamma, 'value', num2str(cellinfo.gamma))
                if isfield(cellinfo, 'U')
                    [PHI, THETA, PSI] = rotation3dToEulerAngles(cellinfo.U);
                else
                    cellinfo.U = eye(3);
                    PHI = 0; THETA = 0; PSI = 0;
                    setappdata(app.figure1, 'cellinfo', cellinfo);
                end
                set(app.ed_phi_euler, 'value', num2str(PHI))
                set(app.ed_theta_euler, 'value', num2str(THETA))
                set(app.ed_psi_euler, 'value', num2str(-PSI))
                setappdata(app.figure1, 'as', cellinfo.recilatticevectors(1, :));
                setappdata(app.figure1, 'bs', cellinfo.recilatticevectors(2, :));
                setappdata(app.figure1, 'cs', cellinfo.recilatticevectors(3, :));
                setappdata(app.figure1, 'a', cellinfo.latticevectors(1, :));
                setappdata(app.figure1, 'b', cellinfo.latticevectors(2, :));
                setappdata(app.figure1, 'c', cellinfo.latticevectors(3, :));
            
                if app.ch_isReciprocal.Value
                    set(app.edview1, 'value', sprintf('%0.5f', norm(cellinfo.recilatticevectors(1, :))));
                    set(app.edview2, 'value', sprintf('%0.5f', norm(cellinfo.recilatticevectors(2, :))));
                    set(app.edview3, 'value', sprintf('%0.5f', norm(cellinfo.recilatticevectors(3, :))));
                else
                    set(app.edview1, 'value', sprintf('%0.5f', norm(cellinfo.latticevectors(1, :))));
                    set(app.edview2, 'value', sprintf('%0.5f', norm(cellinfo.latticevectors(2, :))));
                    set(app.edview3, 'value', sprintf('%0.5f', norm(cellinfo.latticevectors(3, :))));
                end
        end
        
        function update_grid(app)
                %handles = varargin{1};
                fh = getappdata(gcbf, 'dataFigure');
                h = findobj(fh, 'tag', 'grid_hkl');
                if ~isempty(h)
                    delete(h);
                    pbgrid_Callback(app);
                end
        end
        
        function cellinfo = update_to_cellinfo(app, handles)
                A = str2double(handles.ed_a.String);
                B = str2double(handles.ed_b.String);
                C = str2double(handles.ed_c.String);
                alpha = str2double(handles.ed_alpha.String);
                beta = str2double(handles.ed_beta.String);
                gamma = str2double(handles.ed_gamma.String);
                phi = str2double(handles.ed_phi_euler.String);
                theta = str2double(handles.ed_theta_euler.String);
                psi = str2double(handles.ed_psi_euler.String);
                U = eulerAnglesToRotation3d(phi, theta, psi);
                cellinfo = celcon([A, B, C, alpha, beta, gamma], U(1:3, 1:3));
                setappdata(handles.figure1, 'cellinfo', cellinfo);
                setappdata(handles.figure1, 'as', cellinfo.recilatticevectors(1, :));
                setappdata(handles.figure1, 'bs', cellinfo.recilatticevectors(2, :));
                setappdata(handles.figure1, 'cs', cellinfo.recilatticevectors(3, :));
                setappdata(handles.figure1, 'a', cellinfo.latticevectors(1, :));
                setappdata(handles.figure1, 'b', cellinfo.latticevectors(2, :));
                setappdata(handles.figure1, 'c', cellinfo.latticevectors(3, :));
            %     set(handles.edview1, 'Value', sprintf('%0.5f', norm(cellinfo.recilatticevectors(1, :))));
            %     set(handles.edview2, 'Value', sprintf('%0.5f', norm(cellinfo.recilatticevectors(2, :))));
            %     set(handles.edview3, 'Value', sprintf('%0.5f', norm(cellinfo.recilatticevectors(3, :))));
                if handles.ch_isReciprocal.Value
                    set(handles.edview1, 'String', sprintf('%0.5f', norm(cellinfo.recilatticevectors(1, :))));
                    set(handles.edview2, 'String', sprintf('%0.5f', norm(cellinfo.recilatticevectors(2, :))));
                    set(handles.edview3, 'String', sprintf('%0.5f', norm(cellinfo.recilatticevectors(3, :))));
                else
                    set(handles.edview1, 'String', sprintf('%0.5f', norm(cellinfo.latticevectors(1, :))));
                    set(handles.edview2, 'String', sprintf('%0.5f', norm(cellinfo.latticevectors(2, :))));
                    set(handles.edview3, 'String', sprintf('%0.5f', norm(cellinfo.latticevectors(3, :))));
                end
                pbdrawlines_Callback(app, [])
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function recilatvector_finder_OpeningFcn(app, varargin)
            % --- Executes just before recilatvector_finder is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to recilatvector_finder (see VARARGIN)
            
            % Choose default command line output for recilatvector_finder
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            if numel(varargin)>1
                if strcmp(get(varargin{1}, 'type'), 'uimenu')
                    if strcmp(varargin{1}.Text, 'Reciprocal Lattice Vector Tool')
                        pbsetdatafigure_Callback(app, hObject, gcbf, handles)
                        ax = findobj(gcbf, 'type', 'axes');
                        set(ax, 'Ydir', 'normal');
                    end
                end
            end
            init_spacegroup(app, hObject, handles)
        end

        % Selection changed function: bgview
        function bgview_SelectionChangedFcn(app, event)
            % --- Executes when selected object is changed in bgview.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to the selected object in bgview
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            fh = getappdata(gcbf, 'dataFigure');
            if isempty(fh)
                error('Select DataFigure: Edit>Set Current Figure as DataFigure.')
            end
            switch lower(handles.bgview.SelectedObject.String)
                case 'view2'
                    name = 'b';
                case 'view1'
                    name = 'a';
                case 'view3'
                    name = 'c';
            end
            
            if handles.ch_isReciprocal.Value
                name = [name, 's'];
            end
            
            v = getappdata(gcbf, name);
            
            if ~isempty(v)
                figure(fh)
                view(v)
            end
        end

        % Selection changed function: bgview_cross
        function bgview_cross_SelectionChangedFcn(app, event)
            % --- Executes when selected object is changed in bgview.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to the selected object in bgview
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            fh = getappdata(gcbf, 'dataFigure');
            switch lower(handles.bgview_cross.SelectedObject.String)
                case 'v2 x v3'
                    name = 'a';
                case 'v3 x v1'
                    name = 'b';
                case 'v1 x v2'
                    name = 'c';
            end
            
            if ~handles.ch_isReciprocal.Value
                name = [name, 's'];
            end
            
            v = getappdata(gcbf, name);
            
            if ~isempty(v)
                figure(fh)
                view(v)
            end
        end

        % Value changed function: cb_draw_P
        function cb_draw_P_Callback(app, event)
            % --- Executes on button press in cb_draw_P.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cb_draw_P (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of cb_draw_P
            figh = getappdata(gcbf, 'dataFigure');
            cc = findobj(figh, 'tag', 'grid_hkl_P');
            if isempty(cc)
                fprintf('Primitive grid was not plotted.\n')
                return
            end
            
            if get(hObject,'Value')
                set(cc, 'visible', 'on');
            else
                set(cc, 'visible', 'off');
            end
        end

        % Value changed function: cb_selhkls
        function cb_selhkls_Callback(app, event)
            % --- Executes on button press in cb_selhkls.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cb_selhkls (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of cb_selhkls
            ff = evalin('base', 'QmapFamily');
            if isempty(ff)
                fprintf('No splitted patches yet.\n')
                return
            end
            
            fh = getappdata(gcbf, 'dataFigure');
            ax = findobj(fh, 'type', 'axes');
            ff = findobj(ax, 'type', 'patch');
            %ud = get(gcbf, 'userdata');
            if ~get(hObject,'Value')
                xl = (get(ax, 'XLim'));
                m = findobj(ax, 'tag', 'grid_hkl');
                set(ax, 'Units', 'Points')
                axpos = get(ax, 'Position');
                scale=diff(xl)/axpos(3)*m.MarkerSize;
                xd = m.XData;
                yd = m.YData;
                zd = m.ZData;
                pos = [xd(:), yd(:), zd(:)];
                for i=1:numel(ff)
                    cm = mean(ff(i).Vertices);
                    d = sqrt(sum((pos - cm).^2, 2));
                    if any(d<scale)
                        set(ff(i), 'visible', 'off')
                    end
                end
            else
                set(ff, 'visible', 'on');
            end
        end

        % Value changed function: cb_show
        function cb_show_Callback(app, event)
            % --- Executes on button press in cb_show.
            
            % Hint: get(hObject,'Value') returns toggle state of cb_show
            if app.cb_show.Value
                ct = getappdata(gcbf, 'cellinfo');
                v = eval(app.edsetview.Value);
                v = v*ct.recilatticevectors;
                %setappdata(gcbf, 'as', as);
                c = 'r';
                linetag = 'line_hkl';
                h = drawline(app, v, linetag, c);
                set(h, 'linewidth', 2)
                set(h, 'linestyle', ':')
            else
                delete(findobj(0, 'tag', 'line_hkl'))
            end
        end

        % Value changed function: cb_show_cross
        function cb_show_cross_Callback(app, event)
            % --- Executes on button press in cb_show_cross.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cb_show_cross (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of cb_show_cross
            %h = findobj(0, 'tag', 'VolIqPlot');
            %han = guihandles(h);
            %fh = getappdata(gcbf, 'dataFigure');
            if ~get(hObject, 'value')
                h = findobj(0, 'tag', 'VolIqPlot');
                delete(findobj(h, 'tag', 'hkl_slices'));
                return
            end
            switch lower(handles.bgview_cross.SelectedObject.String)
                case 'v2 x v3'
                    v = [1, 0, 0];
                case 'v3 x v1'
                    v = [0, 1, 0];
                case 'v1 x v2'
                    v = [0, 0, 1];
            end
            
            drawhklslices(hObject, v)
            %handles.ed_tmp.Callback(hObject,v)
        end

        % Value changed function: ch_isReciprocal
        function ch_isReciprocal_Callback(app, event)
            % --- Executes on button press in ch_isReciprocal.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ch_isReciprocal (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of ch_isReciprocal
            ev1 = findobj(gcbf, 'tag', 'edview1');
            ev2 = findobj(gcbf, 'tag', 'edview2');
            ev3 = findobj(gcbf, 'tag', 'edview3');
            % alen = str2double(get(ev1, 'Value'));
            % blen = str2double(get(ev2, 'Value'));
            % clen = str2double(get(ev3, 'Value'));
            
                if get(hObject,'Value')
                    as = getappdata(handles.figure1, 'as');
                    bs = getappdata(handles.figure1, 'bs');
                    cs = getappdata(handles.figure1, 'cs');
                else
                    as = getappdata(handles.figure1, 'a');
                    bs = getappdata(handles.figure1, 'b');
                    cs = getappdata(handles.figure1, 'c');
                end
            
                set(ev1, 'Value', sprintf('%0.5f', norm(as)));
                set(ev2, 'Value', sprintf('%0.5f', norm(bs)));
                set(ev3, 'Value', sprintf('%0.5f', norm(cs)));
        end

        % Menu selected function: defineCM
        function defineCM_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to defineCM (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            fh = getappdata(gcbf, 'dataFigure');
            ud = get(gcbf, 'userdata');
            ax = findobj(fh, 'type', 'axes');
            xl = ax.XLim;
            yl = ax.YLim;
            zl = ax.ZLim;
            cm(1) = mean(xl);
            cm(2) = mean(yl);
            cm(3) = mean(zl);
            ud.cm = cm;
            set(gcbf, 'userdata', ud);
        end

        % Value changed function: ed_a
        function ed_a_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ed_a (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns contents of ed_a as text
            %        str2double(get(hObject,'Value')) returns contents of ed_a as a double
            update_to_cellinfo(app, handles)
        end

        % Value changed function: ed_alpha
        function ed_alpha_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ed_alpha (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns contents of ed_alpha as text
            %        str2double(get(hObject,'Value')) returns contents of ed_alpha as a double
            update_to_cellinfo(app, handles)
        end

        % Value changed function: ed_b
        function ed_b_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ed_b (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns contents of ed_b as text
            %        str2double(get(hObject,'Value')) returns contents of ed_b as a double
            update_to_cellinfo(app, handles)
        end

        % Value changed function: ed_beta
        function ed_beta_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ed_beta (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns contents of ed_beta as text
            %        str2double(get(hObject,'Value')) returns contents of ed_beta as a double
            update_to_cellinfo(app, handles)
        end

        % Value changed function: ed_c
        function ed_c_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ed_c (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns contents of ed_c as text
            %        str2double(get(hObject,'Value')) returns contents of ed_c as a double
            update_to_cellinfo(app, handles)
        end

        % Value changed function: ed_gamma
        function ed_gamma_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ed_gamma (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns contents of ed_gamma as text
            %        str2double(get(hObject,'Value')) returns contents of ed_gamma as a double
            update_to_cellinfo(app, handles)
        end

        % Value changed function: ed_markersize
        function ed_markersize_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ed_markersize (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns contents of ed_markersize as text
            %        str2double(get(hObject,'Value')) returns contents of ed_markersize as a double
            pbgrid_Callback(app)
        end

        % Value changed function: ed_phi
        function ed_phi_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ed_phi_euler (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns contents of ed_phi_euler as text
            %        str2double(get(hObject,'Value')) returns contents of ed_phi_euler as a double
            val = str2double(get(hObject, 'Value'));
            set(handles.slider_phi, 'value', val);
            theta = str2double(get(handles.ed_theta_euler, 'Value'));
            slider_rotate(app, val, theta)
        end

        % Value changed function: ed_phi_euler
        function ed_phi_euler_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ed_phi_euler (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns contents of ed_phi_euler as text
            %        str2double(get(hObject,'Value')) returns contents of ed_phi_euler as a double
            update_to_cellinfo(app, handles)
        end

        % Value changed function: ed_psi_euler
        function ed_psi_euler_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ed_psi_euler (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns contents of ed_psi_euler as text
            %        str2double(get(hObject,'Value')) returns contents of ed_psi_euler as a double
            update_to_cellinfo(app, handles)
        end

        % Value changed function: ed_theta
        function ed_theta_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ed_theta_euler (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns contents of ed_theta_euler as text
            %        str2double(get(hObject,'Value')) returns contents of ed_theta_euler as a double
            val = str2double(get(hObject, 'Value'));
            set(handles.slider_theta, 'value', val);
            phi = str2double(get(handles.ed_phi_euler, 'Value'));
            slider_rotate(app, phi, val)
        end

        % Value changed function: ed_theta_euler
        function ed_theta_euler_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ed_theta_euler (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns contents of ed_theta_euler as text
            %        str2double(get(hObject,'Value')) returns contents of ed_theta_euler as a double
            update_to_cellinfo(app, handles)
        end

        % Value changed function: edsetview
        function edsetview_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            
            % Hints: get(hObject,'Value') returns contents of edsetview as text
            %        str2double(get(hObject,'Value')) returns contents of edsetview as a double
            vv = eval(app.edsetview.Value);
            cellinfo = getappdata(gcbf, 'cellinfo');
            if app.ch_isReciprocal.Value
                vv = vv*cellinfo.recilatticevectors;
            else
                vv = vv*cellinfo.latticevectors;
            end
            view(vv);
        end

        % Value changed function: edview1
        function edview1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edview1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns contents of edview1 as text
            %        str2double(get(hObject,'Value')) returns contents of edview1 as a double
            set_recivector(app, app.edview1)
        end

        % Value changed function: edview2
        function edview2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edview2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns contents of edview2 as text
            %        str2double(get(hObject,'Value')) returns contents of edview2 as a double
            set_recivector(app, app.edview2)
        end

        % Value changed function: edview3
        function edview3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edview3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns contents of edview3 as text
            %        str2double(get(hObject,'Value')) returns contents of edview3 as a double
            set_recivector(app, app.edview3)
        end

        % Menu selected function: exportCM2DataFigure
        function exportCM2DataFigure_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to exportCM2DataFigure (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            fh = getappdata(gcbf, 'dataFigure');
            ud = get(fh, 'userdata');
            udf = get(gcbf, 'userdata');
            ud.CM = ud.CM+udf.cm/ud.pixelsize;
            set(fh, 'userdata', ud);
        end

        % Menu selected function: load_cellinfo_frombase
        function load_cellinfo_frombase_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
           % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to load_cellinfo_frombase (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            cellinfo = evalin('base', 'cellinfo');
            load_cellinfo(app, cellinfo)
            update_from_cellinfo(app)
        end

        % Menu selected function: m_autoindexing
        function m_autoindexing_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            ff = evalin('base', 'QmapFamily');
            if isempty(ff)
                warndlg('On the menu of draw_3dmap, run Tools>Split Isosurfaces.', 'Warning', 'modal');
                return
            end
            
            %fh = getappdata(gcbf, 'dataFigure');
            %ax = findobj(fh, 'type', 'axes');
            %ff = findobj(ax, 'type', 'patch');
            fcm = zeros(numel(ff), 3);
            for i=1:numel(ff)
                fcm(i, :) = ff{i}.cm;
            end
            if size(fcm, 1)>70
                fcm = fcm(1:70,:);
            end
            fcm = [fcm;-fcm];
            fcm = reducehkl(fcm);
            pnts = clusterpnts(fcm, 0.01);
            [lattice, indices] = index_proj(fcm);
            cellinfo = celcon4latvec(lattice(1,:),lattice(2,:),lattice(3,:));
            load_cellinfo(app, cellinfo)
        end

        % Menu selected function: m_clearall
        function m_clearall_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to m_clearall (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            figh = getappdata(gcbf, 'dataFigure');
            delete(findobj(figh, 'tag', 'Ewaldsphere_on_RST'));
            delete(findobj(figh, 'tag', 'unitcell'));
            set(handles.uipanel5, 'visible', 'off');
        end

        % Menu selected function: m_draw_EWsphere
        function m_draw_EWsphere_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to m_draw_EWsphere (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            figh = getappdata(gcbf, 'dataFigure');
            waveln = getappdata(figh, 'wavelength');
            if isempty(waveln)
                eng = input('Input X-ray Energy (keV): ');
                waveln = eng2wl(eng);
                setappdata(figh, 'wavelength', waveln)
                fprintf('Type setappdata(gcf, "wavelength", [new value]) to replace.\n')
            end
            % list all posiible hkls
            v1 = getappdata(gcbf, 'as');
            v2 = getappdata(gcbf, 'bs');
            v3 = getappdata(gcbf, 'cs');
            ax = findobj(figh, 'type', 'axes');
            xl = (get(ax, 'XLim'));
            yl = (get(ax, 'YLim'));
            zl = (get(ax, 'ZLim'));
            [X, Y, Z] = meshgrid(-10:10, -10:10, -10:10);
            np = [X(:), Y(:), Z(:)]*[v1; v2; v3];
            t = np(:,1)<xl(1) | np(:,1)>xl(2);
            t = t | np(:,2)<yl(1) | np(:,2)>yl(2);
            t = t | np(:,3)<zl(1) | np(:,3)>zl(2);
            np(t, :) = [];
            
            % draw Ewald sphere.
            cc = Ewaldsphere(waveln, np, 'y');
            xd = get(cc, 'xdata');
            yd = get(cc, 'ydata');
            zd = get(cc, 'zdata');
            dt{1} = xd;
            dt{2} = yd;
            dt{3} = zd;
            set(cc, 'userdata', dt);
            set(cc, 'facealpha', 0.9);
            set(cc, 'tag', 'Ewaldsphere_on_RST');
            set(handles.uipanel5, 'visible', 'on');
        end

        % Menu selected function: m_draw_unitcell
        function m_draw_unitcell_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to m_draw_unitcell (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            ct = getappdata(gcbf, 'cellinfo');
            if isempty(ct)
                fprintf('Cellinfo has not been loaded. Therefore, loading from workspace.\n');
                ct = evalin('base', 'cellinfo');
            end
            fh = getappdata(gcbf, 'dataFigure');
            delete(findobj(fh, 'type', 'line'));
            ax = findobj(fh, 'type', 'axes');
            xl = get(ax, 'XLim');
            yl = get(ax, 'YLim');
            zl = get(ax, 'ZLim');
            ud = get(gcbf, 'userdata');
            if isfield(ud, 'cm')
                cm = ud.cm;
            else
                cm = [0, 0, 0];
            end
            if app.ch_isReciprocal.Value
                cm = [0,0,0];
            end
            
            fprintf('Origin of the unit cell is at [%0.4f, %0.4f, %0.4f]\n', cm);
            vx = zeros(3,3);
            for i=1:3
                set(app.(['rbview',num2str(i)]), 'Value', 1);
                if app.ch_isReciprocal.Value
                    v = ct.recilatticevectors(i, :);
                else
                    v = ct.latticevectors(i, :);
                end
                vx(i, 1:3) = v;
                pbsetview_Callback(app, v);
            end
            
            lh = drawunitcell(vx(1, :), ...
                vx(2,:), ...
                vx(3,:));
            for i=1:numel(lh)
                % lh(i).XData = lh(i).XData+cm(1);
                % lh(i).YData = lh(i).YData+cm(2);
                % lh(i).ZData = lh(i).ZData+cm(3);
                set(lh, 'linestyle', ':', 'tag', 'unitcell');
            end
            set(ax, 'XLim', xl)
            set(ax, 'YLim', yl)
            set(ax, 'ZLim', zl)
        end

        % Menu selected function: m_loadcellinfo
        function m_loadcellinfo_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to m_loadcellinfo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [fname, pname] = uigetfile('*.mat', 'Pick a Map file');
            if isequal(fname,0) || isequal(pname,0)
               disp('User pressed cancel')
            else
               disp(['User selected ', fullfile(pname, fname)])
            end
            loadedData = load(fullfile(pname, fname));
            if isfield(loadedData, 'cellinfo')
                cellinfo = loadedData.cellinfo;
            else
                return
            end
            load_cellinfo(app, cellinfo);
            update_from_cellinfo(app)
        end

        % Menu selected function: m_save_cellinfo
        function m_save_cellinfo_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to m_save_cellinfo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if handles.ch_isReciprocal.Value
                as = getappdata(gcbf, 'as');
                bs = getappdata(gcbf, 'bs');
                cs = getappdata(gcbf, 'cs');
                cellinfo = celcon4rcplatvec(as, bs, cs);
            else
                a = getappdata(gcbf, 'a');
                b = getappdata(gcbf, 'b');
                c = getappdata(gcbf, 'c');
                cellinfo = celcon4latvec(a, b, c);
            end
            
            [fname, pname] = uiputfile('*.mat', 'Save cellinfo As');
            if isequal(fname,0) || isequal(pname,0)
               disp('User pressed cancel')
               return
            end
            fn = fullfile(pname, fname);
            save(fn, 'cellinfo')
            fprintf('Saved as %s.\n', fn)
        end

        % Menu selected function: m_setDataFigure
        function m_setDataFigure_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            pbsetdatafigure_Callback(app, hObject, eventdata, handles)
        end

        % Menu selected function: mn_askexpsetup
        function mn_askexpsetup_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to mn_askexpsetup (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % make a struct variable, mysetup, with the following fields
            % mysetup
            %   .waveln; for example 1 for 1A.
            %   .psize; for example 0.075 for eiger
            %   .SDD; for example 2000 for 2m
            %   .tiltangle; for example = [0, 0, 0];
            %   .phirange; for exasmple = [-80, 80];
            try
                saxs = evalin('base', 'mysetup');
                setappdata(gcbf, 'saxsinfo', saxs);
            catch
                disp('Make a struct variable, mysetup, with the following fields')
                disp(' mysetup')
                disp('   .waveln; for example 1 for 1A.')
                disp('   .psize; for example 0.075 for eiger')
                disp('   .SDD; for example 2000 for 2m')
                disp('   .tiltangle; for example = [0, 0, 0];')
                disp('   .phirange; for exasmple = [-80, 80];')
            end
        end

        % Menu selected function: mn_find_spacegroup
        function mn_find_spacegroup_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to mn_find_spacegroup (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            indexfound = find_allow_reflections(app, handles);
            pmcobj = findobj(gcbf, 'tag', 'pm_centering_type');
            str = get(pmcobj, 'String');
            strv = get(pmcobj, 'ValueIndex');
            % [N, restofN] = strtok(str{strv}, ',');
            str_lattice = split(str, ', ');
            N = str2double(str_lattice{strv,1});
            sg_sel = split(str_lattice{strv,2}, ':');
            latticetype = sg_sel{1};
            for i=strv:size(str_lattice,1)
                sg_str = split(str_lattice{i,2}, ':');
                if ~strcmp(latticetype, sg_str{1})
                    continue
                end
                sg = sg_gen(strtrim(sg_str{2}));
                isok = true;
                for k = size(indexfound, 1)
                    if ccp4spg_is_sysabs(sg, indexfound(k, :))
                        isok = false;
                        break
                    end
                end
                if isok
                    fprintf('Possible spacegroup: %s\n', sg_str{2})
                end
            end
        end

        % Menu selected function: mn_generatebkg
        function mn_generatebkg_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to mn_generatebkg (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            h = getappdata(gcbf, 'dataFigure');
            hp = findobj(h, 'tag', 'grid_hkl');
            hkls = get(hp, 'userdata');
            d = sqrt(sum((hkls).^2, 2));
            [~, ind] = min(d);
            %ud = get(gcbf, 'userdata');
            xd = hp.XData(:);
            yd = hp.YData(:);
            zd = hp.ZData(:);
            pos = [xd(:), yd(:), zd(:)];
            pos0 = pos(ind, :);
            d = sqrt(sum((pos-pos0).^2, 2));
            d(ind) = inf;
            [~, ind] = min(d);
            pos1 = pos(ind, :);
            
            ud = get(h, 'userdata');
            ud_finder = get(gcbf, 'userdata');
            X = ud.Xax;
            Y = ud.Yax;
            Z = ud.Zax;
            if isfield(ud_finder, 'cm')
                X = X-ud_finder.cm(1);
                Y = Y-ud_finder.cm(2);
                Z = Z-ud_finder.cm(3);
            end
            vox = ud.map;
            
            qv = [X(:), Y(:), Z(:)];
            d = sqrt(sum((qv-pos0).^2, 2));
            [~, ind0] = min(d);
            
            d = sqrt(sum((qv-pos1).^2, 2));
            [~, ind1] = min(d);
            [i0, j0, k0] = ind2sub(size(vox), ind0);
            [i1, j1, k1] = ind2sub(size(vox), ind1);
            ds = round(sqrt((i0-i1)^2+(j0-j1)^2+(k0-k1)^2)/2)+1;
            vox = remove_intensity_from_vox(vox, pos, X, Y, Z, ds);
            f = 1;
            while f>0
                [vox, f] = fillNaN(vox);
            end
            ud.background = vox;
            set(h, 'userdata', ud);
        end

        % Menu selected function: mn_integrateintensities
        function mn_integrateintensities_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to mn_integrateintensities (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            h = getappdata(gcbf, 'dataFigure');
            hp = findobj(h, 'tag', 'grid_hkl');
            hkls = get(hp, 'userdata');
            d = sqrt(sum((hkls).^2, 2));
            [~, ind] = min(d);
            %ud = get(gcbf, 'userdata');
            xd = hp.XData(:);
            yd = hp.YData(:);
            zd = hp.ZData(:);
            pos = [xd(:), yd(:), zd(:)];
            pos0 = pos(ind, :);
            d = sqrt(sum((pos-pos0).^2, 2));
            d(ind) = inf;
            [~, ind] = min(d);
            pos1 = pos(ind, :);
            
            ud = get(h, 'userdata');
            ud_finder = get(gcbf, 'userdata');
            X = ud.Xax;
            Y = ud.Yax;
            Z = ud.Zax;
            if isfield(ud_finder, 'cm')
                X = X-ud_finder.cm(1);
                Y = Y-ud_finder.cm(2);
                Z = Z-ud_finder.cm(3);
            end
            vox = ud.map;
            
            qv = [X(:), Y(:), Z(:)];
            d = sqrt(sum((qv-pos0).^2, 2));
            [~, ind0] = min(d);
            
            d = sqrt(sum((qv-pos1).^2, 2));
            [~, ind1] = min(d);
            [i0, j0, k0] = ind2sub(size(vox), ind0);
            [i1, j1, k1] = ind2sub(size(vox), ind1);
            ds = round(sqrt((i0-i1)^2+(j0-j1)^2+(k0-k1)^2)/2*0.75);
            
            [inten, xp, yp, zp] = vox_hkl_integrate(vox, pos, X, Y, Z, ds);
            % Fridel-pair averaging...
            for i = 1:size(hkls, 1)
                G = hkls(i, 1:3);
                t = find(hkls(:,1) == -G(1) & hkls(:,2) == -G(2) & hkls(:,3) == -G(3));
                inten([i,t]) = sum(inten([i,t]))/2;
            end
            %end
            
            ud.intensity = inten;
            ud.qv = pos;
            ud.xp = xp;
            ud.yp = yp;
            ud.zp = zp;
            ud.hkls = hkls;
            set(h, 'userdata', ud);
            % % since qv = hkls*latticevectors', it may be possible to estimate
            % % latticevectors from the fit.
            % mat = ud.hkls'*[xp(:), yp(:), zp(:)]; % Gauss elimination...
            % av = mat(:,1)';
            % bv = mat(:,2)';
            % cv = mat(:,3)';
            % celcon4latvec(av, bv, cv)
            options = optimset('fminsearch');
            options = optimset(options, 'TolX',0.1E-8);
            %        options = optimset(options, 'PlotFcns',@optimplotx);
            options = optimset(options, 'MaxIter',5000);
            options = optimset(options, 'MaxFunEvals', 5000);
            cellinfo = getappdata(gcbf, 'cellinfo');
            fprintf('Fitting optimum lattice vectors\n')
            lv = fminsearch(@(x) fitf(app, x, [xp(:), yp(:), zp(:)], hkls),cellinfo.recilatticevectors, options);
            fprintf('Fit result:')
            cinf = celcon4rcplatvec(lv(1, :), lv(2, :), lv(3, :));
            disp(cinf)
            fprintf('Suggested lattice vectors ([av; bv; cv]):\n')
            disp(cinf.latticevectors)
            fprintf('Done.\n');
        end

        % Menu selected function: mn_overlay2D
        function mn_overlay2D_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to mn_overlay2D (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if strcmp(hObject.Checked, 'on')
                set(hObject, 'Checked', 'Off');
            else
                set(hObject, 'Checked', 'on');
                calculate_q_pixel(app)
            end
        end

        % Menu selected function: mn_plotobserved_reflections
        function mn_plotobserved_reflections_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to mn_plotobserved_reflections (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            ff = evalin('base', 'QmapFamily');
            if isempty(ff)
                warndlg('On the menu of draw_3dmap, run Tools>Split Isosurfaces.', 'Warning', 'modal');
                return
            end
            
            %fh = getappdata(gcbf, 'dataFigure');
            %ax = findobj(fh, 'type', 'axes');
            %ff = findobj(ax, 'type', 'patch');
            fcm = zeros(numel(ff), 3);
            for i=1:numel(ff)
                fcm(i, :) = ff{i}.cm;
            end
            Ndist = distNzero(fcm);
            figure;
            subplot(1,2,1)
            plot3(fcm(:,1),fcm(:,2),fcm(:,3), 'ro');
            title('QmapFamily cm')
            axis image
            subplot(1,2,2)
            histogram(Ndist, round(numel(Ndist)/2))
            title('Histogram of \Delta q')
        end

        % Menu selected function: mn_saveintegratedintensities
        function mn_saveintegratedintensities_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to mn_saveintegratedintensities (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            h = getappdata(gcbf, 'dataFigure');
            ud = get(h, 'userdata');
            if ~isfield(ud, 'hkls')
                fprintf('Run Analysis>Integrated Intensity before proceeding.\n');
            end
            data = [ud.hkls, ud.intensity/max(ud.intensity)*100, ones(size(ud.intensity))];
            %sg = [];
            filename = [];
            cellinfo = getappdata(gcbf, 'cellinfo');
            
            pmcobj = findobj(gcbf, 'tag', 'pm_centering_type');
            %str = get(pmcobj, 'String');
            %strv = get(pmcobj, 'ValueIndex');
            strv = pmcobj.Value;
            [sgN, sg] = strtok(strv, ':');
            [sgN, syst] = strtok(sgN, ',');
            sg = strtrim(sg(2:end));
            syst = strtrim(syst(2:end));
            
            if str2double(sgN)==0
                sg = [];
            else
                switch syst
                    case 'cubic'
                        cellinfo.B = cellinfo.A;
                        cellinfo.C = cellinfo.A;
                        cellinfo.alpha = 90;
                        cellinfo.beta = 90;
                        cellinfo.gamma = 90;
                    case {'hexagonal', 'trigonal'}
                        cellinfo.B = cellinfo.A;
                        cellinfo.alpha = 90;
                        cellinfo.beta = 90;
                        cellinfo.gamma = 120;
                    case 'tetragonal'
                        cellinfo.B = cellinfo.A;
                        cellinfo.alpha = 90;
                        cellinfo.beta = 90;
                        cellinfo.gamma = 90;
                    case 'orthorhombic'
                        cellinfo.alpha = 90;
                        cellinfo.beta = 90;
                        cellinfo.gamma = 90;
                    case 'monoclinic'
                        cellinfo.alpha = 90;
                        cellinfo.gamma = 90;
                end
            end
            saveasinflip([], data, sg, cellinfo, filename);
        end

        % Menu selected function: mn_setcurrent_expsum
        function mn_setcurrent_expsum_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to mn_setcurrent_expsum (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
                img = evalin('base', 'img_sum');
                imagesc(log10(img+0.01), [5, 9]);axis image
                axis xy;
            catch
                disp('img_sum is not available to plot. A current figure will be selected')
            end
            pbsetexpsumfigure_Callback(app, hObject, eventdata, handles)
        end

        % Menu selected function: mn_swapab
        function mn_swapab_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to mn_swapab (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if handles.ch_isReciprocal.Value
                reci_str = 's';
            else
                reci_str = '';
            end
            ax = strcat('a', reci_str);
            bx = strcat('b', reci_str);
            cx = strcat('c', reci_str);
            v1 = getappdata(gcbf, ax);
            v2 = getappdata(gcbf, bx);
            v3 = getappdata(gcbf, cx);
            v = v2;
            v2 = v1;
            v1 = v;
            setappdata(gcbf, ax, v1);
            setappdata(gcbf, bx, v2);
            setappdata(gcbf, cx, v3);
        end

        % Menu selected function: mn_swapac
        function mn_swapac_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to mn_swapac (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if handles.ch_isReciprocal.Value
                reci_str = 's';
            else
                reci_str = '';
            end
            ax = strcat('a', reci_str);
            bx = strcat('b', reci_str);
            cx = strcat('c', reci_str);
            v1 = getappdata(gcbf, ax);
            v2 = getappdata(gcbf, bx);
            v3 = getappdata(gcbf, cx);
            v = v3;
            v3 = v1;
            v1 = v;
            setappdata(gcbf, ax, v1);
            setappdata(gcbf, bx, v2);
            setappdata(gcbf, cx, v3);
        end

        % Menu selected function: mn_swapbc
        function mn_swapbc_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to mn_swapbc (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if handles.ch_isReciprocal.Value
                reci_str = 's';
            else
                reci_str = '';
            end
            ax = strcat('a', reci_str);
            bx = strcat('b', reci_str);
            cx = strcat('c', reci_str);
            v1 = getappdata(gcbf, ax);
            v2 = getappdata(gcbf, bx);
            v3 = getappdata(gcbf, cx);
            v = v2;
            v2 = v3;
            v3 = v;
            setappdata(gcbf, ax, v1);
            setappdata(gcbf, bx, v2);
            setappdata(gcbf, cx, v3);
        end

        % Button pushed function: pbcleargrid
        function pbcleargrid_Callback(app, event)
            % --- Executes on button press in pbcleargrid.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbcleargrid (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            fh = getappdata(gcbf, 'dataFigure');
            ax = findobj(fh, 'type', 'axes');
            delete(findobj(fh, 'tag', 'grid_hkl'));
            delete(findobj(fh, 'tag', 'grid_hkl_P'));
            set(findobj(ax, 'type', 'patch'), 'facealpha', 1);
            if app.mn_overlay2D.Checked
                fh = getappdata(gcbf, 'expsumimgFigure');
                if ishandle(fh)
                    delete(findobj(fh, 'tag', 'simulated_hkls'));
                end
            end
        end

        % Button pushed function: pbdrawlines
        function pbdrawlines_Callback(app, event)
            % --- Executes on button press in pbdrawlines.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbdrawlines (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            draw_latvec(app, 1, event);
            draw_latvec(app, 2, event);
            draw_latvec(app, 3, event);
        end

        % Button pushed function: pbgrid
        function pbgrid_Callback(app, event)
            % --- Executes on button press in pbgrid.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbgrid (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            pbcleargrid_Callback(app);
            ud = get(gcbf, 'userdata');
            if isfield(ud, 'cm')
                cm = ud.cm;
            else
                cm = [0, 0, 0];
            end
            
            fh = getappdata(gcbf, 'dataFigure');
            if isempty(fh)
                error('Please choose datafigure before proceeding.')
            end
            if app.ch_isReciprocal.Value
                v1 = getappdata(gcbf, 'as');
                v2 = getappdata(gcbf, 'bs');
                v3 = getappdata(gcbf, 'cs');
            else
                v1 = getappdata(gcbf, 'a');
                v2 = getappdata(gcbf, 'b');
                v3 = getappdata(gcbf, 'c');
            end
            %ev1 = findobj(gcbf, 'tag', 'edview1');
            %ev2 = findobj(gcbf, 'tag', 'edview2');
            %ev3 = findobj(gcbf, 'tag', 'edview3');
            %alen = str2double(get(ev1, 'Value'));
            %blen = str2double(get(ev2, 'Value'));
            %clen = str2double(get(ev3, 'Value'));
            
            ax = findobj(fh, 'type', 'axes');
            xl = (get(ax, 'XLim'));
            yl = (get(ax, 'YLim'));
            zl = (get(ax, 'ZLim'));
            box = [xl(1), yl(1), zl(1);xl(2),yl(1),zl(1);
                xl(1), yl(2), zl(1);xl(2),yl(2),zl(1);
                xl(1),yl(2),zl(2);xl(2),yl(2),zl(2);
                xl(1),yl(1),zl(2);xl(2),yl(1),zl(2);];
            box = box-cm;
            latticevectors = [v1;v2;v3];
            % fractioanl coordinates
            hbox = box/latticevectors;
            hmin = round(min(hbox(:,1)));
            hmax = round(max(hbox(:,1)));
            kmin = round(min(hbox(:,2)));
            kmax = round(max(hbox(:,2)));
            lmin = round(min(hbox(:,3)));
            lmax = round(max(hbox(:,3)));
            pmcobj = findobj(gcbf, 'tag', 'pm_centering_type');
            %str = get(pmcobj, 'Items');
            %strv = get(pmcobj, 'ValueIndex');
            strv = pmcobj.Value;
            %hmax = max(abs(fix(xl/alen)))+2;
            %kmax = max(abs(fix(yl/blen)))+2;
            %lmax = max(abs(fix(zl/clen)))+2;
            msobj = findobj(gcbf, 'tag', 'ed_markersize');
            ms = str2double(get(msobj, 'Value'));
            
            hl = linspace(hmin, hmax, hmax-hmin+1);
            kl = linspace(kmin, kmax, kmax-kmin+1);
            ll = linspace(lmin, lmax, lmax-lmin+1);
            
            [X, Y, Z] = meshgrid(hl, kl, ll);
            hkls = [X(:), Y(:), Z(:)];
            %N = strtok(str{strv}, ',');
            N = strtok(strv, ',');
            N = str2double(N);
            switch N
                case 0
                    hkls_cen = [];
                    sg = [];
                otherwise
                    if app.ch_isReciprocal.Value
                        ud = get(gcbf, 'userdata');
                        sg = sg_gen(N);
                        hkls_cen = hkl_listall(sg, [hmax, kmax, lmax]);
                    else
                        sg = 'I';
                        hkls_cen = hkls + [0.5, 0.5, 0.5];
                    end
            end
            % convert fractional to cartesian coordinate
            np = hkls*latticevectors;
            if ~app.ch_isReciprocal.Value
                np = np+cm;
            end
            % remove peaks out of the view
            t = np(:,1)<xl(1) | np(:,1)>xl(2);
            t = t | np(:,2)<yl(1) | np(:,2)>yl(2);
            t = t | np(:,3)<zl(1) | np(:,3)>zl(2);
            np(t, :) = [];
            hkls(t, :) = [];
            
            qmax = str2double(app.grid_q_max.Value);
            d = sqrt(sum(np.^2, 2));
            t = d>qmax;
            np(t, :) = [];
            hkls(t, :) = [];
            
            if ~isempty(hkls_cen)
                np_cen = hkls_cen*latticevectors;
                if ~app.ch_isReciprocal.Value
                    np_cen = np_cen+cm;
                end
                d = sqrt(sum(np_cen.^2, 2));
                t = d>qmax;
                hkls_cen(t, :) = [];
            end
            fh = getappdata(gcbf, 'dataFigure');
            ud = get(fh, 'userdata');
            if ~isempty(sg)
                ud.sginfo = sg;
            end
            if ~app.ch_isReciprocal.Value
                if ~isfield(ud, 'Xd')
                    ud.Xd = ud.X*ud.pixelsize;
                    ud.Yd = ud.Y*ud.pixelsize;
                    ud.Zd = ud.Z*ud.pixelsize;
                %    set(fh, 'userdata', ud);
                end
            end
            set(fh, 'userdata', ud);
            set(findobj(ax, 'type', 'patch'), 'facealpha', 0.2);


            if ~isempty(hkls_cen)
                h = plot3(ax, np(:,1), np(:,2), np(:,3), 'bo', 'markersize', 4, 'markerfacecolor', 'k');
                set(h, 'tag', 'grid_hkl_P');
                %set(h, 'userdata', hkls);
                cbh = findobj(gcbf, 'tag', 'cb_draw_P');
                if get(cbh, 'value')
                    set(h, 'visible', 'on')
                else
                    set(h, 'visible', 'off')
                end
                np = hkls_cen*latticevectors;
                t = np(:,1)<xl(1) | np(:,1)>xl(2);
                t = t | np(:,2)<yl(1) | np(:,2)>yl(2);
                t = t | np(:,3)<zl(1) | np(:,3)>zl(2);
                np(t, :) = [];
                if ~app.ch_isReciprocal.Value
                    np = np+cm;
                end
                hkls_cen(t, :) = [];
                %intv = interp3(ud.Xax, ud.Yax, ud.Zax,ud.map, np(:,1),np(:,2),np(:,3));
                %t = isnan(intv);
                h = plot3(ax, np(:,1), np(:,2), np(:,3), 'ro', 'markersize', ms);
                %h = plot3(ax, np(t,1), np(t,2), np(t,3), 'ro', 'markersize', ms);
                set(h, 'tag', 'grid_hkl');
                set(h, 'userdata', hkls_cen);
                %h = plot3(ax, np(~t,1), np(~t,2), np(~t,3), 'ro', 'markersize', ms, 'markerfacecolor', 'r');
                %set(h, 'tag', 'grid_hkl');
                %set(h, 'userdata', hkls_cen);
            else
                 if ~app.ch_isReciprocal.Value
                    try
                        intv = interp3(ud.Xd, ud.Yd, ud.Zd,ud.map, np(:,1),np(:,2),np(:,3));
                    catch
                        intv = interp3(ud.Yd, ud.Xd, ud.Zd,ud.map, np(:,2),np(:,1),np(:,3));
                    end
                    t = isnan(intv);
                 else
                     t = 1:size(np, 1);
                 end
                h = plot3(ax, np(t,1), np(t,2), np(t,3), 'ro', 'markersize', ms);
                set(h, 'tag', 'grid_hkl');
                set(h, 'userdata', hkls);
                h = plot3(ax, np(~t,1), np(~t,2), np(~t,3), 'ro', 'markersize', ms, 'markerfacecolor', 'r');
                set(h, 'tag', 'grid_hkl');
                set(h, 'userdata', hkls);
            end
            
            
            if app.mn_overlay2D.Checked
                fh = getappdata(gcbf, 'expsumimgFigure');
                if ishandle(fh)
                    calculate_q_pixel(app)
                end
            end
        end

        % Button pushed function: pblclearlines
        function pblclearlines_Callback(app, event)
            % --- Executes on button press in pblclearlines.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pblclearlines (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            fh = getappdata(gcbf, 'dataFigure');
            lh = findobj(fh, 'type', 'line', '-and', '-not', {'Tag', ''});
            k = [];
            for i=1:numel(lh)
                if contains(lh(i).Tag, 'line')
                    k = [k, i];
                end
            end
            delete(lh(k));
        end

        % Button pushed function: pbloadcellinfo
        function pbloadcellinfo_Callback(app, event)
            % --- Executes on button press in pbloadcellinfo.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbloadcellinfo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %ct = evalin('base', 'cellinfo');
            
            ct = getappdata(gcbf, 'cellinfo');
            if isempty(ct)
                fprintf('Cellinfo has not been loaded. Therefore, loading from workspace.\n');
                ct = evalin('base', 'cellinfo');
            end
            fh = getappdata(gcbf, 'dataFigure');
            delete(findobj(fh, 'type', 'line'));
            
            if app.ch_isReciprocal.Value
                for i=1:3
                    set(app.(['rbview',num2str(i)]), 'Value', 1);
                    pbsetview_Callback(app, ct.recilatticevectors(i, :));
                end
            
                lh = drawunitcell(ct.recilatticevectors(1, :), ...
                    ct.recilatticevectors(2, :), ...
                    ct.recilatticevectors(3, :));
            else
                for i=1:3
                    set(app.(['rbview',num2str(i)]), 'Value', 1);
                    pbsetview_Callback(app, ct.latticevectors(i, :));
                end
            
                lh = drawunitcell(ct.latticevectors(1, :), ...
                    ct.latticevectors(2, :), ...
                    ct.latticevectors(3, :));
            end
            for i=1:numel(lh)
                set(lh, 'linestyle', ':', 'tag', 'unitcell');
            end
            if app.ch_isReciprocal.Value
                set(app.edview1, 'Value', norm(ct.recilatticevectors(1, :)));
                set(app.edview2, 'Value', norm(ct.recilatticevectors(2, :)));
                set(app.edview3, 'Value', norm(ct.recilatticevectors(3, :)));
            else
                set(app.edview1, 'Value', norm(ct.latticevectors(1, :)));
                set(app.edview2, 'Value', norm(ct.latticevectors(2, :)));
                set(app.edview3, 'Value', norm(ct.latticevectors(3, :)));
            end
        end

        % Button pushed function: pbminus
        function pbminus_Callback(app, event)
            % --- Executes on button press in pbminus.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbminus (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            ds = str2double(handles.edshiftcenterval.String);
            shiftcenter(app, handles, -1, ds);
        end

        % Button pushed function: pbobservedhkl
        function pbobservedhkl_Callback(app, event)
            % --- Executes on button press in pbobservedhkl.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbobservedhkl (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            as = getappdata(gcbf, 'as');
            bs = getappdata(gcbf, 'bs');
            cs = getappdata(gcbf, 'cs');
            
            fh = getappdata(gcbf, 'dataFigure');
            ax = findobj(fh, 'type', 'axes');
            %lh = findobj(ax, 'type', 'line', '-and', {'Tag', ''});
            lh = findobj(ax, 'type', 'line');
            if isempty(lh)
                fprintf('Data Figure does not have any "line" object.\n')
                return
            end
            for i=1:numel(lh)
                l = lh(i);
                xd = get(l, 'xdata');
                if numel(xd) == 1
                    continue
                end
                yd = get(l, 'ydata');
                zd = get(l, 'zdata');
            end
            vv = [xd(:), yd(:), zd(:)];
            
            ohkl = check_hit_hkl(as, bs, cs, vv);
            fprintf('\n');
            fprintf('List of observed hkls ==========\n');
            for i=1:size(ohkl, 1)
                fprintf('[%i, %i, %i]\n', ohkl(i,:))
            end
            fprintf('Total %i reflections are observed.\n', size(ohkl, 1));
        end

        % Button pushed function: pbplus
        function pbplus_Callback(app, event)
            % --- Executes on button press in pbplus.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbplus (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            ds = str2double(handles.edshiftcenterval.String);
            shiftcenter(app, handles, 1, ds);
        end

        % Button pushed function: pbprint
        function pbprint_Callback(app, event)
            % --- Executes on button press in pbprint.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbprint (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                construct_cellinfo(app)
                ct = getappdata(app.figure1, 'cellinfo');
            
                if app.ch_isReciprocal.Value
                    fprintf('\n');
                    fprintf('Reciprocal lattice vectors ==========\n')
                    fprintf('a* = [%0.4e, %0.4e, %0.4e]\n', ct.recilatticevectors(1, :));
                    fprintf('b* = [%0.4e, %0.4e, %0.4e]\n', ct.recilatticevectors(2, :));
                    fprintf('c* = [%0.4e, %0.4e, %0.4e]\n', ct.recilatticevectors(3, :));
            
                    fprintf('Lattice vectors ==========\n')
                    fprintf('a = [%0.4e, %0.4e, %0.4e]\n', ct.latticevectors(1, :));
                    fprintf('b = [%0.4e, %0.4e, %0.4e]\n', ct.latticevectors(2, :));
                    fprintf('c = [%0.4e, %0.4e, %0.4e]\n', ct.latticevectors(3, :));
            
                    fprintf('Lattice Parameters ==========\n')
                    fprintf('[%0.3f, %0.3f, %0.3f, %0.3f, %0.3f, %0.3f]\n', ...
                        ct.A, ct.B, ct.C, ct.alpha, ct.beta, ct.gamma);
                    fprintf('Volume = %0.4e\n', ct.Vol);
                else
                    fprintf('\n');
                    fprintf('Lattice vectors ==========\n')
                    fprintf('a = [%0.4e, %0.4e, %0.4e]\n', ct.latticevectors(1, :));
                    fprintf('b = [%0.4e, %0.4e, %0.4e]\n', ct.latticevectors(2, :));
                    fprintf('c = [%0.4e, %0.4e, %0.4e]\n', ct.latticevectors(3, :));
            
                    fprintf('Reciprocal lattice vectors ==========\n')
                    fprintf('a* = [%0.4e, %0.4e, %0.4e]\n', ct.recilatticevectors(1, :));
                    fprintf('b* = [%0.4e, %0.4e, %0.4e]\n', ct.recilatticevectors(2, :));
                    fprintf('c* = [%0.4e, %0.4e, %0.4e]\n', ct.recilatticevectors(3, :));
            
                    fprintf('Lattice Parameters ==========\n')
                    fprintf('[%0.3f, %0.3f, %0.3f, %0.3f, %0.3f, %0.3f]\n', ...
                        ct.A, ct.B, ct.C, ct.alpha, ct.beta, ct.gamma);
                    fprintf('Volume = %0.4e\n', ct.Vol);
                end
            %    setappdata(gcbf, 'cellinfo', ct)
            %    update_from_cellinfo(handles)
                assignin('base', 'cellinfo', ct);
        end

        % Button pushed function: pbsetCa
        function pbsetCa_Callback(app, event)
            % --- Executes on button press in pbsetCa.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbsetCa (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            a = getcursor(app);
            set(handles.edview1, 'Value', norm(a));
            name = 'a';
            if handles.ch_isReciprocal.Value
                name = [name, 's'];
            end
            setappdata(gcbf, name, a);
            draw_latvec(app, 1, event);
        end

        % Button pushed function: pbsetCb
        function pbsetCb_Callback(app, event)
            % --- Executes on button press in pbsetCb.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbsetCb (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            a = getcursor(app);
            set(handles.edview2, 'Value', norm(a));
            name = 'b';
            if handles.ch_isReciprocal.Value
                name = [name, 's'];
            end
            setappdata(gcbf, name, a);
            draw_latvec(app, 2, event);
        end

        % Button pushed function: pbsetCc
        function pbsetCc_Callback(app, event)
            % --- Executes on button press in pbsetCc.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbsetCc (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            a = getcursor(app);
            set(handles.edview3, 'Value', norm(a));
            name = 'c';
            if handles.ch_isReciprocal.Value
                name = [name, 's'];
            end
            setappdata(gcbf, name, a);
            draw_latvec(app, 3, event);
        end

        % Button pushed function: pbseta
        function pbseta_Callback(app, event)
            % --- Executes on button press in pbseta.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbseta (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            cv = get_crossvector(app);
            name = 'a';
            if app.ch_isReciprocal.Value
                name = [name, 's'];
            end
            a = getappdata(gcbf, name);
            cv = cv/norm(cv);
            if ~isempty(a)
                cv = cv*norm(a);
            else
                set(app.edview1, 'Value', 1);
            end
            
            setappdata(gcbf, name, cv);
            draw_latvec(app, 1, event);
        end

        % Button pushed function: pbsetb
        function pbsetb_Callback(app, event)
            % --- Executes on button press in pbsetb.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbsetb (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            cv = get_crossvector(app);
            name = 'bs';
            if app.ch_isReciprocal.Value
                name = [name, 's'];
            end
            a = getappdata(gcbf, name);
            cv = cv/norm(cv);
            if ~isempty(a)
                cv = cv*norm(a);
            else
                set(app.edview2, 'Value', 1);
            end
            
            setappdata(gcbf, name, cv);
            draw_latvec(app, 2, event);
        end

        % Button pushed function: pbsetc
        function pbsetc_Callback(app, event)
            % --- Executes on button press in pbsetc.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbsetc (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            cv = get_crossvector(app);
            name = 'cs';
            if app.ch_isReciprocal.Value
                name = [name, 's'];
            end
            a = getappdata(gcbf, name);
            cv = cv/norm(cv);
            if ~isempty(a)
                cv = cv*norm(a);
            else
                set(app.edview3, 'Value', 1);
            end
            setappdata(gcbf, name, cv);
            draw_latvec(app, 3, event);
        end

        % Value changed function: pm_centering_type
        function pm_centering_type_Callback(app, event)
            % --- Executes on selection change in pm_centering_type.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pm_centering_type (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'Value')) returns pm_centering_type contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from pm_centering_type
            pbgrid_Callback(app, event)
        end

        % Button pushed function: pushbutton23
        function pushbutton23_Callback(app, event)
            % --- Executes on button press in pushbutton23.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton23 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if isobject(hObject)
                as = get_view(app);
            else
                as = hObject;
            end
            
            set(handles.edview3, 'Value', norm(as));
            name = 'c';
            if handles.ch_isReciprocal.Value
                name = [name, 's'];
            end
            setappdata(gcbf, name, as);
            %         setappdata(gcbf, 'cRmat', Rmat);
            c = 'b';
            linetag = 'linec';
            
            drawline(app, as, linetag, c);
        end

        % Button pushed function: pushbutton24
        function pushbutton24_Callback(app, event)
            % --- Executes on button press in pushbutton24.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton24 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if isobject(hObject)
                as = get_view(app);
            else
                as = hObject;
            end
            set(handles.edview2, 'Value', norm(as));
            name = 'b';
            if handles.ch_isReciprocal.Value
                name = [name, 's'];
            end
            
            setappdata(gcbf, name, as);
            %         setappdata(gcbf, 'bRmat', Rmat);
            c = 'g';
            linetag = 'lineb';
            
            drawline(app, as, linetag, c);
        end

        % Button pushed function: pushbutton25
        function pushbutton25_Callback(app, event)
            % --- Executes on button press in pushbutton25.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton25 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if isobject(hObject)
                as = get_view(app);
            else
                as = hObject;
            end
            
            set(handles.edview1, 'Value', norm(as));
            name = 'a';
            if handles.ch_isReciprocal.Value
                name = [name, 's'];
            end
            setappdata(gcbf, name, as);
            %         setappdata(gcbf, 'aRmat', Rmat);
            c = 'r';
            linetag = 'linea';
            
            drawline(app, as, linetag, c);
        end

        % Button pushed function: pushbutton27
        function pushbutton27_Callback(app, event)
            % --- Executes on button press in pushbutton27.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton27 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            v = get_vector_for_twopnts(app, handles);
            set(handles.edview1, 'Value', norm(v));
            name = 'a';
            if handles.ch_isReciprocal.Value
                name = [name, 's'];
            end
            setappdata(gcbf, name, v);
            %         setappdata(gcbf, 'aRmat', Rmat);
            c = 'r';
            linetag = 'linea';
            
            drawline(app, v, linetag, c);
        end

        % Button pushed function: pushbutton28
        function pushbutton28_Callback(app, event)
            % --- Executes on button press in pushbutton28.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton28 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            v = get_vector_for_twopnts(app, handles);
            set(handles.edview3, 'Value', norm(v));
            name = 'c';
            if handles.ch_isReciprocal.Value
                name = [name, 's'];
            end
            setappdata(gcbf, name, v);
            %         setappdata(gcbf, 'aRmat', Rmat);
            c = 'b';
            linetag = 'linec';
            
            drawline(app, v, linetag, c);
        end

        % Button pushed function: pushbutton29
        function pushbutton29_Callback(app, event)
            % --- Executes on button press in pushbutton29.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton29 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            v = get_vector_for_twopnts(app, handles);
            set(handles.edview2, 'Value', norm(v));
            name = 'b';
            if handles.ch_isReciprocal.Value
                name = [name, 's'];
            end
            setappdata(gcbf, name, v);
            %         setappdata(gcbf, 'aRmat', Rmat);
            c = 'g';
            linetag = 'lineb';
            
            drawline(app, v, linetag, c);
        end

        % Button pushed function: set_hkl1
        function set_hkl1_Callback(app, event)
            % --- Executes on button press in set_hkl1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to set_hkl1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            cellinfo = getappdata(handles.figure1, 'cellinfo');
            U = cellinfo.U;
            hkl1 = eval(handles.ed_hkl1.String);
            hkl = hkl1*cellinfo.recilatticevectors; % converting row reci vectors to cartesian.
            v = get_view(app); % get cartesian coordinates of the current view.
            R = rotate_between_vectors(hkl/norm(hkl), v/norm(v));
            newrv = cellinfo.recilatticevectors*R;
            cellinfo = celcon4rcplatvec(newrv(1, :),newrv(2, :),newrv(3, :));
            cellinfo.U = R'*U;
            %cellinfo = orientcrystals(cellinfo, ori);
            setappdata(handles.figure1, 'cellinfo', cellinfo);
            update_from_cellinfo(app, handles)
        end

        % Button pushed function: set_hkl2
        function set_hkl2_Callback(app, event)
            % --- Executes on button press in set_hkl2.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to set_hkl2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            cellinfo = getappdata(handles.figure1, 'cellinfo');
            U = cellinfo.U;
            hkl1 = eval(handles.ed_hkl1.String);
            hkl2 = eval(handles.ed_hkl2.String);
            hkl1 = hkl1*cellinfo.recilatticevectors; % converting row reci vectors to cartesian.
            hkl2 = hkl2*cellinfo.recilatticevectors; % converting row reci vectors to cartesian.
            hkl3 = cross(hkl1, hkl2);
            v1 = hkl1;
            v2 = get_view(app); % get cartesian coordinates of the current view.
            v3 = cross(v1, v2);
            R = [v1(:)/norm(v1), v2(:)/norm(v2), v3(:)/norm(v3)]*inv([hkl1(:)/norm(hkl1), hkl2(:)/norm(hkl2), hkl3(:)/norm(hkl3)]);
            newrv = cellinfo.recilatticevectors*R';
            newrv(1, :) = newrv(1, :)/norm(newrv(1, :))*norm(cellinfo.recilatticevectors(1, :));
            newrv(2, :) = newrv(2, :)/norm(newrv(2, :))*norm(cellinfo.recilatticevectors(2, :));
            newrv(3, :) = newrv(3, :)/norm(newrv(3, :))*norm(cellinfo.recilatticevectors(3, :));
            cellinfo = celcon4rcplatvec(newrv(1, :),newrv(2, :),newrv(3, :));
            %R = R*cellinfo.U;
            cellinfo.U = (R*U);
            setappdata(handles.figure1, 'cellinfo', cellinfo);
            update_from_cellinfo(app, handles)
        end

        % Value changed function: slalen
        function slalen_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to slalen (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            slider_len_callback(app)
        end

        % Value changed function: slblen
        function slblen_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to slblen (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            slider_len_callback(app)
        end

        % Value changed function: slclen
        function slclen_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to slclen (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            slider_len_callback(app)
        end

        % Value changed function: slider_phi
        function slider_phi_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to slider_phi (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            phi = get(hObject,'value');
            set(handles.ed_phi_euler, 'Value', num2str(phi));
            theta = get(handles.slider_theta, 'value');
            slider_rotate(app, phi, theta);
        end

        % Value changed function: slider_theta
        function slider_theta_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to slider_theta (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            theta = get(hObject,'value');
            set(handles.ed_theta_euler, 'Value', theta);
            phi = get(handles.slider_phi, 'value');
            slider_rotate(app, phi, theta);
        end

        % Value changed function: slview1
        function slview1_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to slview1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            slider_callback(app, event);
        end

        % Value changed function: slview2
        function slview2_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to slview2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            slider_callback(app, event)
        end

        % Value changed function: slview3
        function slview3_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to slview3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            slider_callback(app, event)
        end

        % Value changed function: ed_tmp
        function ed_tmpValueChanged(app, event)
            drawhklslices
            
        end

        % Value changed function: edview_rot1
        function edview_rot1ValueChanged(app, event)
            
        end

        % Value changed function: edview_rot2
        function edview_rot2ValueChanged(app, event)
            
        end

        % Value changed function: edview_rot3
        function edview_rot3ValueChanged(app, event)
            
        end

        % Menu selected function: RefinethegridMenu
        function RefinethegridMenuSelected(app, event)
            cellinfo = getappdata(app.figure1, 'cellinfo'); 
            setappdata(app.figure1, 'cellinfo0', cellinfo)
            lv = cellinfo.recilatticevectors;
            as0 = lv(1,:);
            bs0 = lv(2,:);
            cs0 = lv(3,:);
            peak = findobj(gca, 'tag', 'grid_hkl');
            ud = get(gcf, 'userdata');
            % q^4 multiplied to I(q)
            map4 = ud.map.*(ud.Xax.^2+ud.Yax.^2+ud.Zax.^2).^2;
            hkl = peak.UserData;
            lv0 = lv;
            int0 = 0;
            for k=0:1000
                if k==0
                    lvshift = 0;
                else
                    lvshift = (rand(3)-0.5)/0.5*0.02;
                end
                lv = lv0 + lvshift.*lv0;
                H = hkl*lv;
                intensity = interp3(ud.Yax, ud.Xax, ud.Zax, map4, H(:,1),H(:,2),H(:,3));
                intensity = tsnansum(intensity);
                if intensity>int0
                    lvshift
                    int0 = intensity;
                    as = lv(1,:);
                    bs = lv(2,:);
                    cs = lv(3,:);
                end
            end
            cellinfo = celcon4rcplatvec(as, bs, cs);
            setappdata(app.figure1, 'cellinfo', cellinfo)
            update_from_cellinfo(app)
        end

        % Menu selected function: RevertrefinementMenu
        function RevertrefinementMenuSelected(app, event)
            cellinfo = getappdata(app.figure1, 'cellinfo0'); 
            setappdata(app.figure1, 'cellinfo', cellinfo)
            update_from_cellinfo(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'recilatvector_finder';
            app.figure1.Tag = 'figure1';
            app.m_File = uimenu(app.figure1);
            app.m_File.Text = 'File';
            app.m_File.Tag = 'm_File';
            app.m_loadcellinfo = uimenu(app.m_File);
            app.m_loadcellinfo.MenuSelectedFcn = createCallbackFcn(app, @m_loadcellinfo_Callback, true);
            app.m_loadcellinfo.Text = 'Load Cellinfo';
            app.m_loadcellinfo.Tag = 'm_loadcellinfo';
            app.load_cellinfo_frombase = uimenu(app.m_File);
            app.load_cellinfo_frombase.MenuSelectedFcn = createCallbackFcn(app, @load_cellinfo_frombase_Callback, true);
            app.load_cellinfo_frombase.Text = 'Load Cellinfo from base';
            app.load_cellinfo_frombase.Tag = 'load_cellinfo_frombase';
            app.m_save_cellinfo = uimenu(app.m_File);
            app.m_save_cellinfo.MenuSelectedFcn = createCallbackFcn(app, @m_save_cellinfo_Callback, true);
            app.m_save_cellinfo.Text = 'Save Cellinfo';
            app.m_save_cellinfo.Tag = 'm_save_cellinfo';
            app.mn_saveintegratedintensities = uimenu(app.m_File);
            app.mn_saveintegratedintensities.MenuSelectedFcn = createCallbackFcn(app, @mn_saveintegratedintensities_Callback, true);
            app.mn_saveintegratedintensities.Text = 'Save Integrated Intensities';
            app.mn_saveintegratedintensities.Tag = 'mn_saveintegratedintensities';
            app.m_edit = uimenu(app.figure1);
            app.m_edit.Text = 'Edit';
            app.m_edit.Tag = 'm_edit';
            app.m_setDataFigure = uimenu(app.m_edit);
            app.m_setDataFigure.MenuSelectedFcn = createCallbackFcn(app, @m_setDataFigure_Callback, true);
            app.m_setDataFigure.Text = 'Set Current Figure as Data Figure';
            app.m_setDataFigure.Tag = 'm_setDataFigure';
            app.definecenterofcoordinates = uimenu(app.m_edit);
            app.definecenterofcoordinates.Text = 'Center of coordinates';
            app.definecenterofcoordinates.Tag = 'definecenterofcoordinates';
            app.exportCM2DataFigure = uimenu(app.definecenterofcoordinates);
            app.exportCM2DataFigure.MenuSelectedFcn = createCallbackFcn(app, @exportCM2DataFigure_Callback, true);
            app.exportCM2DataFigure.Text = 'Export CM to Data Figure';
            app.exportCM2DataFigure.Tag = 'exportCM2DataFigure';
            app.defineCM = uimenu(app.definecenterofcoordinates);
            app.defineCM.MenuSelectedFcn = createCallbackFcn(app, @defineCM_Callback, true);
            app.defineCM.Text = 'Define the view center as CM';
            app.defineCM.Tag = 'defineCM';
            app.mn_setcurrent_expsum = uimenu(app.m_edit);
            app.mn_setcurrent_expsum.MenuSelectedFcn = createCallbackFcn(app, @mn_setcurrent_expsum_Callback, true);
            app.mn_setcurrent_expsum.Text = 'Set Current Figure as Exp Sum Image';
            app.mn_setcurrent_expsum.Tag = 'mn_setcurrent_expsum';
            app.mn_askexpsetup = uimenu(app.m_edit);
            app.mn_askexpsetup.MenuSelectedFcn = createCallbackFcn(app, @mn_askexpsetup_Callback, true);
            app.mn_askexpsetup.Text = 'Experimental Setup';
            app.mn_askexpsetup.Tag = 'mn_askexpsetup';
            app.mn_swapaxis = uimenu(app.m_edit);
            app.mn_swapaxis.Text = 'Swap Axes';
            app.mn_swapaxis.Tag = 'mn_swapaxis';
            app.mn_swapab = uimenu(app.mn_swapaxis);
            app.mn_swapab.MenuSelectedFcn = createCallbackFcn(app, @mn_swapab_Callback, true);
            app.mn_swapab.Text = 'a<->b';
            app.mn_swapab.Tag = 'mn_swapab';
            app.mn_swapac = uimenu(app.mn_swapaxis);
            app.mn_swapac.MenuSelectedFcn = createCallbackFcn(app, @mn_swapac_Callback, true);
            app.mn_swapac.Text = 'a<->c';
            app.mn_swapac.Tag = 'mn_swapac';
            app.mn_swapbc = uimenu(app.mn_swapaxis);
            app.mn_swapbc.MenuSelectedFcn = createCallbackFcn(app, @mn_swapbc_Callback, true);
            app.mn_swapbc.Text = 'b<->c';
            app.mn_swapbc.Tag = 'mn_swapbc';
            app.m_draw = uimenu(app.figure1);
            app.m_draw.Text = 'Draw';
            app.m_draw.Tag = 'm_draw';
            app.m_draw_unitcell = uimenu(app.m_draw);
            app.m_draw_unitcell.MenuSelectedFcn = createCallbackFcn(app, @m_draw_unitcell_Callback, true);
            app.m_draw_unitcell.Text = 'Unit Cell';
            app.m_draw_unitcell.Tag = 'm_draw_unitcell';
            app.m_draw_EWsphere = uimenu(app.m_draw);
            app.m_draw_EWsphere.MenuSelectedFcn = createCallbackFcn(app, @m_draw_EWsphere_Callback, true);
            app.m_draw_EWsphere.Text = 'Ewald Sphere';
            app.m_draw_EWsphere.Tag = 'm_draw_EWsphere';
            app.m_clearall = uimenu(app.m_draw);
            app.m_clearall.MenuSelectedFcn = createCallbackFcn(app, @m_clearall_Callback, true);
            app.m_clearall.Text = 'Clear All';
            app.m_clearall.Tag = 'm_clearall';
            app.mn_indexing = uimenu(app.figure1);
            app.mn_indexing.Text = 'Indexing';
            app.mn_indexing.Tag = 'mn_indexing';
            app.RefinethegridMenu = uimenu(app.mn_indexing);
            app.RefinethegridMenu.MenuSelectedFcn = createCallbackFcn(app, @RefinethegridMenuSelected, true);
            app.RefinethegridMenu.Text = 'Refine the grid';
            app.RevertrefinementMenu = uimenu(app.mn_indexing);
            app.RevertrefinementMenu.MenuSelectedFcn = createCallbackFcn(app, @RevertrefinementMenuSelected, true);
            app.RevertrefinementMenu.Text = 'Revert refinement';
            app.mn_plotobserved_reflections = uimenu(app.mn_indexing);
            app.mn_plotobserved_reflections.MenuSelectedFcn = createCallbackFcn(app, @mn_plotobserved_reflections_Callback, true);
            app.mn_plotobserved_reflections.Separator = 'on';
            app.mn_plotobserved_reflections.Text = 'Plot observed reflections';
            app.mn_plotobserved_reflections.Tag = 'mn_plotobserved_reflections';
            app.m_autoindexing = uimenu(app.mn_indexing);
            app.m_autoindexing.MenuSelectedFcn = createCallbackFcn(app, @m_autoindexing_Callback, true);
            app.m_autoindexing.Text = 'AutoIndexing';
            app.m_autoindexing.Tag = 'm_autoindexing';
            app.mn_overlay2D = uimenu(app.mn_indexing);
            app.mn_overlay2D.MenuSelectedFcn = createCallbackFcn(app, @mn_overlay2D_Callback, true);
            app.mn_overlay2D.Text = 'Overlay to 2D';
            app.mn_overlay2D.Tag = 'mn_overlay2D';
            app.mn_find_spacegroup = uimenu(app.mn_indexing);
            app.mn_find_spacegroup.MenuSelectedFcn = createCallbackFcn(app, @mn_find_spacegroup_Callback, true);
            app.mn_find_spacegroup.Text = 'Find Space Group';
            app.mn_find_spacegroup.Tag = 'mn_find_spacegroup';
            app.m_analysis = uimenu(app.figure1);
            app.m_analysis.Text = 'Analysis';
            app.m_analysis.Tag = 'm_analysis';
            app.mn_integrateintensities = uimenu(app.m_analysis);
            app.mn_integrateintensities.MenuSelectedFcn = createCallbackFcn(app, @mn_integrateintensities_Callback, true);
            app.mn_integrateintensities.Text = 'Integrate intensities';
            app.mn_integrateintensities.Tag = 'mn_integrateintensities';
            app.mn_generatebkg = uimenu(app.m_analysis);
            app.mn_generatebkg.MenuSelectedFcn = createCallbackFcn(app, @mn_generatebkg_Callback, true);
            app.mn_generatebkg.Text = 'Generate Background Voxel';
            app.mn_generatebkg.Tag = 'mn_generatebkg';
            app.pbloadcellinfo = uibutton(app.figure1, 'push');
            app.pbloadcellinfo.ButtonPushedFcn = createCallbackFcn(app, @pbloadcellinfo_Callback, true);
            app.pbloadcellinfo.Tag = 'pbloadcellinfo';
            app.pbloadcellinfo.Text = 'Draw Lattice Vectors';
            app.pbcleargrid = uibutton(app.figure1, 'push');
            app.pbcleargrid.ButtonPushedFcn = createCallbackFcn(app, @pbcleargrid_Callback, true);
            app.pbcleargrid.Tag = 'pbcleargrid';
            app.pbcleargrid.Text = 'Clear Grid';
            app.edview1 = uieditfield(app.figure1, 'text');
            app.edview1.ValueChangedFcn = createCallbackFcn(app, @edview1_Callback, true);
            app.edview1.Tag = 'edview1';
            app.edview2 = uieditfield(app.figure1, 'text');
            app.edview2.ValueChangedFcn = createCallbackFcn(app, @edview2_Callback, true);
            app.edview2.Tag = 'edview2';
            app.edview3 = uieditfield(app.figure1, 'text');
            app.edview3.ValueChangedFcn = createCallbackFcn(app, @edview3_Callback, true);
            app.edview3.Tag = 'edview3';
            app.slview2 = uislider(app.figure1);
            app.slview2.Limits = [0 1];
            app.slview2.MajorTicks = [];
            app.slview2.ValueChangedFcn = createCallbackFcn(app, @slview2_Callback, true);
            app.slview2.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.slview2.Tag = 'slview2';
            app.slview2.Value = 0.5;
            app.slview3 = uislider(app.figure1);
            app.slview3.Limits = [0 1];
            app.slview3.MajorTicks = [];
            app.slview3.ValueChangedFcn = createCallbackFcn(app, @slview3_Callback, true);
            app.slview3.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.slview3.Tag = 'slview3';
            app.slview3.Value = 0.5;
            app.pbprint = uibutton(app.figure1, 'push');
            app.pbprint.ButtonPushedFcn = createCallbackFcn(app, @pbprint_Callback, true);
            app.pbprint.Tag = 'pbprint';
            app.pbprint.Text = 'Construct a Cell';
            app.pbgrid = uibutton(app.figure1, 'push');
            app.pbgrid.ButtonPushedFcn = createCallbackFcn(app, @pbgrid_Callback, true);
            app.pbgrid.Tag = 'pbgrid';
            app.pbgrid.Text = 'Draw 3D Grid';
            app.pblclearlines = uibutton(app.figure1, 'push');
            app.pblclearlines.ButtonPushedFcn = createCallbackFcn(app, @pblclearlines_Callback, true);
            app.pblclearlines.Tag = 'pblclearlines';
            app.pblclearlines.Text = 'Clear Lines';
            app.bgview = uibuttongroup(app.figure1);
            app.bgview.SelectionChangedFcn = createCallbackFcn(app, @bgview_SelectionChangedFcn, true);
            app.bgview.Title = 'Rotate to View';
            app.bgview.Tag = 'bgview';
            app.rbview3 = uiradiobutton(app.bgview);
            app.rbview3.Tag = 'rbview3';
            app.rbview3.Text = 'View3';
            app.rbview2 = uiradiobutton(app.bgview);
            app.rbview2.Tag = 'rbview2';
            app.rbview2.Text = 'View2';
            app.rbview1 = uiradiobutton(app.bgview);
            app.rbview1.Tag = 'rbview1';
            app.rbview1.Text = 'View1';
            app.rbview1.Value = true;
            app.edsetview = uieditfield(app.bgview, 'text');
            app.edsetview.ValueChangedFcn = createCallbackFcn(app, @edsetview_Callback, true);
            app.edsetview.Tag = 'edsetview';
            app.edsetview.Value = '[1 0 0]';
            app.text15 = uilabel(app.bgview);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Vector (hkl) : ';
            app.cb_show = uicheckbox(app.bgview);
            app.cb_show.ValueChangedFcn = createCallbackFcn(app, @cb_show_Callback, true);
            app.cb_show.Tag = 'cb_show';
            app.cb_show.Text = 'Show';
            app.bgshiftcenter = uibuttongroup(app.figure1);
            app.bgshiftcenter.Title = 'Shift Coordinate Origin along';
            app.bgshiftcenter.Tag = 'bgshiftcenter';
            app.rbshiftcenter3 = uiradiobutton(app.bgshiftcenter);
            app.rbshiftcenter3.Tag = 'rbshiftcenter3';
            app.rbshiftcenter3.Text = 'axis 3';
            app.rbshiftcenter2 = uiradiobutton(app.bgshiftcenter);
            app.rbshiftcenter2.Tag = 'rbshiftcenter2';
            app.rbshiftcenter2.Text = 'axis 2';
            app.rbshiftcenter1 = uiradiobutton(app.bgshiftcenter);
            app.rbshiftcenter1.Tag = 'rbshiftcenter1';
            app.rbshiftcenter1.Text = 'axis 1';
            app.rbshiftcenter1.Value = true;
            app.edshiftcenterval = uieditfield(app.bgshiftcenter, 'text');
            app.edshiftcenterval.Tag = 'edshiftcenterval';
            app.edshiftcenterval.Value = '0.01';
            app.pbminus = uibutton(app.bgshiftcenter, 'push');
            app.pbminus.ButtonPushedFcn = createCallbackFcn(app, @pbminus_Callback, true);
            app.pbminus.Tag = 'pbminus';
            app.pbminus.Text = '-';
            app.pbplus = uibutton(app.bgshiftcenter, 'push');
            app.pbplus.ButtonPushedFcn = createCallbackFcn(app, @pbplus_Callback, true);
            app.pbplus.Tag = 'pbplus';
            app.pbplus.Text = '+';
            app.pbdrawlines = uibutton(app.figure1, 'push');
            app.pbdrawlines.ButtonPushedFcn = createCallbackFcn(app, @pbdrawlines_Callback, true);
            app.pbdrawlines.Tag = 'pbdrawlines';
            app.pbdrawlines.Text = 'Draw Lines';
            app.pbobservedhkl = uibutton(app.figure1, 'push');
            app.pbobservedhkl.ButtonPushedFcn = createCallbackFcn(app, @pbobservedhkl_Callback, true);
            app.pbobservedhkl.Tag = 'pbobservedhkl';
            app.pbobservedhkl.Text = 'Show observed hkls';
            app.slview1 = uislider(app.figure1);
            app.slview1.Limits = [0 1];
            app.slview1.MajorTicks = [];
            app.slview1.ValueChangedFcn = createCallbackFcn(app, @slview1_Callback, true);
            app.slview1.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.slview1.Tag = 'slview1';
            app.slview1.Value = 0.5;
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Set Cross(Crnt View, Vrgb):';
            app.uipanel2.Tag = 'uipanel2';
            app.pbseta = uibutton(app.uipanel2, 'push');
            app.pbseta.ButtonPushedFcn = createCallbackFcn(app, @pbseta_Callback, true);
            app.pbseta.Tag = 'pbseta';
            app.pbseta.Text = 'As a';
            app.pbsetb = uibutton(app.uipanel2, 'push');
            app.pbsetb.ButtonPushedFcn = createCallbackFcn(app, @pbsetb_Callback, true);
            app.pbsetb.Tag = 'pbsetb';
            app.pbsetb.Text = 'As b';
            app.pbsetc = uibutton(app.uipanel2, 'push');
            app.pbsetc.ButtonPushedFcn = createCallbackFcn(app, @pbsetc_Callback, true);
            app.pbsetc.Tag = 'pbsetc';
            app.pbsetc.Text = 'As c';
            app.lb_color = uilistbox(app.uipanel2);
            app.lb_color.Items = {'R', 'G', 'B'};
            app.lb_color.Tag = 'lb_color';
            app.lb_color.Value = 'R';
            app.text11 = uilabel(app.uipanel2);
            app.text11.Tag = 'text11';
            app.text11.Text = 'X';
            app.text14 = uilabel(app.uipanel2);
            app.text14.Tag = 'text14';
            app.text14.Text = {'Current'; '  View'};
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Set DataCursor:';
            app.uipanel3.Tag = 'uipanel3';
            app.pbsetCc = uibutton(app.uipanel3, 'push');
            app.pbsetCc.ButtonPushedFcn = createCallbackFcn(app, @pbsetCc_Callback, true);
            app.pbsetCc.Tag = 'pbsetCc';
            app.pbsetCc.Text = 'As c';
            app.pbsetCb = uibutton(app.uipanel3, 'push');
            app.pbsetCb.ButtonPushedFcn = createCallbackFcn(app, @pbsetCb_Callback, true);
            app.pbsetCb.Tag = 'pbsetCb';
            app.pbsetCb.Text = 'As b';
            app.pbsetCa = uibutton(app.uipanel3, 'push');
            app.pbsetCa.ButtonPushedFcn = createCallbackFcn(app, @pbsetCa_Callback, true);
            app.pbsetCa.Tag = 'pbsetCa';
            app.pbsetCa.Text = 'As a';
            app.ed_markersize = uieditfield(app.figure1, 'text');
            app.ed_markersize.ValueChangedFcn = createCallbackFcn(app, @ed_markersize_Callback, true);
            app.ed_markersize.Tag = 'ed_markersize';
            app.ed_markersize.Value = '3';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Marker Size :';
            app.uipanel5 = uipanel(app.figure1);
            app.uipanel5.Title = 'Rotating X-ray Around a Crystal';
            app.uipanel5.Tag = 'uipanel5';
            app.slider_phi = uislider(app.uipanel5);
            app.slider_phi.Limits = [-180 180];
            app.slider_phi.MajorTicks = [];
            app.slider_phi.ValueChangedFcn = createCallbackFcn(app, @slider_phi_Callback, true);
            app.slider_phi.MinorTicks = [-180 -162 -144 -126 -108 -90 -72 -54 -36 -18 0 18 36 54 72 90 108 126 144 162 180];
            app.slider_phi.Tag = 'slider_phi';
            app.slider_theta = uislider(app.uipanel5);
            app.slider_theta.Limits = [-180 180];
            app.slider_theta.MajorTicks = [];
            app.slider_theta.ValueChangedFcn = createCallbackFcn(app, @slider_theta_Callback, true);
            app.slider_theta.MinorTicks = [-180 -162 -144 -126 -108 -90 -72 -54 -36 -18 0 18 36 54 72 90 108 126 144 162 180];
            app.slider_theta.Tag = 'slider_theta';
            app.text5 = uilabel(app.uipanel5);
            app.text5.Tag = 'text5';
            app.text5.Text = 'phi :';
            app.text6 = uilabel(app.uipanel5);
            app.text6.Tag = 'text6';
            app.text6.Text = 'theta :';
            app.ed_phi = uieditfield(app.uipanel5, 'text');
            app.ed_phi.ValueChangedFcn = createCallbackFcn(app, @ed_phi_Callback, true);
            app.ed_phi.Tag = 'ed_phi';
            app.ed_phi.Value = '0';
            app.ed_theta = uieditfield(app.uipanel5, 'text');
            app.ed_theta.ValueChangedFcn = createCallbackFcn(app, @ed_theta_Callback, true);
            app.ed_theta.Tag = 'ed_theta';
            app.ed_theta.Value = '0';
            app.uipanel6 = uipanel(app.figure1);
            app.uipanel6.Title = 'Spacegroup';
            app.uipanel6.Tag = 'uipanel6';
            app.pm_centering_type = uidropdown(app.uipanel6);
            app.pm_centering_type.Items = {'P', 'I', 'F', 'A'};
            app.pm_centering_type.ValueChangedFcn = createCallbackFcn(app, @pm_centering_type_Callback, true);
            app.pm_centering_type.Tag = 'pm_centering_type';
            app.pm_centering_type.Value = 'P';
            app.cb_draw_P = uicheckbox(app.uipanel6);
            app.cb_draw_P.ValueChangedFcn = createCallbackFcn(app, @cb_draw_P_Callback, true);
            app.cb_draw_P.Tag = 'cb_draw_P';
            app.cb_draw_P.Text = 'add P';
            app.cb_selhkls = uicheckbox(app.figure1);
            app.cb_selhkls.ValueChangedFcn = createCallbackFcn(app, @cb_selhkls_Callback, true);
            app.cb_selhkls.Tag = 'cb_selhkls';
            app.cb_selhkls.Text = 'Selected Visible';
            app.cb_selhkls.Value = true;
            app.slblen = uislider(app.figure1);
            app.slblen.Limits = [0 1];
            app.slblen.MajorTicks = [];
            app.slblen.ValueChangedFcn = createCallbackFcn(app, @slblen_Callback, true);
            app.slblen.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.slblen.Tag = 'slblen';
            app.slblen.Value = 0.5;
            app.slclen = uislider(app.figure1);
            app.slclen.Limits = [0 1];
            app.slclen.MajorTicks = [];
            app.slclen.ValueChangedFcn = createCallbackFcn(app, @slclen_Callback, true);
            app.slclen.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.slclen.Tag = 'slclen';
            app.slclen.Value = 0.5;
            app.slalen = uislider(app.figure1);
            app.slalen.Limits = [0 1];
            app.slalen.MajorTicks = [];
            app.slalen.ValueChangedFcn = createCallbackFcn(app, @slalen_Callback, true);
            app.slalen.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.slalen.Tag = 'slalen';
            app.slalen.Value = 0.5;
            app.text12 = uilabel(app.figure1);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Length:';
            app.text13 = uilabel(app.figure1);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Rotate (Relative):';
            app.ch_isReciprocal = uicheckbox(app.figure1);
            app.ch_isReciprocal.ValueChangedFcn = createCallbackFcn(app, @ch_isReciprocal_Callback, true);
            app.ch_isReciprocal.Tag = 'ch_isReciprocal';
            app.ch_isReciprocal.Text = 'The current is a reciprocal (check) or a real (uncheck) lattice.';
            app.ch_isReciprocal.Value = true;
            app.uipanel7 = uipanel(app.figure1);
            app.uipanel7.Title = 'Pick two points';
            app.uipanel7.Tag = 'uipanel7';
            app.pushbutton27 = uibutton(app.uipanel7, 'push');
            app.pushbutton27.ButtonPushedFcn = createCallbackFcn(app, @pushbutton27_Callback, true);
            app.pushbutton27.Tag = 'pushbutton27';
            app.pushbutton27.Text = 'As a';
            app.pushbutton28 = uibutton(app.uipanel7, 'push');
            app.pushbutton28.ButtonPushedFcn = createCallbackFcn(app, @pushbutton28_Callback, true);
            app.pushbutton28.Tag = 'pushbutton28';
            app.pushbutton28.Text = 'As c';
            app.pushbutton29 = uibutton(app.uipanel7, 'push');
            app.pushbutton29.ButtonPushedFcn = createCallbackFcn(app, @pushbutton29_Callback, true);
            app.pushbutton29.Tag = 'pushbutton29';
            app.pushbutton29.Text = 'As b';
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Title = 'Set Current View:';
            app.uipanel4.Tag = 'uipanel4';
            app.pushbutton25 = uibutton(app.uipanel4, 'push');
            app.pushbutton25.ButtonPushedFcn = createCallbackFcn(app, @pushbutton25_Callback, true);
            app.pushbutton25.Tag = 'pushbutton25';
            app.pushbutton25.Text = 'As a';
            app.pushbutton24 = uibutton(app.uipanel4, 'push');
            app.pushbutton24.ButtonPushedFcn = createCallbackFcn(app, @pushbutton24_Callback, true);
            app.pushbutton24.Tag = 'pushbutton24';
            app.pushbutton24.Text = 'As b';
            app.pushbutton23 = uibutton(app.uipanel4, 'push');
            app.pushbutton23.ButtonPushedFcn = createCallbackFcn(app, @pushbutton23_Callback, true);
            app.pushbutton23.Tag = 'pushbutton23';
            app.pushbutton23.Text = 'As c';
            app.uibuttongroup3 = uibuttongroup(app.figure1);
            app.uibuttongroup3.Title = 'Unit Cell Information & Orientation';
            app.uibuttongroup3.Tag = 'uibuttongroup3';
            app.txta = uilabel(app.uibuttongroup3);
            app.txta.Tag = 'txta';
            app.txta.Text = 'a:';
            app.text29 = uilabel(app.uibuttongroup3);
            app.text29.Tag = 'text29';
            app.text29.Text = 'b:';
            app.text30 = uilabel(app.uibuttongroup3);
            app.text30.Tag = 'text30';
            app.text30.Text = 'c:';
            app.text31 = uilabel(app.uibuttongroup3);
            app.text31.Tag = 'text31';
            app.text31.Text = 'alpha:';
            app.text32 = uilabel(app.uibuttongroup3);
            app.text32.Tag = 'text32';
            app.text32.Text = 'beta:';
            app.text33 = uilabel(app.uibuttongroup3);
            app.text33.Tag = 'text33';
            app.text33.Text = 'gamma:';
            app.ed_a = uieditfield(app.uibuttongroup3, 'text');
            app.ed_a.ValueChangedFcn = createCallbackFcn(app, @ed_a_Callback, true);
            app.ed_a.Tag = 'ed_a';
            app.ed_a.Value = '0';
            app.ed_b = uieditfield(app.uibuttongroup3, 'text');
            app.ed_b.ValueChangedFcn = createCallbackFcn(app, @ed_b_Callback, true);
            app.ed_b.Tag = 'ed_b';
            app.ed_b.Value = '0';
            app.ed_c = uieditfield(app.uibuttongroup3, 'text');
            app.ed_c.ValueChangedFcn = createCallbackFcn(app, @ed_c_Callback, true);
            app.ed_c.Tag = 'ed_c';
            app.ed_c.Value = '0';
            app.ed_alpha = uieditfield(app.uibuttongroup3, 'text');
            app.ed_alpha.ValueChangedFcn = createCallbackFcn(app, @ed_alpha_Callback, true);
            app.ed_alpha.Tag = 'ed_alpha';
            app.ed_alpha.Value = '0';
            app.ed_beta = uieditfield(app.uibuttongroup3, 'text');
            app.ed_beta.ValueChangedFcn = createCallbackFcn(app, @ed_beta_Callback, true);
            app.ed_beta.Tag = 'ed_beta';
            app.ed_beta.Value = '0';
            app.ed_gamma = uieditfield(app.uibuttongroup3, 'text');
            app.ed_gamma.ValueChangedFcn = createCallbackFcn(app, @ed_gamma_Callback, true);
            app.ed_gamma.Tag = 'ed_gamma';
            app.ed_gamma.Value = '0';
            app.text34 = uilabel(app.uibuttongroup3);
            app.text34.Tag = 'text34';
            app.text34.Text = 'phi:';
            app.text35 = uilabel(app.uibuttongroup3);
            app.text35.Tag = 'text35';
            app.text35.Text = 'theta:';
            app.text36 = uilabel(app.uibuttongroup3);
            app.text36.Tag = 'text36';
            app.text36.Text = 'psi:';
            app.ed_phi_euler = uieditfield(app.uibuttongroup3, 'text');
            app.ed_phi_euler.ValueChangedFcn = createCallbackFcn(app, @ed_phi_euler_Callback, true);
            app.ed_phi_euler.Tag = 'ed_phi_euler';
            app.ed_phi_euler.Value = '0';
            app.ed_theta_euler = uieditfield(app.uibuttongroup3, 'text');
            app.ed_theta_euler.ValueChangedFcn = createCallbackFcn(app, @ed_theta_euler_Callback, true);
            app.ed_theta_euler.Tag = 'ed_theta_euler';
            app.ed_theta_euler.Value = '0';
            app.ed_psi_euler = uieditfield(app.uibuttongroup3, 'text');
            app.ed_psi_euler.ValueChangedFcn = createCallbackFcn(app, @ed_psi_euler_Callback, true);
            app.ed_psi_euler.Tag = 'ed_psi_euler';
            app.ed_psi_euler.Value = '0';
            app.set_hkl1 = uibutton(app.uibuttongroup3, 'push');
            app.set_hkl1.ButtonPushedFcn = createCallbackFcn(app, @set_hkl1_Callback, true);
            app.set_hkl1.Tag = 'set_hkl1';
            app.set_hkl1.Text = 'set';
            app.ed_hkl1 = uieditfield(app.uibuttongroup3, 'text');
            app.ed_hkl1.Tag = 'ed_hkl1';
            app.ed_hkl1.Value = '[1 1 0]';
            app.ed_hkl2 = uieditfield(app.uibuttongroup3, 'text');
            app.ed_hkl2.Tag = 'ed_hkl2';
            app.ed_hkl2.Value = '[1 0 -1]';
            app.set_hkl2 = uibutton(app.uibuttongroup3, 'push');
            app.set_hkl2.ButtonPushedFcn = createCallbackFcn(app, @set_hkl2_Callback, true);
            app.set_hkl2.Tag = 'set_hkl2';
            app.set_hkl2.Text = 'set';
            app.text37 = uilabel(app.uibuttongroup3);
            app.text37.Tag = 'text37';
            app.text37.Text = 'hkl for the view dir.';
            app.bgview_cross = uibuttongroup(app.figure1);
            app.bgview_cross.SelectionChangedFcn = createCallbackFcn(app, @bgview_cross_SelectionChangedFcn, true);
            app.bgview_cross.Title = 'Orient so that the view becomes normal to two view vectors';
            app.bgview_cross.Tag = 'bgview_cross';
            app.cb_show_cross = uicheckbox(app.bgview_cross);
            app.cb_show_cross.ValueChangedFcn = createCallbackFcn(app, @cb_show_cross_Callback, true);
            app.cb_show_cross.Tag = 'cb_show_cross';
            app.cb_show_cross.Text = 'Show this plane';
            app.rb_v2xv3 = uiradiobutton(app.bgview_cross);
            app.rb_v2xv3.Tag = 'rb_v2xv3';
            app.rb_v2xv3.Text = 'V2 x V3';
            app.rb_v2xv3.Value = true;
            app.rb_v3xv1 = uiradiobutton(app.bgview_cross);
            app.rb_v3xv1.Tag = 'rb_v3xv1';
            app.rb_v3xv1.Text = 'V3 x V1';
            app.rb_v1xv2 = uiradiobutton(app.bgview_cross);
            app.rb_v1xv2.Tag = 'rb_v1xv2';
            app.rb_v1xv2.Text = 'V1 x V2';
            app.ed_tmp = uieditfield(app.bgview_cross, 'text');
            app.ed_tmp.ValueChangedFcn = createCallbackFcn(app, @ed_tmpValueChanged, true);
            app.ed_tmp.Tag = 'ed_tmp';
            app.grid_q_max = uieditfield(app.figure1, 'text');
            app.grid_q_max.Tag = 'grid_q_max';
            app.grid_q_max.Value = '0.2';
            app.text39 = uilabel(app.figure1);
            app.text39.Tag = 'text39';
            app.text39.Text = 'q_max:';
            app.edview_rot1 = uieditfield(app.figure1, 'text');
            app.edview_rot1.ValueChangedFcn = createCallbackFcn(app, @edview_rot1ValueChanged, true);
            app.edview_rot1.Tag = 'edview_rot1';
            app.edview_rot1.Value = '0.001';
            app.edview_rot2 = uieditfield(app.figure1, 'text');
            app.edview_rot2.ValueChangedFcn = createCallbackFcn(app, @edview_rot2ValueChanged, true);
            app.edview_rot2.Tag = 'edview_rot2';
            app.edview_rot2.Value = '0.001';
            app.edview_rot3 = uieditfield(app.figure1, 'text');
            app.edview_rot3.ValueChangedFcn = createCallbackFcn(app, @edview_rot3ValueChanged, true);
            app.edview_rot3.Tag = 'edview_rot3';
            app.edview_rot3.Value = '0.001';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = recilatvector_finder(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)recilatvector_finder_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end