classdef reflection_patch_handlingtool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        LoadPeaksMenu                 matlab.ui.container.Menu
        ExportPeaksMenu               matlab.ui.container.Menu
        EditMenu                      matlab.ui.container.Menu
        ClusterPatchesMenu            matlab.ui.container.Menu
        CalcIQShowOutliersMenu        matlab.ui.container.Menu
        RemoveRedsMenu                matlab.ui.container.Menu
        CenterofMassMenu              matlab.ui.container.Menu
        ShowAllMenu                   matlab.ui.container.Menu
        RemoveonDataTipMenu           matlab.ui.container.Menu
        HistogramofNNMenu             matlab.ui.container.Menu
        ClusterbyNNdistanceMenu       matlab.ui.container.Menu
        CutoffDistanceEditField       matlab.ui.control.NumericEditField
        CutoffDistanceEditFieldLabel  matlab.ui.control.Label
        RemoveButton                  matlab.ui.control.Button
        MergeButton                   matlab.ui.control.Button
        VoxelButton                   matlab.ui.control.Button
        Button_2                      matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        PeaksListBox                  matlab.ui.control.ListBox
        PeaksListBoxLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        FaceFamily % Description
        Selected % current items
    end
    
    methods (Access = private)
        function obj = get_Particles(app)
            fh = findobj(0, 'Tag', 'VolIqPlot');
            obj = findobj(fh, 'Tag', 'Particles');
            try
                obj([obj.FaceAlpha]==0) = [];
            catch
            end
        end
        function set_facealpha(app, items, val)
            obj = get_Particles(app);
            [obj.FaceAlpha] = deal(0.2);
            [obj(items).FaceAlpha] = deal(val);
        end
        function set_facecolor(app, items, col)
            obj = get_Particles(app);
            [obj.FaceColor] = deal([0,0,1]);
            [obj(items).FaceColor] = deal(col);
        end
        
        function plotcms(app)
            cm = get_cms(app);
            hold on
            for i=1:size(cm,1)
                %cm = mean(obj(i).Vertices);
                h = plot3(cm(i,1), cm(i,2), cm(i,3), 'ro');
                set(h, 'Tag', 'CM');
                set(h, 'userdata', i);
            end
            hold off
        end

        function cm = get_cms(app)
            obj = get_Particles(app);
            cm = zeros(numel(obj), 3);
            for i=1:numel(obj)
                cm(i,:) = mean(obj(i).Vertices);
            end
        end
        function q = get_patch_maxsize(app)
            obj = get_Particles(app);
            cm = get_cms(app);
            q = 0;
            for i=1:numel(obj)
                %cm = mean(obj(i).Vertices);
                vs = obj(i).Vertices-cm(i,:);
                sz = max(sqrt(sum(vs.^2,2)));
                if sz>q
                    q = sz;
                end
            end
        end        
        function merge(app, obj, selected)
            sobj = obj(selected);
            fh = findobj(0, 'Tag', 'VolIqPlot');
            ax = findobj(fh, 'type', 'axes');
            faces = [];
            vertices = [];
            N_faces = 0;
            for i=1:numel(sobj)
                faces = [faces;sobj(i).Faces+N_faces];
                vertices = [vertices;sobj(i).Vertices];
                N_faces = size(vertices, 1);
            end
            %delete(obj(selected(1:end-1)));
            [obj(selected(1:end-1)).FaceAlpha] = deal(0);
            obj(selected(end)).Vertices = vertices;
            obj(selected(end)).Faces = faces;
%            t = patch(ax, 'faces',faces,...
%            'vertices', vertices,...
%            'FaceColor', [1, 0, 0], 'edgecolor', 'none');
            %set(t, 'tag', 'Particles');            
        end
        function obj2 = calc_polyhedral_properties(app, obj)
            obj2 = struct([]);
            for i=1:numel(obj)
                if i==1
                    obj2 = polyhedron_area_volume(obj(i));
                else
                    obj2(i) = polyhedron_area_volume(obj(i));
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: LoadPeaksMenu
        function LoadPeaksMenuSelected(app, event)
            %app.FaceFamily = evalin('base', 'QmapFamily');
            obj = get_Particles(app);
            items = cell(1, numel(obj));
            for i=1:numel(items)
                cm = mean(obj(i).Vertices);
                q = sqrt(sum(cm.^2));
                items{i} = num2str(q);
            end
            app.PeaksListBox.Items = items;
        end

        % Value changed function: PeaksListBox
        function PeaksListBoxClicked(app, event)
%            item = event.InteractionInformation.Item
            set_facealpha(app, app.PeaksListBox.ValueIndex, 1);
            set_facecolor(app, app.PeaksListBox.ValueIndex, [1,0,0]);
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            items = app.PeaksListBox.Items;
            vals = app.PeaksListBox.ValueIndex;
            items(app.PeaksListBox.ValueIndex) = [];
            set_facealpha(app, app.PeaksListBox.ValueIndex, 0);
            app.PeaksListBox.Items = items;
            app.PeaksListBox.ValueIndex = vals(end);
        end

        % Button pushed function: MergeButton
        function MergeButtonPushed(app, event)
            %items = app.PeaksListBox.Items;
            
            selected = app.PeaksListBox.ValueIndex;
            obj = get_Particles(app);

            merge(app, obj, selected)
            LoadPeaksMenuSelected(app,[]);

        end

        % Menu selected function: ShowAllMenu
        function ShowAllMenuSelected(app, event)
            if event.Source.Checked
                delete(findobj(0, 'Tag', 'CM'))
                event.Source.Checked = 0;
            else
                plotcms(app)
                event.Source.Checked = 1;
            end
        end

        % Menu selected function: RemoveonDataTipMenu
        function RemoveonDataTipMenuSelected(app, event)
            fh = findobj(0, 'Tag', 'VolIqPlot');
            ax = findobj(fh, 'type', 'axes');
            tp = findobj(ax, 'type', 'datatip');
            obj = get_Particles(app);
            foundtooltip = [];
            cmstart = 1;
            if ~isempty(tp)
                for k=1:numel(tp)
                    pos = [tp(k).X, tp(k).Y, tp(k).Z];
                    %qpos = sqrt(sum(pos.^2));
                    CM = findobj(ax, 'Tag', 'CM');
                    for i=cmstart:numel(CM)
                        cmp = [CM(i).XData,CM(i).YData,CM(i).ZData];
                        h = get(CM(i), 'userdata');
                        vs = obj(h).Vertices-cmp;
                        dist = sqrt(sum(vs.^2,2));
                        m_dist = mean(dist);
                        if sqrt(sum((cmp-pos).^2))<m_dist
                            h = get(CM(i), 'userdata');
                            set_facealpha(app, h, 0);
                            delete(CM(i));
                            foundtooltip = [foundtooltip, k];
                            break
                        end
                    end
                end
            end
            try
                delete(foundtooltip)
            catch
                
            end
            delete(findobj(0, 'Tag', 'CM'))
            plotcms(app)
            %ShowAllMenuSelected(app)
            %ShowAllMenuSelected(app)
        end

        % Menu selected function: ClusterPatchesMenu
        function ClusterPatchesMenuSelected(app, event)
            maxR = get_patch_maxsize(app);
            fprintf('Maximum radius of the patch is %0.3e\n', maxR)
            cms = get_cms(app);
            obj = get_Particles(app);
            %mgr = {};
            %ismerged = zeros(size(obj));
            for i=1:numel(obj)
                dist = sqrt(sum((cms-cms(i,:)).^2,2));
                selected = find(dist < maxR*2);
                selected = selected(selected>i);
                if numel(selected)>1
                    selected = [i;selected];
                    merge(app, obj, selected)
                end
            end
        end

        % Menu selected function: CalcIQShowOutliersMenu
        function CalcIQShowOutliersMenuSelected(app, event)
            obj = get_Particles(app);
            obj2 = calc_polyhedral_properties(app, obj);
            for i=1:numel(obj2)
                if obj2(i).IsoperimetricQuotient<0.2
                    obj(i).FaceColor = [1,0,0];
                end
            end
        end

        % Menu selected function: RemoveRedsMenu
        function RemoveRedsMenuSelected(app, event)
            obj = get_Particles(app);
            for i=1:numel(obj)
                if all(obj(i).FaceColor == [1, 0, 0])
                    obj(i).FaceAlpha = 0;
                end
            end
        end

        % Menu selected function: ExportPeaksMenu
        function ExportPeaksMenuSelected(app, event)
            obj = get_Particles(app);
            facefamily = cell(1, numel(obj));
            for i=1:numel(obj)
                facefamily{i}.faces = obj(i).Faces;
                facefamily{i}.vertices = obj(i).Vertices;
                facefamily{i}.cm = mean(facefamily{i}.vertices, 1);
            end
            assignin('base', 'QmapFamily', facefamily);
        end

        % Menu selected function: HistogramofNNMenu
        function HistogramofNNMenuSelected(app, event)
            %obj = get_Particles(app);
            cms = get_cms(app);
            ds = distNzero(cms);
            %dds = sort(ds);
            figure()
            histogram(ds, 100)
        end

        % Menu selected function: ClusterbyNNdistanceMenu
        function ClusterbyNNdistanceMenuSelected(app, event)
            cms = get_cms(app);
            obj = get_Particles(app);
            [ds,~,ind1,ind2] = distNzero(cms);
            indx = find(ds < app.CutoffDistanceEditField.Value);
            for i=1:numel(indx)
                merge(app, obj, [ind1(i), ind2(i)]);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadPeaksMenu = uimenu(app.FileMenu);
            app.LoadPeaksMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadPeaksMenuSelected, true);
            app.LoadPeaksMenu.Text = 'Load Peaks';
            app.ExportPeaksMenu = uimenu(app.FileMenu);
            app.ExportPeaksMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportPeaksMenuSelected, true);
            app.ExportPeaksMenu.Text = 'Export Peaks';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.ClusterPatchesMenu = uimenu(app.EditMenu);
            app.ClusterPatchesMenu.MenuSelectedFcn = createCallbackFcn(app, @ClusterPatchesMenuSelected, true);
            app.ClusterPatchesMenu.Text = 'Cluster Patches';
            app.CalcIQShowOutliersMenu = uimenu(app.EditMenu);
            app.CalcIQShowOutliersMenu.MenuSelectedFcn = createCallbackFcn(app, @CalcIQShowOutliersMenuSelected, true);
            app.CalcIQShowOutliersMenu.Text = 'Calc I.Q. & Show Outliers';
            app.RemoveRedsMenu = uimenu(app.EditMenu);
            app.RemoveRedsMenu.MenuSelectedFcn = createCallbackFcn(app, @RemoveRedsMenuSelected, true);
            app.RemoveRedsMenu.Text = 'Remove Reds';
            app.CenterofMassMenu = uimenu(app.UIFigure);
            app.CenterofMassMenu.Text = 'Center of Mass';
            app.ShowAllMenu = uimenu(app.CenterofMassMenu);
            app.ShowAllMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowAllMenuSelected, true);
            app.ShowAllMenu.Text = 'Show All';
            app.RemoveonDataTipMenu = uimenu(app.CenterofMassMenu);
            app.RemoveonDataTipMenu.MenuSelectedFcn = createCallbackFcn(app, @RemoveonDataTipMenuSelected, true);
            app.RemoveonDataTipMenu.Text = 'Remove on DataTip';
            app.HistogramofNNMenu = uimenu(app.CenterofMassMenu);
            app.HistogramofNNMenu.MenuSelectedFcn = createCallbackFcn(app, @HistogramofNNMenuSelected, true);
            app.HistogramofNNMenu.Text = 'Histogram of NN';
            app.ClusterbyNNdistanceMenu = uimenu(app.CenterofMassMenu);
            app.ClusterbyNNdistanceMenu.MenuSelectedFcn = createCallbackFcn(app, @ClusterbyNNdistanceMenuSelected, true);
            app.ClusterbyNNdistanceMenu.Text = 'Cluster by NN distance';
            app.PeaksListBoxLabel = uilabel(app.UIFigure);
            app.PeaksListBoxLabel.Text = 'Peaks';
            app.PeaksListBox = uilistbox(app.UIFigure);
            app.PeaksListBox.Multiselect = 'on';
            app.PeaksListBox.ValueChangedFcn = createCallbackFcn(app, @PeaksListBoxClicked, true);
            app.PeaksListBox.Value = {'Item 1'};
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Text = '>>';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Text = '<<';
            app.VoxelButton = uibutton(app.UIFigure, 'push');
            app.VoxelButton.Text = 'Voxel';
            app.MergeButton = uibutton(app.UIFigure, 'push');
            app.MergeButton.ButtonPushedFcn = createCallbackFcn(app, @MergeButtonPushed, true);
            app.MergeButton.Text = 'Merge';
            app.RemoveButton = uibutton(app.UIFigure, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.CutoffDistanceEditFieldLabel = uilabel(app.UIFigure);
            app.CutoffDistanceEditFieldLabel.Text = 'Cutoff Distance';
            app.CutoffDistanceEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = reflection_patch_handlingtool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end